classdef EegWorkflow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        eeg_workflow                 matlab.ui.Figure
        ChanlocsDropDown             matlab.ui.control.DropDown
        pushbuttonInterpolateToChanlocs  matlab.ui.control.Button
        listboxHistory               matlab.ui.control.ListBox
        TextAreaRemoveChans          matlab.ui.control.TextArea
        ListBoxCleanline             matlab.ui.control.ListBox
        pushbuttonLineNoise          matlab.ui.control.Button
        ICANumberDropDown            matlab.ui.control.DropDown
        ICAnumberDropDownLabel       matlab.ui.control.Label
        ListBoxInterpolationPeriods  matlab.ui.control.ListBox
        ListBoxEMGPeriods            matlab.ui.control.ListBox
        ListBoxExcessivePeriods      matlab.ui.control.ListBox
        ListBoxFlatPeriods           matlab.ui.control.ListBox
        pushbuttonRemoveResting      matlab.ui.control.Button
        ListBoxAltEMG                matlab.ui.control.ListBox
        ListBoxAltEOG                matlab.ui.control.ListBox
        ListBoxICA                   matlab.ui.control.ListBox
        ListBoxASR                   matlab.ui.control.ListBox
        ListBoxEMG                   matlab.ui.control.ListBox
        ListBoxEOG                   matlab.ui.control.ListBox
        ListBoxFilter                matlab.ui.control.ListBox
        ListBoxRereference           matlab.ui.control.ListBox
        ListBoxResample              matlab.ui.control.ListBox
        ListBoxLookup                matlab.ui.control.ListBox
        ListBoxExcessive             matlab.ui.control.ListBox
        ListBoxFlatline              matlab.ui.control.ListBox
        ListBoxOpen                  matlab.ui.control.ListBox
        ICAtypeDropDown              matlab.ui.control.DropDown
        ICAtypeDropDownLabel         matlab.ui.control.Label
        pushbuttonIntClean           matlab.ui.control.Button
        pushbuttonEditCode           matlab.ui.control.Button
        textareaExecuteCode          matlab.ui.control.TextArea
        pushbuttonExecuteCode        matlab.ui.control.Button
        pushbuttonLast4min           matlab.ui.control.Button
        pbBatch_v2                   matlab.ui.control.Button
        pbOverlayPSD                 matlab.ui.control.Button
        pushbuttonLastSec            matlab.ui.control.Button
        pushbuttonExcessivePeriods   matlab.ui.control.Button
        pushbuttonEMGPeriods         matlab.ui.control.Button
        pushbuttonRemoveEOG          matlab.ui.control.Button
        pushbuttonMemoryBack         matlab.ui.control.Button
        pushbuttonReview             matlab.ui.control.Button
        pushbuttonAltEMG             matlab.ui.control.Button
        pushbuttonFlatPeriods        matlab.ui.control.Button
        pbDFA                        matlab.ui.control.Button
        pbViewPSD                    matlab.ui.control.Button
        pushbuttonAltEOG             matlab.ui.control.Button
        pushbuttonRemoveFirstSec     matlab.ui.control.Button
        pushbuttonImputeAll          matlab.ui.control.Button
        pushbuttonOverlay            matlab.ui.control.Button
        pushbuttonExcessive          matlab.ui.control.Button
        pushbuttonRemoveTask         matlab.ui.control.Button
        pushbuttonDOWN               matlab.ui.control.Button
        pushbuttonUp                 matlab.ui.control.Button
        pushbuttonSaveMemory         matlab.ui.control.Button
        pbUndo                       matlab.ui.control.Button
        pushbuttonPlot2D             matlab.ui.control.Button
        listboxStdout                matlab.ui.control.ListBox
        pushbuttonSave               matlab.ui.control.Button
        pbCompERP                    matlab.ui.control.Button
        pushbuttonEMG                matlab.ui.control.Button
        pushbuttonASR                matlab.ui.control.Button
        pushbuttonInitialICA         matlab.ui.control.Button
        pushbuttonFlatline           matlab.ui.control.Button
        pushbuttonResample           matlab.ui.control.Button
        pbERP                        matlab.ui.control.Button
        pbEpoch                      matlab.ui.control.Button
        pushbuttonICA                matlab.ui.control.Button
        pushbuttonRemoveNoEEG        matlab.ui.control.Button
        pushbuttonRereference        matlab.ui.control.Button
        pbView                       matlab.ui.control.Button
        pushbuttonFilter             matlab.ui.control.Button
        pushbuttonChanlocs           matlab.ui.control.Button
        pushbuttonOpen               matlab.ui.control.Button
        UITrackAxes                  matlab.ui.control.UIAxes
    end


    methods (Access = private)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function guiEegAutoflow_OpeningFcn(app, varargin)
            % --- Executes just before guiEegAutoflow is made visible.
        
            % Ensure that the app appears on screen when run
            movegui(app.eeg_workflow, 'onscreen');
        
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
        
            % Choose default command line output for guiEegAutoflow
            handles.output = hObject;
        
            % Update handles structure  (this is what initialises guidata!)
            guidata(hObject, handles);

            % Put the repository folders on the path, so the app also runs
            % when the .mlapp is opened directly (not via EegWorkflowStart)
            here = fileparts(which('EegWorkflow'));
            for sub = {'code', 'functions', 'gui', 'external', 'resources'}
                d = fullfile(here, sub{1});
                if isfolder(d) && ~contains([path pathsep], [d pathsep])
                    addpath(d);
                end
            end
        
            % Everything else lives in the external class
            EegCallbacks.guiEegAutoflow_OpeningFcn(app, varargin{:});

        end

        % Callback function
        function checkboxTaskNoTask_Callback(app, event)
            % --- Executes on button press in checkboxTaskNoTask.
            
            EegCallbacks.checkboxTaskNoTask_Callback(app, event);
        end

        % Close request function: eeg_workflow
        function eeg_workflow_CloseRequestFcn(app, event)
            % --- Executes when user attempts to close fig_eeg_workflow.
            try
                EegCallbacks.eeg_workflow_CloseRequestFcn(app, event);
            catch E
                warning('saving settings may have failed')
            end
            delete(app);
        end

        % Callback function
        function pbBatch_Callback(app, event)
            % --- Executes on button press in pbBatch. -------------------------------
            EegCallbacks.pbBatch_Callback(app, event);
        end

        % Button pushed function: pbCompERP
        function pbCompERP_Callback(app, event)
            % --- Executes on button press in pbCompERP.
            EegCallbacks.pbCompERP_Callback(app, event)
        end

        % Button pushed function: pbDFA
        function pbDFA_Callback(app, event)
            % --- Executes on button press in pbDFA.
            EegCallbacks.pbDFA_Callback(app, event)
        end

        % Button pushed function: pbERP
        function pbERP_Callback(app, event)
            % --- Executes on button press in pbERP.
            EegCallbacks.pbERP_Callback(app, event)
        end

        % Button pushed function: pbEpoch
        function pbEpoch_Callback(app, event)
            % --- Executes on button press in pbEpoch.
            EegCallbacks.bEpoch_Callback(app, event);
        end

        % Button pushed function: pbUndo
        function pbUndo_Callback(app, event)
            % --- Executes on button press in pbUndo.
            EegCallbacks.pbUndo_Callback(app, event);
        end

        % Button pushed function: pbViewPSD
        function pbViewPSD_Callback(app, event)
            % --- Executes on button press in pbViewPSD.
            EegCallbacks.pbViewPSD_Callback(app, event);

        end

        % Button pushed function: pbView
        function pbView_Callback(app, event)
            % --- Executes on button press in pbView.
            EegCallbacks.pbView_Callback(app, event);
        end

        % Callback function
        function pushbuttoSaveChanlocs_Callback(app, event)
            % --- Executes on button press in pushbuttoSaveChanlocs.
            EegCallbacks.pushbuttoSaveChanlocs_Callback(app, event);
        end

        % Button pushed function: pushbuttonRemoveFirstSec
        function pushbuttonRemoveFirstSec_Callback(app, event)
            % --- Executes on button press in pushbutton38.
            EegCallbacks.pushbuttonRemoveFirstSec_Callback(app, event);
        end

        % Button pushed function: pushbuttonEMG
        function pushbuttonEMG_Callback(app, event)
            % --- Executes on button press in pushbuttonAARWinSec.
            EegCallbacks.pushbuttonEMG_Callback(app, event);
        end

        % Button pushed function: pushbuttonAltEMG
        function pushbuttonAltEMG_Callback(app, event)
            % --- Executes on button press in pushbuttonAltAAR.
            EegCallbacks.pushbuttonAltEMG_Callback(app, event);
        end

        % Button pushed function: pushbuttonAltEOG
        function pushbuttonAltEOG_Callback(app, event)
            % --- Executes on button press in pushbuttonAltInitialEOG.
            EegCallbacks.pushbuttonAltEOG_Callback(app, event);
        end

        % Callback function
        function pushbuttonBadChans_Callback(app, event)
            % --- Executes on button press in pushbuttonBadChans.
            EegCallbacks.pushbuttonBadChans_Callback(app, event);
        end

        % Button pushed function: pushbuttonChanlocs
        function pushbuttonChanlocs_Callback(app, event)
            % --- Executes on button press in pushbuttonChanlocs.
            EegCallbacks.pushbuttonChanlocs_Callback(app, event);
        end

        % Button pushed function: pushbuttonASR
        function pushbuttonASR_Callback(app, event)
            % --- Executes on button press in pushbuttonClean.
            EegCallbacks.pushbuttonASR_Callback(app, event);
        end

        % Button pushed function: pushbuttonDOWN
        function pushbuttonDOWN_Callback(app, event)
            % --- Executes on button press in pushbuttonDOWN.
            EegCallbacks.pushbuttonDOWN_Callback(app, event);
        end

        % Button pushed function: pushbuttonEMGPeriods
        function pushbuttonEMGPeriods_Callback(app, event)
            % --- Executes on button press in pushbuttonEMGPeriods.
            EegCallbacks.pushbuttonEMGPeriods_Callback(app, event);
        end

        % Button pushed function: pushbuttonExcessive
        function pushbuttonExcessive_Callback(app, event)
            % --- Executes on button press in pushbuttonExcessive.
            EegCallbacks.pushbuttonExcessive_Callback(app, event);
        end

        % Button pushed function: pushbuttonFilter
        function pushbuttonFilter_Callback(app, event)
            % --- Executes on button press in pushbuttonFilter.
            EegCallbacks.pushbuttonFilter_Callback(app, event);
        end

        % Button pushed function: pushbuttonFlatPeriods
        function pushbuttonFlatPeriods_Callback(app, event)
            % --- Executes on button press in pushbuttonFlatPeriods.
            EegCallbacks.pushbuttonFlatPeriods_Callback(app, event);
        end

        % Button pushed function: pushbuttonFlatline
        function pushbuttonFlatline_Callback(app, event)
            % --- Executes on button press in pushbuttonFlatline.
            EegCallbacks.pushbuttonFlatline_Callback(app, event);
        end

        % Button pushed function: pushbuttonICA
        function pushbuttonICA_Callback(app, event)
            % --- Executes on button press in pushbuttonICLabel.
            EegCallbacks.pushbuttonICA_Callback(app, event);
        end

        % Button pushed function: pushbuttonImputeAll
        function pushbuttonImputeAll_Callback(app, event)
            % --- Executes on button press in pushbuttonImputeAll. -------------------
            EegCallbacks.pushbuttonImputeAll_Callback(app, event);
        end

        % Callback function
        function pushbuttonImpute_Callback(app, event)
            % --- Executes on button press in pushbuttonImpute.
            EegCallbacks.pushbuttonImpute_Callback(app, event);
        end

        % Button pushed function: pushbuttonInitialICA
        function pushbuttonInitialICA_Callback(app, event)
            % --- Executes on button press in pushbuttonInitialICA.
            EegCallbacks.pushbuttonInitialICA_Callback(app, event);
        end

        % Callback function
        function pushbuttonMaskEvt_Callback(app, event)
            % --- Executes on button press in pushbuttonMaskEvt.
            EegCallbacks.ushbuttonMaskEvt_Callback(app, event);
        end

        % Button pushed function: pushbuttonMemoryBack
        function pushbuttonMemoryBack_Callback(app, event)
            % --- Executes on button press in pushbuttonMemoryBack.
            EegCallbacks.pushbuttonMemoryBack_Callback(app, event);
        end

        % Button pushed function: pushbuttonOpen
        function pushbuttonOpen_Callback(app, event)
            % --- Executes on button press in pbOpen.
            EegCallbacks.pushbuttonOpen_Callback(app, event);
        end

        % Button pushed function: pushbuttonOverlay
        function pushbuttonOverlay_Callback(app, event)
            % --- Executes on button press in pushbuttonOverlay.
            EegCallbacks.pushbuttonOverlay_Callback(app, event);
        end

        % Button pushed function: pushbuttonPlot2D
        function pushbuttonPlot2D_Callback(app, event)
            % --- Executes on button press in pushbuttonPlot2D.
            EegCallbacks.pushbuttonPlot2D_Callback(app, event);
        end

        % Button pushed function: pushbuttonRemoveEOG
        function pushbuttonRemoveEOG_Callback(app, event)
            % --- Executes on button press in pushbuttonRemoveEOG.
            EegCallbacks.pushbuttonRemoveEOG_Callback(app, event);
        end

        % Button pushed function: pushbuttonRemoveNoEEG
        function pushbuttonRemoveNoEEG_Callback(app, event)
            % --- Executes on button press in pushbuttonRemoveNoEEG.
            EegCallbacks.pushbuttonRemoveNoEEG_Callback(app, event);
        end

        % Button pushed function: pushbuttonRemoveTask
        function pushbuttonRemoveTask_Callback(app, event)
            % --- Executes on button press in pushbuttonRemoveResting.
            EegCallbacks.pushbuttonRemoveTask_Callback(app, event);
        end

        % Button pushed function: pushbuttonRereference
        function pushbuttonRereference_Callback(app, event)
            % --- Executes on button press in pushbuttonReref.
            EegCallbacks.pushbuttonRereference_Callback(app, event);
        end

        % Button pushed function: pushbuttonResample
        function pushbuttonResample_Callback(app, event)
            % --- Executes on button press in pushbuttonResample.
            EegCallbacks.pushbuttonResample_Callback(app, event);
        end

        % Button pushed function: pushbuttonReview
        function pushbuttonReview_Callback(app, event)
            % --- Executes on button press in pushbuttonReview.
            EegCallbacks.pushbuttonReview_Callback(app, event);
        end

        % Button pushed function: pushbuttonSaveMemory
        function pushbuttonSaveMemory_Callback(app, event)
            % --- Executes on button press in pushbuttonSaveMemory.
            EegCallbacks.pushbuttonSaveMemory_Callback(app, event);
        end

        % Button pushed function: pushbuttonSave
        function pushbuttonSave_Callback(app, event)
            % --- Executes on button press in pushbuttonSave.
            EegCallbacks.pushbuttonSave_Callback(app, event);
        end

        % Callback function
        function pushbuttonSleepTheta_Callback(app, event)
            % --- Executes on button press in pushbuttonSleepTheta.
            EegCallbacks.ushbuttonSleepTheta_Callback(app, event);
        end

        % Button pushed function: pushbuttonUp
        function pushbuttonUp_Callback(app, event)
            % --- Executes on button press in pushbuttonUp.
            EegCallbacks.pushbuttonUp_Callback(app, event);
        end

        % Callback function
        function sliderAARShift_Callback(app, event)
            % --- Executes on slider movement.
            EegCallbacks.sliderAARShift_Callback(app, event);
        end

        % Callback function
        function sliderAARWinSec_Callback(app, event)
            % --- Executes on slider movement.
            EegCallbacks.liderAARWinSec_Callback(app, event);
        end

        % Callback function
        function sliderBurstCriterion_Callback(app, event)
            % --- Executes on slider movement.
            EegCallbacks.sliderBurstCriterion_Callback(app, event);
        end

        % Callback function
        function sliderChannelMinR_Callback(app, event)
            % --- Executes on slider movement.
            EegCallbacks.sliderChannelMinR_Callback(app, event);
        end

        % Callback function
        function sliderExcessive_Callback(app, event)
            % --- Executes on slider movement.
            EegCallbacks.sliderExcessive_Callback(app, event);
        end

        % Callback function
        function sliderFlatlineSD_Callback(app, event)
            % --- Executes on slider movement.
            EegCallbacks.sliderFlatlineSD_Callback(app, event);
        end

        % Callback function
        function sliderHigh_Callback(app, event)
            % --- Executes on slider movement.
            EegCallbacks.sliderHigh_Callback(app, event);
        end

        % Callback function
        function sliderLow_Callback(app, event)
            % --- Executes on slider movement.
            EegCallbacks.sliderLow_Callback(app, event);
        end

        % Callback function
        function sliderMaxBadTime_Callback(app, event)
            % --- Executes on slider movement.
            EegCallbacks.sliderMaxBadTime_Callback(app, event);
        end

        % Callback function
        function sliderMinICLabelPercent_Callback(app, event)
            % --- Executes on slider movement.
            EegCallbacks.sliderMinICLabelPercent_Callback(app, event);
        end

        % Callback function
        function sliderNComps_Callback(app, event)
            % --- Executes on slider movement.
            EegCallbacks.sliderNComps_Callback(app, event);
        end

        % Callback function
        function sliderTstat_Callback(app, event)
            % --- Executes on slider movement.
            EegCallbacks.sliderTstat_Callback(app, event);
        end

        % Button pushed function: pushbuttonExcessivePeriods
        function pushbuttonExcessivePeriodsButtonPushed(app, event)
            % --- Executes on button press in pushbuttonFlatPeriods.
            EegCallbacks.pushbuttonExcessivePeriodsButtonPushed(app, event);
        end

        % Button pushed function: pushbuttonLastSec
        function pushbuttonLastSecButtonPushed(app, event)
            % --- Executes on button press in pushbutton38.
            EegCallbacks.pushbuttonLastSecButtonPushed(app, event);
        end

        % Button pushed function: pbOverlayPSD
        function pbOverlayPSDButtonPushed(app, event)
            EegCallbacks.pbOverlayPSDButtonPushed(app, event);
        end

        % Callback function
        function pushbuttonRmEyesOpenButtonPushed(app, event)
            % --- Executes on button press in pushbuttonRmEyesOpenButton.
            EegCallbacks.pushbuttonRmEyesOpenButtonPushed(app, event);
        end

        % Callback function
        function sliderAARWinSecValueChanging(app, event)
            EegCallbacks.sliderAARWinSecValueChanging(app, event);
        end

        % Button pushed function: pbBatch_v2
        function pbBatch_v2ButtonPushed(app, event)
            EegCallbacks.pbBatchButtonPushed(app, event);
        end

        % Button pushed function: pushbuttonLast4min
        function pushbuttonLast4minButtonPushed(app, event)
            EegCallbacks.pushbuttonLast4minButtonPushed(app, event);
        end

        % Button pushed function: pushbuttonEditCode
        function pushbuttonEditCodeButtonPushed(app, event)
            EegCallbacks.pushbuttonEditCodeButtonPushed(app, event)
        end

        % Button pushed function: pushbuttonExecuteCode
        function pushbuttonExecuteCodeButtonPushed(app, event)
            EegCallbacks.pushbuttonExecuteCodeButtonPushed(app, event);
        end

        % Button pushed function: pushbuttonIntClean
        function pushbuttonIntCleanButtonPushed(app, event)
            EegCallbacks.pushbuttonIntCleanButtonPushed(app, event);
        end

        % Callback function
        function pushbuttonSOUNDButtonPushed(app, event)
            EegCallbacks.pushbuttonSOUNDButtonPushed(app, event);
        end

        % Callback function
        function ListBoxOpenClicked(app, event)
            EegCallbacks.ListBoxClicked(app, event);
        end

        % Clicked callback: ListBoxASR, ListBoxAltEMG, ListBoxAltEOG, 
        % ...and 15 other components
        function ListBoxFlatlineClicked(app, event)
            EegCallbacks.ListBoxClicked(app, event);            
        end

        % Button pushed function: pushbuttonLineNoise
        function pushbuttonLineNoiseButtonPushed(app, event)
            EegCallbacks.pushbuttonLineNoise_Callback(app, event);
        end

        % Button pushed function: pushbuttonRemoveResting
        function pushbuttonRemoveRestingButtonPushed(app, event)
            EegCallbacks.pushbuttonRemoveResting_Callback(app, event);
        end

        % Button down function: UITrackAxes
        function UITrackAxesButtonDown(app, event)
            EegCallbacks.UITrackAxesButtonDown(app, event);
        end

        % Button pushed function: pushbuttonInterpolateToChanlocs
        function pushbuttonInterpolateToChanlocsButtonPushed(app, event)
            EegCallbacks.pushbuttonInterpolateToChanlocs(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.eeg_workflow = uifigure('Visible', 'off');
            app.eeg_workflow.Name = 'figEegAutoflow';
            app.eeg_workflow.CloseRequestFcn = createCallbackFcn(app, @eeg_workflow_CloseRequestFcn, true);
            app.eeg_workflow.Tag = 'fig_eeg_workflow';
            app.UITrackAxes = uiaxes(app.eeg_workflow);
            title(app.UITrackAxes, 'Title')
            zlabel(app.UITrackAxes, 'Z')
            app.UITrackAxes.XTick = [];
            app.UITrackAxes.YTick = [];
            app.UITrackAxes.ButtonDownFcn = createCallbackFcn(app, @UITrackAxesButtonDown, true);
            app.pushbuttonOpen = uibutton(app.eeg_workflow, 'push');
            app.pushbuttonOpen.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonOpen_Callback, true);
            app.pushbuttonOpen.Tag = 'pushbuttonOpen';
            app.pushbuttonOpen.Tooltip = 'Open files of ANT, EEGLAB, Neuroscan, BioSemi type';
            app.pushbuttonOpen.Text = 'Open';
            app.pushbuttonChanlocs = uibutton(app.eeg_workflow, 'push');
            app.pushbuttonChanlocs.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonChanlocs_Callback, true);
            app.pushbuttonChanlocs.Tag = 'pushbuttonChanlocs';
            app.pushbuttonChanlocs.Tooltip = 'look up channel locations. only the spherical positions of 10/10 locations are available. ';
            app.pushbuttonChanlocs.Text = 'Lookup';
            app.pushbuttonFilter = uibutton(app.eeg_workflow, 'push');
            app.pushbuttonFilter.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonFilter_Callback, true);
            app.pushbuttonFilter.Tag = 'pushbuttonFilter';
            app.pushbuttonFilter.Tooltip = 'Filter. Set specs right and below.';
            app.pushbuttonFilter.Text = 'Filter';
            app.pbView = uibutton(app.eeg_workflow, 'push');
            app.pbView.ButtonPushedFcn = createCallbackFcn(app, @pbView_Callback, true);
            app.pbView.Tag = 'pbView';
            app.pbView.Text = 'View';
            app.pushbuttonRereference = uibutton(app.eeg_workflow, 'push');
            app.pushbuttonRereference.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonRereference_Callback, true);
            app.pushbuttonRereference.Tag = 'pushbuttonReref';
            app.pushbuttonRereference.Tooltip = 'Rereference. If avg, be sure to redo after deleting channels.';
            app.pushbuttonRereference.Text = 'Rereference';
            app.pushbuttonRemoveNoEEG = uibutton(app.eeg_workflow, 'push');
            app.pushbuttonRemoveNoEEG.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonRemoveNoEEG_Callback, true);
            app.pushbuttonRemoveNoEEG.Tag = 'pushbuttonRemoveNoEEG';
            app.pushbuttonRemoveNoEEG.Tooltip = 'Remove channels without location info (assumed to be not EEG)';
            app.pushbuttonRemoveNoEEG.Text = 'Rm ~EEG ch';
            app.pushbuttonICA = uibutton(app.eeg_workflow, 'push');
            app.pushbuttonICA.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonICA_Callback, true);
            app.pushbuttonICA.Tag = 'pushbuttonICLabel';
            app.pushbuttonICA.Tooltip = 'Clean non-brain ICs with IClabel toolbox. Keeps ''Other'' ICs.';
            app.pushbuttonICA.Text = 'ICA';
            app.pbEpoch = uibutton(app.eeg_workflow, 'push');
            app.pbEpoch.ButtonPushedFcn = createCallbackFcn(app, @pbEpoch_Callback, true);
            app.pbEpoch.Tag = 'pbEpoch';
            app.pbEpoch.Text = 'Epoch';
            app.pbERP = uibutton(app.eeg_workflow, 'push');
            app.pbERP.ButtonPushedFcn = createCallbackFcn(app, @pbERP_Callback, true);
            app.pbERP.Tag = 'pbERP';
            app.pbERP.Text = 'View ERPs';
            app.pushbuttonResample = uibutton(app.eeg_workflow, 'push');
            app.pushbuttonResample.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonResample_Callback, true);
            app.pushbuttonResample.Tag = 'pushbuttonResample';
            app.pushbuttonResample.Tooltip = 'Resample. Set popup menu on the right.';
            app.pushbuttonResample.Text = 'Resample';
            app.pushbuttonFlatline = uibutton(app.eeg_workflow, 'push');
            app.pushbuttonFlatline.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonFlatline_Callback, true);
            app.pushbuttonFlatline.Tag = 'pushbuttonFlatline';
            app.pushbuttonFlatline.Tooltip = 'remove flatline channels (works best directly afte rimport).';
            app.pushbuttonFlatline.Text = 'Flatline';
            app.pushbuttonInitialICA = uibutton(app.eeg_workflow, 'push');
            app.pushbuttonInitialICA.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonInitialICA_Callback, true);
            app.pushbuttonInitialICA.Tag = 'pushbuttonInitialICA';
            app.pushbuttonInitialICA.Tooltip = 'Initial EOG removes eye blinks to make cleaning raw data better.';
            app.pushbuttonInitialICA.Text = 'EOG';
            app.pushbuttonASR = uibutton(app.eeg_workflow, 'push');
            app.pushbuttonASR.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonASR_Callback, true);
            app.pushbuttonASR.Tag = 'pushbuttonClean';
            app.pushbuttonASR.Tooltip = 'Clean with cleanrawdata / ASR';
            app.pushbuttonASR.Text = 'ASR';
            app.pushbuttonEMG = uibutton(app.eeg_workflow, 'push');
            app.pushbuttonEMG.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonEMG_Callback, true);
            app.pushbuttonEMG.Tag = 'pushbuttonAARWinSec';
            app.pushbuttonEMG.Tooltip = 'AAR removes muscle artefacts.';
            app.pushbuttonEMG.Text = 'EMG';
            app.pbCompERP = uibutton(app.eeg_workflow, 'push');
            app.pbCompERP.ButtonPushedFcn = createCallbackFcn(app, @pbCompERP_Callback, true);
            app.pbCompERP.Tag = 'pbCompERP';
            app.pbCompERP.Text = 'View comp ERPs';
            app.pushbuttonSave = uibutton(app.eeg_workflow, 'push');
            app.pushbuttonSave.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonSave_Callback, true);
            app.pushbuttonSave.Tag = 'pushbuttonSave';
            app.pushbuttonSave.Text = 'Save';
            app.listboxStdout = uilistbox(app.eeg_workflow);
            app.listboxStdout.Items = {'<function output will be shown here>'};
            app.listboxStdout.Tag = 'listboxStdout';
            app.listboxStdout.Value = '<function output will be shown here>';
            app.pushbuttonPlot2D = uibutton(app.eeg_workflow, 'push');
            app.pushbuttonPlot2D.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonPlot2D_Callback, true);
            app.pushbuttonPlot2D.Tag = 'pushbuttonPlot2D';
            app.pushbuttonPlot2D.Text = 'Plot 2D';
            app.pbUndo = uibutton(app.eeg_workflow, 'push');
            app.pbUndo.ButtonPushedFcn = createCallbackFcn(app, @pbUndo_Callback, true);
            app.pbUndo.Tag = 'pbUndo';
            app.pbUndo.Text = 'Undo';
            app.pushbuttonSaveMemory = uibutton(app.eeg_workflow, 'push');
            app.pushbuttonSaveMemory.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonSaveMemory_Callback, true);
            app.pushbuttonSaveMemory.Tag = 'pushbuttonSaveMemory';
            app.pushbuttonSaveMemory.Tooltip = 'Save data as variable GlobEEG. To acces type ''global GlobEEG'' in command window';
            app.pushbuttonSaveMemory.Text = 'Save GlobEEG';
            app.pushbuttonUp = uibutton(app.eeg_workflow, 'push');
            app.pushbuttonUp.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonUp_Callback, true);
            app.pushbuttonUp.Tag = 'pushbuttonUp';
            app.pushbuttonUp.Tooltip = 'Initial ICA removes eye blinks to make cleaning raw data better.';
            app.pushbuttonUp.Text = '↑';
            app.pushbuttonDOWN = uibutton(app.eeg_workflow, 'push');
            app.pushbuttonDOWN.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonDOWN_Callback, true);
            app.pushbuttonDOWN.Tag = 'pushbuttonDOWN';
            app.pushbuttonDOWN.Tooltip = 'Initial ICA removes eye blinks to make cleaning raw data better.';
            app.pushbuttonDOWN.Text = '↓';
            app.pushbuttonRemoveTask = uibutton(app.eeg_workflow, 'push');
            app.pushbuttonRemoveTask.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonRemoveTask_Callback, true);
            app.pushbuttonRemoveTask.Tag = 'pushbuttonRemoveResting';
            app.pushbuttonRemoveTask.Tooltip = 'Remove perdiods of resting between task blocks. Deselect any period of data 2s removed from events';
            app.pushbuttonRemoveTask.Text = 'Remove task';
            app.pushbuttonExcessive = uibutton(app.eeg_workflow, 'push');
            app.pushbuttonExcessive.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonExcessive_Callback, true);
            app.pushbuttonExcessive.Tag = 'pushbuttonExcessive';
            app.pushbuttonExcessive.Tooltip = 'remove channels with excessive signal';
            app.pushbuttonExcessive.Text = 'Bad chans';
            app.pushbuttonOverlay = uibutton(app.eeg_workflow, 'push');
            app.pushbuttonOverlay.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonOverlay_Callback, true);
            app.pushbuttonOverlay.Tag = 'pushbuttonOverlay';
            app.pushbuttonOverlay.Text = 'Overlayed View';
            app.pushbuttonImputeAll = uibutton(app.eeg_workflow, 'push');
            app.pushbuttonImputeAll.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonImputeAll_Callback, true);
            app.pushbuttonImputeAll.Tag = 'pushbuttonImputeAll';
            app.pushbuttonImputeAll.Tooltip = 'Impute all channels. this may help in reducing noise';
            app.pushbuttonImputeAll.Text = 'Interpolate all';
            app.pushbuttonRemoveFirstSec = uibutton(app.eeg_workflow, 'push');
            app.pushbuttonRemoveFirstSec.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonRemoveFirstSec_Callback, true);
            app.pushbuttonRemoveFirstSec.Tag = 'pushbutton38';
            app.pushbuttonRemoveFirstSec.Tooltip = 'Remove seconds from start';
            app.pushbuttonRemoveFirstSec.Text = 'Rm first 1s';
            app.pushbuttonAltEOG = uibutton(app.eeg_workflow, 'push');
            app.pushbuttonAltEOG.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonAltEOG_Callback, true);
            app.pushbuttonAltEOG.Tag = 'pushbuttonAltInitialEOG';
            app.pushbuttonAltEOG.Tooltip = 'Alternative version for Initial EOG for low number of channels';
            app.pushbuttonAltEOG.Text = 'Alt EOG';
            app.pbViewPSD = uibutton(app.eeg_workflow, 'push');
            app.pbViewPSD.ButtonPushedFcn = createCallbackFcn(app, @pbViewPSD_Callback, true);
            app.pbViewPSD.Tag = 'pbViewPSD';
            app.pbViewPSD.Text = 'View PSD';
            app.pbDFA = uibutton(app.eeg_workflow, 'push');
            app.pbDFA.ButtonPushedFcn = createCallbackFcn(app, @pbDFA_Callback, true);
            app.pbDFA.Tag = 'pbDFA';
            app.pbDFA.Text = 'View DFA';
            app.pushbuttonFlatPeriods = uibutton(app.eeg_workflow, 'push');
            app.pushbuttonFlatPeriods.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonFlatPeriods_Callback, true);
            app.pushbuttonFlatPeriods.Tag = 'pushbuttonFlatPeriod';
            app.pushbuttonFlatPeriods.Tooltip = 'Remove periods with flatline data (for specific EEG devices)';
            app.pushbuttonFlatPeriods.Text = 'Flat periods';
            app.pushbuttonAltEMG = uibutton(app.eeg_workflow, 'push');
            app.pushbuttonAltEMG.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonAltEMG_Callback, true);
            app.pushbuttonAltEMG.Tag = 'pushbuttonAltAAR';
            app.pushbuttonAltEMG.Tooltip = 'AAR removes muscle artefacts.';
            app.pushbuttonAltEMG.Text = 'Alt EMG';
            app.pushbuttonReview = uibutton(app.eeg_workflow, 'push');
            app.pushbuttonReview.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonReview_Callback, true);
            app.pushbuttonReview.Tag = 'pushbuttonReview';
            app.pushbuttonReview.Text = 'Review';
            app.pushbuttonMemoryBack = uibutton(app.eeg_workflow, 'push');
            app.pushbuttonMemoryBack.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonMemoryBack_Callback, true);
            app.pushbuttonMemoryBack.Tag = 'pushbuttonMemoryBack';
            app.pushbuttonMemoryBack.Tooltip = 'Save data as variable GlobEEG. To acces type ''global GlobEEG'' in command window';
            app.pushbuttonMemoryBack.Text = 'Load GlobEEG';
            app.pushbuttonRemoveEOG = uibutton(app.eeg_workflow, 'push');
            app.pushbuttonRemoveEOG.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonRemoveEOG_Callback, true);
            app.pushbuttonRemoveEOG.Tag = 'pushbuttonRemoveEOG';
            app.pushbuttonRemoveEOG.Tooltip = {'Remove channels matching *EOG*, EXT* A1, A2, M1, M2'};
            app.pushbuttonRemoveEOG.Text = 'Remove chans';
            app.pushbuttonEMGPeriods = uibutton(app.eeg_workflow, 'push');
            app.pushbuttonEMGPeriods.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonEMGPeriods_Callback, true);
            app.pushbuttonEMGPeriods.Tag = 'pushbuttonEMGPeriods';
            app.pushbuttonEMGPeriods.Tooltip = 'Remove periods with flatline data (for specific EEG devices)';
            app.pushbuttonEMGPeriods.Text = 'EMG periods';
            app.pushbuttonExcessivePeriods = uibutton(app.eeg_workflow, 'push');
            app.pushbuttonExcessivePeriods.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonExcessivePeriodsButtonPushed, true);
            app.pushbuttonExcessivePeriods.Tag = 'pushbuttonExcessivePeriod';
            app.pushbuttonExcessivePeriods.Tooltip = {'Remove periods excessive data'};
            app.pushbuttonExcessivePeriods.Text = 'Excessive periods';
            app.pushbuttonLastSec = uibutton(app.eeg_workflow, 'push');
            app.pushbuttonLastSec.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonLastSecButtonPushed, true);
            app.pushbuttonLastSec.Tag = 'pushbutton38';
            app.pushbuttonLastSec.Tooltip = 'Remove seconds from start';
            app.pushbuttonLastSec.Text = 'Rm last 1s';
            app.pbOverlayPSD = uibutton(app.eeg_workflow, 'push');
            app.pbOverlayPSD.ButtonPushedFcn = createCallbackFcn(app, @pbOverlayPSDButtonPushed, true);
            app.pbOverlayPSD.Tag = 'pbOverlayPSD';
            app.pbOverlayPSD.Text = 'Overlay PSD';
            app.pbBatch_v2 = uibutton(app.eeg_workflow, 'push');
            app.pbBatch_v2.ButtonPushedFcn = createCallbackFcn(app, @pbBatch_v2ButtonPushed, true);
            app.pbBatch_v2.Tag = 'pbBatch_v2';
            app.pbBatch_v2.Tooltip = 'Batch run';
            app.pbBatch_v2.Text = 'Batch';
            app.pushbuttonLast4min = uibutton(app.eeg_workflow, 'push');
            app.pushbuttonLast4min.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonLast4minButtonPushed, true);
            app.pushbuttonLast4min.Tag = 'pushbuttonLast4min';
            app.pushbuttonLast4min.Tooltip = 'Remove seconds from start';
            app.pushbuttonLast4min.Text = 'Keep last 4 min';
            app.pushbuttonExecuteCode = uibutton(app.eeg_workflow, 'push');
            app.pushbuttonExecuteCode.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonExecuteCodeButtonPushed, true);
            app.pushbuttonExecuteCode.Tag = 'pushbuttonExecuteCode';
            app.pushbuttonExecuteCode.Tooltip = {'Execute this code, use EEG to represent the data. EDit code below'};
            app.pushbuttonExecuteCode.Text = 'Execute code';
            app.textareaExecuteCode = uitextarea(app.eeg_workflow);
            app.textareaExecuteCode.Tag = 'textareaExecuteCode';
            app.textareaExecuteCode.Editable = 'off';
            app.textareaExecuteCode.Value = {'EEG = pop_epoch(EEG, ''eyes_open'',[1 39]);'; 'EEG.data = EEG.data(:,:);'; 'EEG.trials=1;'; 'EEG = eeg_checkset(EEG);'};
            app.pushbuttonEditCode = uibutton(app.eeg_workflow, 'push');
            app.pushbuttonEditCode.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonEditCodeButtonPushed, true);
            app.pushbuttonEditCode.Tag = 'pushbuttonExecuteCode';
            app.pushbuttonEditCode.Tooltip = {'Execute this code, use EEG to represent the data. EDit code below'};
            app.pushbuttonEditCode.Text = '...';
            app.pushbuttonIntClean = uibutton(app.eeg_workflow, 'push');
            app.pushbuttonIntClean.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonIntCleanButtonPushed, true);
            app.pushbuttonIntClean.Tag = 'pushbuttonIntClean';
            app.pushbuttonIntClean.Tooltip = 'Determine bad channels and delete';
            app.pushbuttonIntClean.Text = {'Interpolation'; 'Clean'};
            app.ICAtypeDropDownLabel = uilabel(app.eeg_workflow);
            app.ICAtypeDropDownLabel.Text = 'ICA type';
            app.ICAtypeDropDown = uidropdown(app.eeg_workflow);
            app.ICAtypeDropDown.Items = {'PICARD', 'binica', 'runica'};
            app.ICAtypeDropDown.Value = 'PICARD';
            app.ListBoxOpen = uilistbox(app.eeg_workflow);
            app.ListBoxOpen.Items = {};
            app.ListBoxOpen.ClickedFcn = createCallbackFcn(app, @ListBoxFlatlineClicked, true);
            app.ListBoxOpen.Value = {};
            app.ListBoxFlatline = uilistbox(app.eeg_workflow);
            app.ListBoxFlatline.Items = {};
            app.ListBoxFlatline.ClickedFcn = createCallbackFcn(app, @ListBoxFlatlineClicked, true);
            app.ListBoxFlatline.Value = {};
            app.ListBoxExcessive = uilistbox(app.eeg_workflow);
            app.ListBoxExcessive.Items = {};
            app.ListBoxExcessive.ClickedFcn = createCallbackFcn(app, @ListBoxFlatlineClicked, true);
            app.ListBoxExcessive.Value = {};
            app.ListBoxLookup = uilistbox(app.eeg_workflow);
            app.ListBoxLookup.Items = {};
            app.ListBoxLookup.ClickedFcn = createCallbackFcn(app, @ListBoxFlatlineClicked, true);
            app.ListBoxLookup.Value = {};
            app.ListBoxResample = uilistbox(app.eeg_workflow);
            app.ListBoxResample.Items = {};
            app.ListBoxResample.ClickedFcn = createCallbackFcn(app, @ListBoxFlatlineClicked, true);
            app.ListBoxResample.Value = {};
            app.ListBoxRereference = uilistbox(app.eeg_workflow);
            app.ListBoxRereference.Items = {};
            app.ListBoxRereference.ClickedFcn = createCallbackFcn(app, @ListBoxFlatlineClicked, true);
            app.ListBoxRereference.Value = {};
            app.ListBoxFilter = uilistbox(app.eeg_workflow);
            app.ListBoxFilter.Items = {};
            app.ListBoxFilter.ClickedFcn = createCallbackFcn(app, @ListBoxFlatlineClicked, true);
            app.ListBoxFilter.Value = {};
            app.ListBoxEOG = uilistbox(app.eeg_workflow);
            app.ListBoxEOG.Items = {};
            app.ListBoxEOG.ClickedFcn = createCallbackFcn(app, @ListBoxFlatlineClicked, true);
            app.ListBoxEOG.Value = {};
            app.ListBoxEMG = uilistbox(app.eeg_workflow);
            app.ListBoxEMG.Items = {};
            app.ListBoxEMG.ClickedFcn = createCallbackFcn(app, @ListBoxFlatlineClicked, true);
            app.ListBoxEMG.Value = {};
            app.ListBoxASR = uilistbox(app.eeg_workflow);
            app.ListBoxASR.Items = {};
            app.ListBoxASR.ClickedFcn = createCallbackFcn(app, @ListBoxFlatlineClicked, true);
            app.ListBoxASR.Value = {};
            app.ListBoxICA = uilistbox(app.eeg_workflow);
            app.ListBoxICA.Items = {};
            app.ListBoxICA.ClickedFcn = createCallbackFcn(app, @ListBoxFlatlineClicked, true);
            app.ListBoxICA.Value = {};
            app.ListBoxAltEOG = uilistbox(app.eeg_workflow);
            app.ListBoxAltEOG.Items = {};
            app.ListBoxAltEOG.ClickedFcn = createCallbackFcn(app, @ListBoxFlatlineClicked, true);
            app.ListBoxAltEOG.Value = {};
            app.ListBoxAltEMG = uilistbox(app.eeg_workflow);
            app.ListBoxAltEMG.Items = {};
            app.ListBoxAltEMG.ClickedFcn = createCallbackFcn(app, @ListBoxFlatlineClicked, true);
            app.ListBoxAltEMG.Value = {};
            app.pushbuttonRemoveResting = uibutton(app.eeg_workflow, 'push');
            app.pushbuttonRemoveResting.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonRemoveRestingButtonPushed, true);
            app.pushbuttonRemoveResting.Tag = 'pushbuttonRemoveResting';
            app.pushbuttonRemoveResting.Tooltip = 'Remove perdiods of resting between task blocks. Deselect any period of data 2s removed from events';
            app.pushbuttonRemoveResting.Text = 'Remove no-task';
            app.ListBoxFlatPeriods = uilistbox(app.eeg_workflow);
            app.ListBoxFlatPeriods.Items = {};
            app.ListBoxFlatPeriods.ClickedFcn = createCallbackFcn(app, @ListBoxFlatlineClicked, true);
            app.ListBoxFlatPeriods.Value = {};
            app.ListBoxExcessivePeriods = uilistbox(app.eeg_workflow);
            app.ListBoxExcessivePeriods.Items = {};
            app.ListBoxExcessivePeriods.ClickedFcn = createCallbackFcn(app, @ListBoxFlatlineClicked, true);
            app.ListBoxExcessivePeriods.Value = {};
            app.ListBoxEMGPeriods = uilistbox(app.eeg_workflow);
            app.ListBoxEMGPeriods.Items = {};
            app.ListBoxEMGPeriods.ClickedFcn = createCallbackFcn(app, @ListBoxFlatlineClicked, true);
            app.ListBoxEMGPeriods.Value = {};
            app.ListBoxInterpolationPeriods = uilistbox(app.eeg_workflow);
            app.ListBoxInterpolationPeriods.Items = {};
            app.ListBoxInterpolationPeriods.ClickedFcn = createCallbackFcn(app, @ListBoxFlatlineClicked, true);
            app.ListBoxInterpolationPeriods.Value = {};
            app.ICAnumberDropDownLabel = uilabel(app.eeg_workflow);
            app.ICAnumberDropDownLabel.Text = 'ICA number';
            app.ICANumberDropDown = uidropdown(app.eeg_workflow);
            app.ICANumberDropDown.Items = {'1', '3', '10'};
            app.ICANumberDropDown.Value = '1';
            app.pushbuttonLineNoise = uibutton(app.eeg_workflow, 'push');
            app.pushbuttonLineNoise.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonLineNoiseButtonPushed, true);
            app.pushbuttonLineNoise.Tag = 'pushbuttonFilter';
            app.pushbuttonLineNoise.Tooltip = 'Filter. Set specs right and below.';
            app.pushbuttonLineNoise.Text = 'Line noise';
            app.ListBoxCleanline = uilistbox(app.eeg_workflow);
            app.ListBoxCleanline.Items = {};
            app.ListBoxCleanline.ClickedFcn = createCallbackFcn(app, @ListBoxFlatlineClicked, true);
            app.ListBoxCleanline.Value = {};
            app.TextAreaRemoveChans = uitextarea(app.eeg_workflow);
            app.TextAreaRemoveChans.Value = {'*eog*'; 'a1'; 'a2'; 'm1'; 'm2'; 'ext*'};
            app.listboxHistory = uilistbox(app.eeg_workflow);
            app.listboxHistory.Items = {'<function output will be shown here>'};
            app.listboxHistory.Tag = 'listboxStdout';
            app.listboxHistory.Value = '<function output will be shown here>';
            app.pushbuttonInterpolateToChanlocs = uibutton(app.eeg_workflow, 'push');
            app.pushbuttonInterpolateToChanlocs.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonInterpolateToChanlocsButtonPushed, true);
            app.pushbuttonInterpolateToChanlocs.Tag = 'pushbuttonImputeAll';
            app.pushbuttonInterpolateToChanlocs.Tooltip = 'Impute all channels. this may help in reducing noise';
            app.pushbuttonInterpolateToChanlocs.Text = 'Interpolate to';
            app.ChanlocsDropDown = uidropdown(app.eeg_workflow);
            app.ChanlocsDropDown.Items = {'10/20 (19)', '10/10 (64)', 'F3 F4 F7 F8 C3 C4 P3 P4 O1 O2 T5 T6'};
            app.ChanlocsDropDown.Value = '10/20 (19)';
            % Show the figure after all components are created
            app.eeg_workflow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EegWorkflow(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.eeg_workflow)

                % Execute the startup function
                runStartupFcn(app, @(app)guiEegAutoflow_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.eeg_workflow)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.eeg_workflow)
        end
    end
end