classdef guiEegAutoflow_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        fig_eeg_workflow            matlab.ui.Figure
        checkboxSaveForImpute       matlab.ui.control.CheckBox
        pushbuttonLast4min          matlab.ui.control.Button
        pbBatch_v2                  matlab.ui.control.Button
        checkboxRenameEOGVH         matlab.ui.control.CheckBox
        pushbuttonRmEyesOpen        matlab.ui.control.Button
        pbOverlayPSD                matlab.ui.control.Button
        checkboxDataAreAvgRef       matlab.ui.control.CheckBox
        pushbuttonLastSec           matlab.ui.control.Button
        pushbuttonExcessivePeriods  matlab.ui.control.Button
        spinnerExcessive            matlab.ui.control.Spinner
        ZLabel                      matlab.ui.control.Label
        spinnerFlatlineSD           matlab.ui.control.Spinner
        SDSpinnerLabel              matlab.ui.control.Label
        spinnerHigh                 matlab.ui.control.Spinner
        HighLabel                   matlab.ui.control.Label
        spinnerLow                  matlab.ui.control.Spinner
        LowLabel                    matlab.ui.control.Label
        checkboxButterworth         matlab.ui.control.CheckBox
        pushbuttonSleepTheta        matlab.ui.control.Button
        pushbuttonEMGPeriods        matlab.ui.control.Button
        checkboxJader               matlab.ui.control.CheckBox
        pushbuttonRemoveEOG         matlab.ui.control.Button
        pushbuttonMemoryBack        matlab.ui.control.Button
        pushbuttonReview            matlab.ui.control.Button
        checkboxCorrectNK           matlab.ui.control.CheckBox
        sliderTstat                 matlab.ui.control.Slider
        pushbuttonAltAAR            matlab.ui.control.Button
        pushbuttonFlatPeriods       matlab.ui.control.Button
        pbDFA                       matlab.ui.control.Button
        pbViewPSD                   matlab.ui.control.Button
        pushbuttonAltInitialEOG     matlab.ui.control.Button
        textAARShift                matlab.ui.control.Label
        sliderAARShift              matlab.ui.control.Slider
        sliderAARWinSec             matlab.ui.control.Slider
        textAARWinSec               matlab.ui.control.Label
        pushbuttonMaskEvt           matlab.ui.control.Button
        pushbuttonRemoveFirstSec    matlab.ui.control.Button
        pushbuttonImputeAll         matlab.ui.control.Button
        pbBatch                     matlab.ui.control.Button
        pushbuttonImpute            matlab.ui.control.Button
        pushbuttoSaveChanlocs       matlab.ui.control.Button
        pushbuttonOverlay           matlab.ui.control.Button
        checkboxBiosig              matlab.ui.control.CheckBox
        pushbuttonExcessive         matlab.ui.control.Button
        checkboxTaskNoTask          matlab.ui.control.CheckBox
        pushbuttonRemoveResting     matlab.ui.control.Button
        sliderMaxBadTime            matlab.ui.control.Slider
        pushbuttonDOWN              matlab.ui.control.Button
        pushbuttonUp                matlab.ui.control.Button
        pushbuttonSaveMemory        matlab.ui.control.Button
        pbUndo                      matlab.ui.control.Button
        pushbuttonPlot2D            matlab.ui.control.Button
        popupmenuLookupType         matlab.ui.control.DropDown
        text11                      matlab.ui.control.Label
        listboxStdout               matlab.ui.control.ListBox
        pushbuttonSave              matlab.ui.control.Button
        checkboxKeepOther           matlab.ui.control.CheckBox
        textMinICLabelPercent       matlab.ui.control.Label
        sliderMinICLabelPercent     matlab.ui.control.Slider
        textBurstCriterion          matlab.ui.control.Label
        sliderBurstCriterion        matlab.ui.control.Slider
        textChannelMinR             matlab.ui.control.Label
        sliderChannelMinR           matlab.ui.control.Slider
        checkboxBurstDelete         matlab.ui.control.CheckBox
        pbCompERP                   matlab.ui.control.Button
        textNComps                  matlab.ui.control.Label
        sliderNComps                matlab.ui.control.Slider
        pushbuttonAARWinSec         matlab.ui.control.Button
        pushbuttonClean             matlab.ui.control.Button
        pushbuttonInitialICA        matlab.ui.control.Button
        checkboxNotch               matlab.ui.control.CheckBox
        checkboxAddCpz              matlab.ui.control.CheckBox
        pushbuttonFlatline          matlab.ui.control.Button
        popupmenuRerefFreq          matlab.ui.control.DropDown
        pushbuttonResample          matlab.ui.control.Button
        pbERP                       matlab.ui.control.Button
        pbEpoch                     matlab.ui.control.Button
        pushbuttonICLabel           matlab.ui.control.Button
        pushbuttonRemoveNoEEG       matlab.ui.control.Button
        pushbuttonBadChans          matlab.ui.control.Button
        popupmenuReref              matlab.ui.control.DropDown
        pushbuttonReref             matlab.ui.control.Button
        pbView                      matlab.ui.control.Button
        pushbuttonFilter            matlab.ui.control.Button
        pushbuttonChanlocs          matlab.ui.control.Button
        pushbuttonOpen              matlab.ui.control.Button
        textMaxBadTime              matlab.ui.control.Label
        textTstat                   matlab.ui.control.Label
    end


    methods (Access = private)
        function AddToListbox(app, listboxObject, str)

            old = listboxObject.String;
            if ischar(old)
                old = {old};
            end
            if ischar(str)
                new = {str};
            elseif isstring(str)
                new = cellstr(str);
            elseif iscell(str)
                new = str;
            else
                error('Input must be a char, string, or cell array of char.');
            end

            % Concatenate and trim if too many lines
            combined = [old; new];
            if numel(combined) > 100
                combined = combined(end-99:end);  % keep last 100 lines
            end

            listboxObject.String = combined;

            % now move to the AppDEsigner style code. The listboxObject
            % that was passed is an object created by the migration tool,
            % so that old code works. The new listbox object type is
            % different and has e.g. Items in stead of String as property.
            app.listboxStdout.scroll('bottom')
        end

        
        % ----------------------------------------------------------------
        % GETUICONTROLDATA gets data with the values in all UI objects and
        % puts the key value pairs in strlist
        function strlist = GetUIControlData(app, hObject)
            % pass a handle to the window/gui, and it will extract all the
            % UIControl object data (slider, checkbox value and edit
            % box). Converts all to strings.
            strlist = struct;

            ch = get(hObject,'ch');
            count = 0;
            for c=1:length(ch)
                skip = false;
                tag = get(ch(c), 'tag');
                switch get(ch(c), 'type')
                    case 'uiedit'
                        val = sprintf('%s',get(ch(c),'string'));
                    case {'uicheckbox','uislider','uispinner'}
                        val = sprintf('%.4f',get(ch(c),'value'));
                    case {'uidropdown'}
                        % not all versions of matlab keep track of
                        % valueindex.
                        if isprop(ch(c),'valueindex')
                            val = get(ch(c), 'valueindex');
                        else
                            str = get(ch(c), 'value');
                            items = get(ch(c), 'items');
                            val = find(strcmpi(items, str));
                            if isempty(val)
                                val = 0;
                            elseif length(val)>1
                                val = val(1);
                            end
                        end
                        % convert to string
                        val = sprintf('%.4f', val);
                    otherwise
                        skip=true;
                end
                if ~skip
                    count = count + 1;
                    strlist(count).key = tag;
                    strlist(count).val = val;
                end
            end
            
            strlist = struct2table(strlist);
        end
        

        % ----------------------------------------------------------------
        % SETUICONTROLDATA fills the data with the values in strlist 
        function SetUIControlData(app, hObject, strlist)
            
            % hObject holds the figure with the app struct
            ch = get(hObject,'ch');
            T = table; 
            T.type = get(ch,'type'); 
            T.tag = get(ch,'tag');
            for c=1:size(T,1)
                try
                    tag = T.tag{c};
                    fprintf('%s  %s\n',T.type{c}, T.tag{c})
                    switch T.type{c}
                        case 'uiedit'
                            ndx = find(strcmpi(strlist.key, tag));
                            if length(ndx)==1 %#ok<ISCL>
                                if isnumeric(strlist.val)
                                    set(ch(c),'string', sprintf('%.4f', strlist.val(ndx)));
                                else
                                    set(ch(c),'string', sprintf('%s', strlist.val{ndx}));
                                end
                            end
                            pause(0.005)
                        case {'uicheckbox'}
                            ndx = find(strcmpi(strlist.key, tag));
                            if length(ndx)==1 %#ok<ISCL>
                                if isnumeric(strlist.val)
                                    set(ch(c),'value', strlist.val(ndx));
                                else
                                    set(ch(c),'value', str2num(strlist.val{ndx}));
                                end
                            end
                            pause(0.005);
                            
                        case {'uidropdown'}
                            ndx = find(strcmpi(strlist.key, tag));
                            if length(ndx)==1 %#ok<ISCL>
                                fld = ifthen(isprop(ch(c),'value'), 'value', 'valueindex');
                                if isnumeric(strlist.val)
                                    % backward compatibility
                                    set(ch(c), fld, strlist.val(ndx));
                                elseif strcmpi(fld, 'valueindex')
                                    % newer way of storing values in
                                    % appdesigner uidropdown...
                                    val = strlist.val{ndx};
                                    set(ch(c), fld, str2num(val));
                                else
                                    val = strlist.val{ndx};
                                    if ~isempty(str2num(val))
                                        % is a numeric value but stored as
                                        % string, find corresponding item
                                        % in items, then store that string
                                        % value in VALUE
                                        items = get(ch(c),'items');
                                        set(ch(c), fld, items{str2num(val)});
                                    else
                                        % is a string, assume it is the
                                        % item name, store dierctly in
                                        % value.
                                        set(ch(c), fld, val);
                                    end
                                end
                            end
                            pause(0.005);
    
                        case {'uislider','uispinner'}
                            ndx = find(strcmpi(strlist.key, tag));
                            if length(ndx)==1 %#ok<ISCL>
                                if isnumeric(strlist.val)
                                    set(ch(c),'value', strlist.val(ndx));
                                else
                                    set(ch(c),'value', str2num(strlist.val{ndx}));
                                end
                                evt = struct('Value',strlist.val{ndx}, 'Source', ch(c), 'EventName','ValueChanged');
                                % fire callback?
                            end
                            pause(0.005);
                    end
                catch E
                    pause(.05)
                end
            end
        end

        % ----------------------------------------------------------------
        function Reject_Callback(app, tmprej)
            % --- Executes on REJECT. Pause to allow updates (maybe not
            % required)
            fprintf('blablabla');
            pause(1);
        end

        % ----------------------------------------------------------------
        % alternative for updating text objects linked to slider objects
        function updateSliderAndText(app, data)
            % 
            % data.textfieldvalues(sn).slidername = slidernames{sn};
            %     data.textfieldvalues(sn).textname = textname;
            %     data.textfieldvalues(sn).textvalue = data.(textname).String;
            for sn=data.textfieldvalues
                sn.slidername
                % now change the value of each text field
                value = data.(sn.slidername).Value;
                data.(sn.textname).String = sprintf(sn.textvalue, value);
            end

            drawnow;
        end
         
        % ----------------------------------------------------------------
        % Callback function for the overwrite yes/no in batch mode
        function buttonCallback(app, f, choice)
                global skipOverwrite_choice;
                skipOverwrite_choice = choice;
                uiresume(f); % Resume program execution
                delete(f);
        end
        
        %-----------------------------------------------------------------
        % private function to get PSD calls the LOCAL pfft function
        function [P, fs] = calc_PSD(app, EEG)
            
            if size(EEG.data,3)>1
                win = ones(1,size(tmp.data,2));
                [P, fs] = pfft(EEG.data(:,:)', EEG.srate, win, 0);
            else
                % windows with
                win = hanning(EEG.srate*2);
                [P, fs] = pfft(EEG.data(:,:)', EEG.srate, win, .5);
            end
        end
        
        % callback for the PSD window checkbox that groups channel data.
        % Gets the PSD in P and channel locations, then clusters these (or
        % not)
        function checkbox_callback(app, hObject, ax, fs, P, chanlocs, fontsize, lo, hi)
            % Callback function for checkbox
            % hardcoded limits
        
            ndx = fs>lo & fs<hi;
        
            % plot either all channels or a summary
            if get(hObject,'Value')==0
                cla
                traces = plot(ax, fs(ndx), 10*log10(P(ndx,:)));
                leg = {chanlocs.labels};
            else
                numlabels = {'theta','radius','X','Y','Z','sph_theta','sph_phi','sph_radius'};
                tab = struct2table(chanlocs);
                % repair: convert cell array of double to double with missings
                for lab=1:length(numlabels)
                    if ismember(numlabels{lab}, tab.Properties.VariableNames)
                        if iscell(tab.(numlabels{lab}))
                            values = cellfun(@(x)ifthen(isempty(x), nan, double(x)), tab.(numlabels{lab}));
                            tab.(lab) = values;
                        end
                    end
                end
        
                relX = tab.X ./ sqrt(tab.X.^2+tab.Y.^2+tab.Z.^2);
                relY = tab.Y ./ sqrt(tab.X.^2+tab.Y.^2+tab.Z.^2);
                % relZ = tab.Z ./ sqrt(tab.X.^2+tab.Y.^2+tab.Z.^2);
        
                ant    = relX>=-1E-5;
                post   = ~ant;
                medial = abs(relY)<.41;
                left   = relY>=.41;
                right  = relY<=.41;
        
                regP = nan(size(P,1),6);
                regP(:,1) = mean(P(:,ant & left),2);
                regP(:,2) = mean(P(:,ant & medial),2);
                regP(:,3) = mean(P(:,ant & right),2);
                regP(:,4) = mean(P(:,post & left),2);
                regP(:,5) = mean(P(:,post & medial),2);
                regP(:,6) = mean(P(:,post & right),2);
        
                traces = plot(ax, fs(ndx), 10*log10(regP(ndx,:)));
                leg = {'ant left','ant medial','ant right','post left','post medial','post right'};
        
            end
            set(ax, 'fontsize', fontsize+2)
            xlabel('frequency (Hz)')
            ylabel('Power({\mu}V^2/Hz)')
            patch([8 12 12 8], repelem(ylim, 2), [.5 .5 .5], ...
                'facealpha', .2, 'edgecolor','none')
            line([1 1], ylim, 'linestyle', ':')
            line([4 4], ylim, 'linestyle', ':')
            line([10 10], ylim, 'linestyle', ':')
            line([19 19], ylim, 'linestyle', ':')
            line([30 30], ylim, 'linestyle', ':')

            legend(traces, leg, 'location','northeast')
        end
        
        % callback for the PSD window checkbox that groups channel data.
        % Gets the PSD in P and channel locations, then clusters these (or
        % not)
        function checkbox_callback_overlay(app, hObject, event, ax, fs, Ps, fontsize, lo, hi)
            % Callback function for checkbox
            % hardcoded limits

            data = guidata(app.fig_eeg_workflow);
            chanlocs = data.EEG.chanlocs;
        
            ndx = fs>lo & fs<hi;

            P = Ps{1};
            P2= Ps{2};
            if any(size(P)~=size(P2))
                errordlg2('Only available for data of the same size.','Error')
                return
            end

            cla;
        
            % plot either all channels or a summary
            if get(hObject,'Value')==0
                plot(ax, fs(ndx), 10*log10(P(ndx,:)));
                hold on
                ax.ColorOrderIndex = 1;   % restart color cycle at first color
                plot(ax, fs(ndx)+.5, 10*log10(P2(ndx,:)),'--');
                legend({chanlocs.labels}, 'location','northeast')
            else
                numlabels = {'theta','radius','X','Y','Z','sph_theta','sph_phi','sph_radius'};
                tab = struct2table(chanlocs);
                % repair: convert cell array of double to double with missings
                for lab=1:length(numlabels)
                    if ismember(numlabels{lab}, tab.Properties.VariableNames)
                        if iscell(tab.(numlabels{lab}))
                            values = cellfun(@(x)ifthen(isempty(x), nan, double(x)), tab.(numlabels{lab}));
                            tab.(lab) = values;
                        end
                    end
                end
        
                relX = tab.X ./ sqrt(tab.X.^2+tab.Y.^2+tab.Z.^2);
                relY = tab.Y ./ sqrt(tab.X.^2+tab.Y.^2+tab.Z.^2);
                % relZ = tab.Z ./ sqrt(tab.X.^2+tab.Y.^2+tab.Z.^2);
        
                ant    = relX>=-1E-5;
                post   = ~ant;
                medial = abs(relY)<.41;
                left   = relY>=.41;
                right  = relY<=.41;
        
                regP = nan(size(P,1),6);
                regP(:,1) = mean(P(:,ant & left),2);
                regP(:,2) = mean(P(:,ant & medial),2);
                regP(:,3) = mean(P(:,ant & right),2);
                regP(:,4) = mean(P(:,post & left),2);
                regP(:,5) = mean(P(:,post & medial),2);
                regP(:,6) = mean(P(:,post & right),2);

                regP2 = nan(size(P2,1),6);
                regP2(:,1) = mean(P2(:,ant & left),2);
                regP2(:,2) = mean(P2(:,ant & medial),2);
                regP2(:,3) = mean(P2(:,ant & right),2);
                regP2(:,4) = mean(P2(:,post & left),2);
                regP2(:,5) = mean(P2(:,post & medial),2);
                regP2(:,6) = mean(P2(:,post & right),2);
        
                plot(ax, fs(ndx)+.5, 10*log10(regP(ndx,:)));
                hold on
                ax.ColorOrderIndex = 1;   % restart color cycle at first color
                plot(ax, fs(ndx)+.5, 10*log10(regP2(ndx,:)),'--');
                legend('ant left','ant medial','ant right','post left','post medial','post right')
        
            end
            set(ax, 'fontsize', fontsize+2)
            xlabel('frequency (Hz)')
            ylabel('Power({\mu}V^2/Hz)')

            set(line([1 1], ylim), 'linestyle', '--');
            set(line([4 4], ylim), 'linestyle', '--');
            set(line([8 8], ylim), 'linestyle', '--');
            set(line([13 13], ylim), 'linestyle', '--');
            set(line([30 30], ylim), 'linestyle', '--');
        end
        
        % ----------------------------------------------------------------
        function choice = choosedialog(app)
            d = dialog('Position',[300 300 250 150],'Name','Select');
            txt = uicontrol('Parent',d,...
                'Style','text',...
                'Position',[20 80 210 40],...
                'String','Select',...
                'Callback',@app.popup_callback);           
            pop = uicontrol('Parent',d,...
                'Style','popup',...
                'Position',[85 20 70 25],...
                'String',arrayfun(@(x)sprintf('%d',x),1:length(tmp),'uni',false)',...
                'value',1.0);
            btn = uicontrol('Parent',d,...
                'Position',[89 20 70 25],...
                'String','OK',...
                'Callback','delete(gcf)');
            
            uiwait(d);
        end
        
        function editOpenFilepath_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to editOpenFilepath (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function figUseSource_Callback(app, hObject) 
            data = guidata(hObject);
            data_parent = guidata(data.Parent);
            data_parent.EEG = data.EEG;
        end
        
        %-----------------------------------------------------------------
        % Update listbox
        function listboxEegProperties_Update(app, hObject)
            % --- Call this to update the listbox.
            
            % hObject    handle to pbOpen (see GCBO)
            
            data = guidata(hObject);
            
            fields = fieldnames(data.EEG);
            tmpstr = {};
            for f=1:length(fields)
                if ischar(data.EEG.(fields{f}))
                    tmpstr = [tmpstr ; {sprintf('%-12s "%s"',fields{f}, data.EEG.(fields{f}))}];
                elseif isinteger(data.EEG.(fields{f})) && length(data.EEG.(fields{f}))==1
                    tmpstr = [tmpstr ; {sprintf('%-12s %d',fields{f}, data.EEG.(fields{f}))}];
                elseif isnumeric(data.EEG.(fields{f})) && length(data.EEG.(fields{f}))==1
                    tmpstr = [tmpstr ; {sprintf('%-12s %f',fields{f}, data.EEG.(fields{f}))}];
                else
                    tmpstr = [tmpstr ; {sprintf('%-12s <%s>',fields{f}, class(data.EEG.(fields{f})))}];
                end
            end
            data.listboxEegProperties.String = tmpstr;
        end
        
        %-----------------------------------------------------------------
        % private function to update the EEG.etc field with measures of
        % regular frequency power so as to track the progressin of band
        % power and changes due to 
        function recordPower(app, event, tag)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            data = guidata(hObject);

            % Identify channels that have spherical location information
            hasLoc = false(1, data.EEG.nbchan);
            for ch = 1:data.EEG.nbchan
                try
                    hasLoc(ch) = ~isempty(data.EEG.chanlocs(ch).sph_theta) && ...
                                 ~isempty(data.EEG.chanlocs(ch).sph_phi);
                catch
                    hasLoc(ch) = false;
                end
            end

            % Skip entirely if no channel has location info yet
            if ~any(hasLoc)
                return;
            end

            % First call with location info: lock in the reference channel list
            if ~isfield(data.EEG.etc, 'recordPowerChannels')
                data.EEG.etc.recordPowerChannels = {data.EEG.chanlocs(hasLoc).labels};
            end
            refChannels = data.EEG.etc.recordPowerChannels;

            if ~isfield(data.EEG.etc, 'recordPowerFreqs')
                data.EEG.etc.recordPowerFreqs = {'delta','theta','alpha','low beta','high beta'};
            end

            % Compute PSD only for channels that currently have location info
            locIdx       = find(hasLoc);
            currentLabels = {data.EEG.chanlocs(locIdx).labels};
            bands = [1 4; 4 7.5; 7.5 13; 13 19; 19 30];
            [P, fs] = pfft(data.EEG.data(locIdx, :)', data.EEG.srate, hanning(data.EEG.srate * 2));

            % Build rectangular output aligned to reference list; NaN for removed channels
            S = struct;
            S.Tag      = tag;
            S.bands    = bands;
            S.channels = refChannels;
            S.bandpower = nan(length(bands), length(refChannels));
            for f = 1:size(bands, 1)
                ndx     = fs >= bands(f,1) & fs < bands(f,2);
                bandpow = 10*log10(sum(P(ndx, :)) * (fs(2) - fs(1)));
                for c = 1:length(currentLabels)
                    refIdx = strcmp(currentLabels{c}, refChannels);
                    if any(refIdx)
                        S.bandpower(f, refIdx) = bandpow(c);
                    end
                end
            end

            if ~isfield(data.EEG.etc, 'recordPower')
                data.EEG.etc.recordPower = {};
            end
            data.EEG.etc.recordPower{end+1} = S;

            if ~isfield(data.EEG.etc, 'recordPowerMetadata')
                data.EEG.etc.recordPowerMetadata = struct;
                data.EEG.etc.recordPowerMetadata.xmax = data.EEG.xmax;
                data.EEG.etc.recordPowerMetadata.nbchan = data.EEG.nbchan;
                data.EEG.etc.recordPowerMetadata.rank = rank(corr(data.EEG.data(:,:)'));
            else
                data.EEG.etc.recordPowerMetadata(end+1).xmax = data.EEG.xmax;
                data.EEG.etc.recordPowerMetadata(end).nbchan = data.EEG.nbchan;
                data.EEG.etc.recordPowerMetadata(end).rank = rank(corr(data.EEG.data(:,:)'));
            end

            guidata(hObject, data);
        end


        %-----------------------------------------------------------------
        % private function to load EEG files of any kind
        function EEG = loadfile(app, PathName, FileName, listboxStdout, ...
                checkboxBiosig, checkboxDataAreAvgRef)
            
            if PathName(end)~='/'
                PathName = [PathName '/'];
            end
            
            zz = strsplit(FileName,'.');
            switch zz{end}
                case 'cnt'
                    try
                        cLoadANTNeuro = true;
                        [EEG, cmd] = pop_loadeep_v4([PathName FileName], 'triggerfile', 'on');
                    catch E
                        if strcmpi(E.message, 'Error getting samples')
                            cLoadANTNeuro = false;
                            % try loading Neuroscan
                            [EEG, cmd] = pop_loadcnt([PathName FileName] , 'dataformat', 'auto', 'memmapfile', '');
                        else
                            rethrow(E)
                        end
                    end
            
                    % add filename
                    EEG.filename = [PathName FileName];
            
                    if cLoadANTNeuro
                        % some event editing needs to be done for ANT Neuro files
            
                        % copy to tmp variable and work on that
                        tmp = EEG;
            
                        % insert boundaries and events
                        evtcnt = 0;
                        for ev=1:length(tmp.event)
                            if tmp.event(ev).latency>1
                                evtcnt = evtcnt + 1;
                                if strcmp(tmp.event(ev).type,'__')
                                    typ = 'boundary';
                                else
                                    typ = strtrim(tmp.event(ev).type);
                                end
                                tmp.event(evtcnt).type = typ;
                                tmp.event(evtcnt).latency = EEG.event(ev).latency;
                                tmp.event(evtcnt).duration = EEG.event(ev).duration;
                            end
                        end
            
                        % check for event length (>10 events), start of first event named 31 (>100 sec), and
                        % existence of a boundary event. If not, search for a "jump" in
                        % activity just before the first event and put in a boundary
                        % event (segment will not work otherwise.)
                        if length(tmp.event)>10 && ~isempty(str2num(tmp.event(1).type)) ...
                                && tmp.event(1).latency>100*tmp.srate ...
                                && sum(strcmpi('boundary',{tmp.event.type}))==0
                            sig = tmp.data(:,(tmp.event(1).latency-tmp.srate*4):tmp.event(1).latency);
                            z = abs(zscore(mean(abs(diff(sig')')))');
                            ndx = tmp.event(1).latency-tmp.srate*4 + min(find(z>10)) + 0;
                            dummy = tmp.event(1);
                            dummy.type = 'boundary';
                            dummy.latency = ndx;
                            dummy.duration = 0;
                            tmp.event = cat(1,dummy,tmp.event(:));
                        end
                        EEG = tmp;
                        AddToListbox(app, listboxStdout, 'Read ANT CNT file')
            
                    else
                        AddToListbox(app, listboxStdout, 'Read Neuroscan CNT file')
                    end
            
                case 'set'
                    [EEG, cmd] = pop_loadset([PathName FileName]);
                    EEG.filename = [PathName FileName];
                    AddToListbox(app, listboxStdout, 'Read EEGLAB file')
            
                case 'bdf'
                    if checkboxBiosig.Value ~= 0
                        AddToListbox(app, listboxStdout, 'Read BDF file with pop_biosig');
                        [EEG, cmd] = pop_biosig([PathName FileName], 'bdfeventmode',1);
                        AddToListbox(app, listboxStdout, ' - data read');
                    else
                        AddToListbox(app, listboxStdout, 'Read BDF file with pop_readbdf');
                        AddToListbox(app, listboxStdout, ' - read file header');
                        tmp = sopen([PathName FileName]);
                        AddToListbox(app, listboxStdout, sprintf(' - %d channels', tmp.NS));
                        [EEG, cmd] = pop_readbdf([PathName FileName], [], tmp.NS);
                        AddToListbox(app, listboxStdout, ' - data read');
                    end
                    EEG.filename = FileName;
                    AddToListbox(app, listboxStdout, ' - *** NOTE Data are raw. Please rereference in the next steps.');
                    AddToListbox(app, listboxStdout, ' - *** NOTE Renaming EXG* to EXT*.');
                    for ch=1:EEG.nbchan
                        if strncmp(EEG.chanlocs(ch).labels, "EXG", 3)
                            EEG.chanlocs(ch).labels(3) = "T";
                        end
                    end
            
                case 'edf'
                    % read header
                    [EEG, cmd] = pop_biosig([PathName FileName]);
                    EEG.filename = FileName;
                    AddToListbox(app, listboxStdout, 'Read EDF file.');
            
                case 'vhdr'
                    % read header
                    [EEG, cmd] = pop_loadbv(PathName, FileName, [], []);
                    EEG.filename = FileName;
                    AddToListbox(app, listboxStdout, 'Read BrainVision file.');
            end

            % sometimes raw data are in average reference, but that is not
            % detected
            if checkboxDataAreAvgRef.Value
                AddToListbox(app, listboxStdout, 'Assuming data are in average reference.');
                EEG.ref = 'average';
                test = mean(EEG.data(:,100:199));
                if any(test>.5)
                    warning('The data do not average out to near zero. check AvgRef setting.')
                    AddToListbox(app, listboxStdout, '*** The data do not average out to near zero.\n*** check AvgRef setting.');
                end
            end

            EEG.history = [EEG.history char(uint8(10)) cmd];
        end
        
        function pbFilter_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in pushbuttonFilter.
            
            % hObject    handle to pushbuttonFilter (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            data = guidata(hObject);
            data.pbFilter.BackgroundColor = [.3 .6 .3];
            pause(0.005)
            
            if ~isfield(data,'EEG') || isempty(data.EEG.data)
                msgbox('No data available');
                data.pbFilter.BackgroundColor = [1 .6 .6];
                return
            end
            
            
            if data.radiobuttonFIR.Value
                AddToListbox(app, data.listboxStdout, 'Applying FIR filter while observing transition band.');
                data.EEG = filter_fir(data.EEG, data.EEG.srate, data.spinnerLow.Value, data.spinnerHigh.Value, 3.0, true, false, false);
                if data.chbxNotch.Value
                    AddToListbox(app, data.listboxStdout, ' Notch FIR filter 47 to 53 Hz');
                    data.EEG = filter_fir(data.EEG, data.EEG.srate, 47, 53, 3.0, true, true);
                end
            else
                AddToListbox(app, data.listboxStdout, ' Applying 3rd order Butterworth IIR filter (zerophase)');
                data.EEG = filter_butter(data.EEG, data.EEG.srate, data.spinnerLow.Value, data.spinnerHigh.Value, 3, true, false, false);
                if data.chbxNotch.Value
                    AddToListbox(app, data.listboxStdout, ' Notch FIR filter 47 to 53 Hz');
                    data.EEG = filter_butter(data.EEG, data.EEG.srate, 47, 53, 3, true, true);
                end
            end
            
            data.pbFilter.BackgroundColor = [1 .6 .6];
            guidata(hObject,data);
        end
        
%         function pbReref_ButtonDownFcn(app, hObject, eventdata, handles)
%             % --- If Enable == 'on', executes on mouse press in 5 pixel border.
%             % --- Otherwise, executes on mouse press in 5 pixel border or over pbReref.
%             
%             % hObject    handle to pbReref (see GCBO)
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             
%             data = guidata(hObject);
%         end
%         
%         function pbReref_Callback(app, hObject, eventdata, handles)
%             % --- Executes on button press in pbReref.
%             
%             % hObject    handle to pbReref (see GCBO)
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             
%             data = guidata(hObject);
%             data.pbReref.BackgroundColor = [.3 .6 .3];
%             tmp = data.EEG;
%             if size(tmp.data,3) ~= tmp.trials
%                 tmp.trials = size(tmp.data,3);
%             end
%             if tmp.trials==1 && tmp.pnts~=size(tmp.data,2)
%                 tmp.pnts = size(tmp.data,2);
%             end
%             
%             
%             if ~isfield(data,'EEG') || isempty(data.EEG.data)
%                 msgbox('No data available');
%                 data.pbReref.BackgroundColor = [.6 1 .6];
%                 return
%             end
%             
%             switch data.popupmenuReref.Value
%                 case 1, [tmp, cmd] = pop_reref(tmp, find(ismember(upper({tmp.chanlocs.labels}),'CPZ')));
%                 case 2, [tmp, cmd] = pop_reref(tmp, find(ismember(upper({tmp.chanlocs.labels}),{'M1','M2'})));
%                 case 3, [tmp, cmd] = pop_reref(tmp, [], 'exclude', find(ismember(upper({tmp.chanlocs.labels}),{'HEOG','VEOG'})));
%             end
%             
%             data.EEG = tmp;
%             data.pbReref.BackgroundColor = [1 .6 .6];
%             guidata(hObject,data);
%         end
        
        function pbSegment_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in pbSegment.
            
            % hObject    handle to pbSegment (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            data = guidata(hObject);
            
            if isempty(data.EEG.event)
                uialert(hObject,'No boundary events detected. No segmentation performed.','segment warning')
            else
                data.pbSegment.BackgroundColor = [.3 .6 .3];
                pause(0.005);
            
                data.nsegments = sum(strcmpi({data.EEG.event.type},'boundary'))+1;
                if data.nsegments>1
                    data.popupmenuSegments.String = {'Single segment'};
                    ndx = strcmpi({data.EEG.event.type},'boundary');
                    cut = [1 data.EEG.event(ndx).latency data.EEG.pnts+1];
                    tmp = struct;
                    for seg=1:data.nsegments
                        tmp(seg).EEG = data.EEG;
                        tmp(seg).EEG.data = data.EEG.data(:,cut(seg):cut(seg+1)-1);
                        tmp(seg).EEG.pnts = size(data.segment(seg).EEG.data,2);
                        del = [];
                        for ev=1:length(tmp(seg).EEG.event)
                            if tmp(seg).EEG.event(ev).latency>=cut(seg)&&tmp(seg).EEG.event(ev).latency<cut(seg+1)
                                tmp(seg).EEG.event(ev).latency = tmp(seg).EEG.event(ev).latency - cut(seg) + 1;
                            else
                                del = [del ev];
                            end
                        end
                        if ~isempty(del)
                            tmp(seg).EEG = pop_editeventvals(tmp(seg).EEG, 'delete', del);
                        end
                        tmp(seg).EEG = eeg_checkset(tmp(seg).EEG);
                        tmp(seg).bad = [];
                        tmp(seg).cut = [];
                    end
            
                    % create UI to select segment and overwrite EEG
                else
                    uialert(hObject,'No boundary events detected. No segmentation performed.','segment warning')
                end
            end
            
            data.pbSegment.BackgroundColor = [1 .6 .6];
            data.pbAutoRej.BackgroundColor = [.6 1 .6];
            data.pbViewSeg.BackgroundColor = [.6 1 .6];
            guidata(hObject,data);
        end
        
        function pbViewSeg_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in pbViewSeg.
            
            % hObject    handle to pbViewSeg (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global viewseg_tmprej;
            
            data = guidata(hObject);
            data.pbViewSeg.BackgroundColor = [.3 .6 .3];
            pause(0.005);
            
            if ~isfield(data,'nsegments') || data.nsegments==0
                fprintf('No available segments');
                beep;
                data.pbViewSeg.BackgroundColor = [1 .6 .6];
                return
            end
            
            % some data available. Open data with eegplot but first open bad channel
            % selector.
            h = figBadChans;
            WinOnTop(h);
            set(h,'units','pixels')
            pos = get(h,'pos');
            data.hBadChans = h;
            
            % set a counter for tagging the eegplot window
            if ~isfield(data,'hWindowCounter')
                data.hWindowCounter = 1;
            else
                data.hWindowCounter = data.hWindowCounter + 1;
            end
            
            % open data
            screensize = get(groot, 'Screensize' );
            ndx = data.popupmenuSegments.Value;
            switch ndx
                case 1,
                    tmp = data.EEG;
                    if isfield(data,'cut')
                        tmpcut = data.cut;
                    else
                        tmpcut = [];
                    end
            
                otherwise,
                    tmp = data.segment(ndx-1).EEG;
                    if isfield(data.segment(ndx-1),'cut')
                        tmpcut = data.segment(ndx-1).cut;
                    else
                        tmpcut = [];
                    end
            end
            
            %{
                    chbx = {};
                    for ch=1:tmp.nbchan
                        chbx{ch} = uicontrol(h,'Style','checkbox','String',tmp.chanlocs(ch).labels,...
                            'Position',[8+floor((ch-1)/32)*60 pos(4)-(mod(ch-1,32)*16+20) 60 20], 'tag', sprintf('chbx%d',ch));
                        if isfield(tmp.chanlocs,'badchan') && tmp.chanlocs(ch).badchan
                            chbx{ch}.Value = 1;
                        else
                            chbx{ch}.Value = 0;
                        end
                    end
            
                    if isfield(data,'cut')
                        winrej = [data.cut repmat([.5 .5 1],size(data.cut,1),1) ones(size(data.cut,1),tmp.nbchan)];
                        eegplot(tmp.data,'srate',tmp.srate,'eloc_file',tmp.chanlocs,'spacing',50,...
                            'limits',[tmp.xmin tmp.xmax],'winlength',12,'position',screensize,...
                            'events',tmp.event,'winrej',winrej,'command', 'global viewseg_tmprej, viewseg_tmprej=TMPREJ',...
                            'tag',sprintf('figRejectViewer_%d',data.hWindowCounter));
                    else
                        eegplot(tmp.data,'srate',tmp.srate,'eloc_file',tmp.chanlocs,'spacing',50,...
                            'limits',[tmp.xmin tmp.xmax],'winlength',12,'position',screensize,...
                            'events',tmp.event,'command', 'global viewseg_tmprej, viewseg_tmprej=TMPREJ',...
                            'tag',sprintf('figRejectViewer_%d',data.hWindowCounter));
                    end
            %}
            
            chbx = {};
            for ch=1:tmp.nbchan
                chbx{ch} = uicontrol(h,'Style','checkbox','String',tmp.chanlocs(ch).labels,...
                    'Position',[8+floor((ch-1)/32)*60 pos(4)-(mod(ch-1,32)*16+20) 60 20], 'tag', sprintf('chbx%d',ch));
                if isfield(tmp.chanlocs,'badchan') && tmp.chanlocs(ch).badchan
                    chbx{ch}.Value = 1;
                else
                    chbx{ch}.Value = 0;
                end
            end
            
            viewseg_tmprej = [];
            if ~isempty(tmpcut)
                winrej = [tmpcut repmat([.5 .5 1],size(tmpcut,1),1) ones(size(tmpcut,1), tmp.nbchan)];
                eegplot(tmp.data,'srate',tmp.srate,'eloc_file',tmp.chanlocs,'spacing',50,...
                    'limits',[tmp.xmin tmp.xmax],'winlength',12,'position',screensize,...
                    'events',tmp.event,'winrej', winrej,'command', 'global viewseg_tmprej, viewseg_tmprej=TMPREJ',...
                    'tag',sprintf('figRejectViewer_%d',data.hWindowCounter), 'dispchans', min(64,tmp.nbchan));
            else
                % data3D = reshape(tmp.data(:,1:floor(tmp.pnts/tmp.srate)*tmp.srate),tmp.nchan,tmp.srate,floor(tmp.pnts/tmp.srate));
                eegplot(tmp.data,'srate',tmp.srate,'eloc_file',tmp.chanlocs,'spacing',50,...
                    'limits',[tmp.xmin tmp.xmax],'winlength',12,'position',screensize,...
                    'events',tmp.event,'command', 'global viewseg_tmprej, viewseg_tmprej=TMPREJ',...
                    'tag',sprintf('figRejectViewer_%d',data.hWindowCounter), 'dispchans', min(64,tmp.nbchan));
            end
            
            % start checking timer. This will check whether windows were closed (reject
            % or not reject, or delete button in channel rejection window)
            data.ViewSegmentNumber = ndx; % 1 for full data, 2 for first segment, etc.
            data.ViewSegmentTimer = timer('StartDelay', 1, 'Period', 1, 'TasksToExecute', Inf, ...
                'ExecutionMode', 'fixedDelay', 'busymode', 'drop');
            data.ViewSegmentTimer.TimerFcn = {@app.viewsegtimerFcn, hObject};
            start(data.ViewSegmentTimer);
            
            % update button color
            data.pbViewSeg.BackgroundColor = [1 .6 .6];
            guidata(hObject, data);
        end
        
        function popup_callback(app, popup, event)
            idx = popup.Value;
            popup_items = popup.String;
            choice = char(popup_items(idx,:));
        end
        
        function popupmenuSegments_Callback(app, hObject, eventdata, handles)
            % --- Executes on selection change in popupmenuSegments.
            
            % hObject    handle to popupmenuSegments (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenuSegments contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenuSegments
            
            data = guidata(hObject);
            data.ViewSegmentNumber = get(hObject,'Value');
            guidata(hObject, data);
        end
        
        function popupmenuSegments_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to popupmenuSegments (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function pushbuttonUseSource_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in pushbuttonUseSource.
            
            % hObject    handle to pushbuttonUseSource (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            h = figMatchChannels(gcf, @app.figUseSource_Callback);
            uiwait(h);
            
            data.pushbuttonUseSource.BackgroundColor = [.9 .8 .6];
            data.pushbuttonChanlocs.BackgroundColor = [.9 .8 .6];
            data.pbFilter.BackgroundColor = [.6 1 .6];
            
            guidata(hObject, data);
        end
        
        function pushbuttonView_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in pushbuttonView.
            
            % hObject    handle to pushbuttonView (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            data = guidata(hObject);
            data.pushbuttonView.BackgroundColor = [.3 .6 .3];
            pause(0.005)
            
            if ~isfield(data,'EEG') || isempty(data.EEG.data)
                msgbox('No data available');
                data.pushbuttonView.BackgroundColor = [1 .6 .6];
                return
            end
            
            screensize = get(groot, 'Screensize' );
            tmp = data.EEG;
            eegplot(tmp.data,'srate',tmp.srate,'eloc_file',tmp.chanlocs,'spacing',50,...
                'limits',[tmp.xmin tmp.xmax],'winlength',12,'position',screensize,...
                'events',tmp.event, 'dispchans', min(64,tmp.nbchan));
            
            data.pushbuttonView.BackgroundColor = [.6 1 .6];
            guidata(hObject,data);
        end
        
        function setFontSize(app, hObject, fs)
            
                try
                    chlist = hObject.Children;
                catch
                    return
                end
            
                for ch=1:length(chlist)
                    if isprop(chlist(ch), 'fontsize')
                        try
                            set(chlist(ch), 'fontsize', fs);
                        catch
                            beep
                        end
                    end
                    if isprop(chlist(1), 'children')
                        if ~isempty(get(chlist(ch), 'children'))
                            setFontSize(app, chlist(ch), fs);
                        end
                    end
                end
        end
        
        function skip_or_overwrite_dialog(app)
                % Create a UI figure
                fig = uifigure('Name', 'Action Required', 'Position', [500 500 300 150]);
            
                % Add text label
                lbl = uilabel(fig, ...
                    'Text', 'Some output files exists. What would you like to do?', ...
                    'Position', [25 80 250 40], ...
                    'HorizontalAlignment', 'center');
            
                % Create buttons and define callbacks
                btnSkip = uibutton(fig, 'push', ...
                    'Text', 'Skip all', ...
                    'Position', [50 30 80 30], ...
                    'ButtonPushedFcn', @(btn,event) buttonCallback(app, fig, 'skip'));
            
                btnOverwrite = uibutton(fig, 'push', ...
                    'Text', 'Overwrite', ...
                    'Position', [170 30 80 30], ...
                    'ButtonPushedFcn', @(btn,event) buttonCallback(app, fig, 'overwrite'));
            
                % Block program execution until the user closes the figure
                uiwait(fig);
        end
        
        function sliderHighdB_Callback(app, hObject, eventdata, handles)
            % --- Executes on slider movement.
            
            % hObject    handle to sliderHighdB (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            
            data = guidata(hObject);
            hObject.Value = round(hObject.Value);
            data.textHighdB.String = sprintf('High dB cutoff: %d',get(hObject, 'Value'));
            guidata(hObject, data);
        end
        
        function sliderHighdB_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to sliderHighdB (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: slider controls usually have a light gray background.
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end
        
        function sliderInitialBadChanSD_Callback(app, hObject, eventdata, handles)
            % --- Executes on slider movement.
            
            % hObject    handle to sliderInitialBadChanSD (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            data = guidata(hObject);
            data.textInitialBadChanSD.String = sprintf('%.1f', get(hObject,'Value'));
        end
        
        function sliderInitialBadChanSD_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to sliderInitialBadChanSD (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: slider controls usually have a light gray background.
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end
        
        function sliderLowdB_Callback(app, hObject, eventdata, handles)
            % --- Executes on slider movement.
            
            % hObject    handle to sliderLowdB (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            
            data = guidata(hObject);
            hObject.Value = round(hObject.Value);
            data.textLowdB.String = sprintf('Low dB cutoff: %d',get(hObject, 'Value'));
            guidata(hObject, data);
        end
        
        function sliderLowdB_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to sliderLowdB (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: slider controls usually have a light gray background.
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end
        
        function viewsegtimerFcn(app, obj, event, arg1)
            % The timerFcn is called after the window becomes visible.
            
            
            % arg1 is the parent window passed in the viewsegtimerFcn specification above
            data = guidata(arg1);
            global viewseg_tmprej;
            
            stop(obj);
            
            % check if data rejection window is still open. Get the handle. Return is
            % window still open. If the bad channel window "reject" button was pressed,
            % then continue to remove bad channels.
            hWindow = findall(0,'tag',sprintf('figRejectViewer_%d',data.hWindowCounter));
            try
                % try to get the bad channel window data. Exception handles situation
                % where bad channel window was closed before the data rejection window.
                badchans_data = guidata(data.hBadChans);
                if ~isempty(hWindow) && ~badchans_data.DeletePressed
                    % data rejection window still open. (as well as channel data
                    % window) restart timer and continue waiting.
                    start(obj);
                    return
                end
            catch E
                % probably because the bad channel window was closed. Check if eegplot
                % window is still open
                if ~isempty(hWindow)
                    % data rejection window still open. restart timer and continue
                    % waiting (restart timer)
                    start(obj);
                    return
                end
                % fallthru: window is now closed (and
                warning('no bad channel selection window found');
                badchans_data = struct;
                badchans_data.DeletePressed = false;
            end
            
            % stop the timer checking if there is no rejection AND delete button was
            % not pressed.
            if isempty(viewseg_tmprej) && ~badchans_data.DeletePressed
                % reject button not pressed so stop checking
                try
                    close(data.hBadChans);
                catch
                end
            
                delete(obj);
                set(data.pbViewSeg, 'BackgroundColor', [.6 1 .6])
                set(data.pbICA, 'BackgroundColor', [.6 1 .6]);
                return
            end
            
            % winrej data found
            
            try
                ndx = data.ViewSegmentNumber;
                switch ndx
                    case 1
                        % when delete button pressed, remove channels. Otherwise,
                        % remove bad parts
                        if badchans_data.DeletePressed
                            % look for the checkbox controls and read value
                            for ch=1:data.EEG.nbchan
                                ctrl = findall(data.hBadChans,'tag',sprintf('chbx%d',ch));
                                data.bad(ch) = get(ctrl,'Value');
                                data.EEG.chanlocs(ch).badchan = data.bad(ch);
                            end
                            if sum(data.bad)>0
                                data.pbAutoRej.BackgroundColor = [.6 1 .6];
                                [data.EEG , cmd] = pop_select(data.EEG,'nochannel',find(data.bad));
                                data.history = [data.history char(uint8(10)) cmd];
                                data.bad = zeros(data.EEG.nbchan,1);
                                if data.popupmenuReref.Value==3 || strcmpi(data.EEG.ref, 'average') % average reference
                                    fprintf('*** Rereferencing again to average.\n')
                                    data.EEG.data = data.EEG.data - repmat(mean(data.EEG.data),size(data.EEG.data,1),1);
                                end
                            end
                        else
                            % delete marked data or epochs
                            if ndims(data.EEG.data)<3
                                % continuous data removal
                                [data.EEG, cmd] = eeg_eegrej(data.EEG, viewseg_tmprej(:,1:2));
                                data.history = [data.history char(uint8(10)) cmd];
                            else
                                % epoched data removal
                                [tmprej tmprejE] = eegplot2trial(viewseg_tmprej, data.EEG.pnts, data.EEG.trials);
                                [data.EEG, cmd] = pop_rejepoch(data.EEG, tmprej, 0);
                                data.history = [data.history char(uint8(10)) cmd];
                            end
                            data.cut = [];
                        end
                    otherwise
                        if badchans_data.DeletePressed
                            % look for the checkbox controls and read value
                            for ch=1:data.segment(ndx-1).EEG.nbchan
                                ctrl = findall(data.hBadChans,'tag',sprintf('chbx%d',ch));
                                data.segment(ndx-1).bad(ch) = get(ctrl,'Value');
                                data.segment(ndx-1).EEG.chanlocs(ch).badchan = data.segment(ndx-1).bad(ch);
                            end
                            if sum(data.segment(ndx-1).bad)>0
                                data.pbAutoRej.BackgroundColor = [.6 1 .6];
                                data.segment(ndx-1).EEG = pop_select(data.segment(ndx-1).EEG,'nochannel',find(data.segment(ndx-1).bad));
                                data.segment(ndx-1).bad = zeros(data.segment(ndx-1).EEG.nbchan,1);
                                if data.popupmenuReref.Value==3 % average reference
                                    fprintf('*** Rereferencing again to average.\n')
                                    data.EEG.data = data.EEG.data - repmat(mean(data.EEG.data),size(data.EEG.data,1),1);
                                end
                            end
                        else
                            % remove data stretches or epochs
                            if ndims(data.segment(ndx-1).EEG.data)<3
                                % continuous data removal
                                data.segment(ndx-1).EEG = eeg_eegrej(data.segment(ndx-1).EEG, viewseg_tmprej(:,1:2));
                            else
                                % epoched data removal
                                epndx = floor(([viewseg_tmprej(:,1),viewseg_tmprej(:,2)-1])./size(data.segment(ndx-1).EEG.data,2))+1;
                                epndxall = [];
                                for row=1:size(epndx,1)
                                    epndxall = union(epndxall,epndx(row,1):epndx(row,2));
                                end
                                [data.segment(ndx-1).EEG] = pop_rejepoch(data.segment(ndx-1).EEG, epndxall, 0);
                            end
                            data.segment(ndx-1).cut = [];
                        end
                end
            catch E
                pause(0.005);
                % likely timer is an old one.
            end
            
            try
                close(data.hBadChans);
            catch
            end
            try
                close(hWindow);
            catch
            end
            guidata(arg1,data);
            listboxEegProperties_Update(app, arg1)
            delete(obj);
            set(data.pbViewSeg, 'BackgroundColor', [.3 1 .3])
            set(data.pbICA, 'BackgroundColor', [.6 1 .6]);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function guiEegAutoflow_OpeningFcn(app, varargin)
            % --- Executes just before guiEegAutoflow is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.fig_eeg_workflow, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to guiEegAutoflow (see VARARGIN)
            
            % Choose default command line output for guiEegAutoflow
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            % UIWAIT makes guiEegAutoflow wait for user response (see UIRESUME)
            % uiwait(handles.fig_eeg_workflow);
            
            data = guidata(hObject);

            % before anything, locate all the slider objects, the
            % associated text objects (by name) and retrieve the text
            % object strings that will serve as a format string for the
            % sprints statements in the slider callbacks and the
            % initialisation procedure!
            fns = fieldnames(data);
            slidernames = fns(contains(fns,'slider', 'IgnoreCase', true));
            data.textfieldvalues = struct();
            for sn=1:length(slidernames)
                textname = strrep(slidernames{sn}, 'slider', 'text');
                data.textfieldvalues(sn).slidername = slidernames{sn};
                data.textfieldvalues(sn).textname = textname;
                data.textfieldvalues(sn).textvalue = data.(textname).String;
            end

            % now update the text fields
            updateSliderAndText(app, data)

            % now start up the window
            AddToListbox(app, data.listboxStdout, '  *** Warning *** EEGLAB with specific plugins is required')
            AddToListbox(app, data.listboxStdout, '   - EEGLAB V2020 has been tested, requires signal processing toolbox')
            AddToListbox(app, data.listboxStdout, '   - AAR')
            AddToListbox(app, data.listboxStdout, '   - CleanRawdata')
            AddToListbox(app, data.listboxStdout, '   - ICLabel')
            AddToListbox(app, data.listboxStdout, '   - file import plugins (ANT, Biosemi, EDF)')
            AddToListbox(app, data.listboxStdout, '   - and several support functions')
            
            if isempty(which("eeglab.m"))
                AddToListbox(app, data.listboxStdout, '*** warning *** cannot find EEGLAB. Please activate locate.')
                data.listboxStdout;
                filepath = uigetdir(pwd, "Locate EEGLAB");
                addpath(filepath)
                eeglab;
            end
   
            % set the values of the uicontrols. Read
            if ismac()
                data.INIDIR = '~/Application Support/Matlab_EegAutoFlow';
            elseif isunix
                data.INIDIR = '~/.config/Matlab_EegAutoFlow';
            elseif ispc
                data.INIDIR = '~/AppData/Matlab_EegAutoFlow';
            else
                warning('unknown system')
                data.INIDIR = './';
            end
            
            % read the defualt values
            try
                FN = sprintf('%s/%s.ini', data.INIDIR, get(hObject,'name'));
                opts = detectImportOptions(FN, 'TextType', 'string', 'filetype', 'text');
                opts.DataLines = [2 Inf];
                opts.VariableTypes(:) = {'string'};  % Force all columns to string
                strlist = readtable(FN, opts);
                SetUIControlData(app, hObject, strlist);
            catch E
                warning('Initialization file not found. Will be created on close.')
            end

            % rerun slider and text updating, as sliders may have been
            % updated.
            updateSliderAndText(app, data);
            
            % initialise fontsize
            if ispc
                data.fontsize = 9;
            else
                data.fontsize = 10;
            end
            setFontSize(app, hObject.Parent, data.fontsize)
            
            % push the data to the object
            guidata(hObject, data);
        end

        % Value changed function: checkboxTaskNoTask
        function checkboxTaskNoTask_Callback(app, event)
            % --- Executes on button press in checkboxTaskNoTask.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            % hObject    handle to checkboxTaskNoTask (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkboxTaskNoTask
            
            data = guidata(hObject);
            tmp = ifthen(get(hObject,"Value")>0, 'Rm task', 'Rm no-task');
            data.pushbuttonRemoveResting.String = tmp;
        end

        % Close request function: fig_eeg_workflow
        function fig_eeg_workflow_CloseRequestFcn(app, event)
            % --- Executes when user attempts to close fig_eeg_workflow.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to fig_eeg_workflow (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: delete(hObject) closes the figure
            
            try data = guidata(hObject);
            
                % save all the settings
                
                strlist = GetUIControlData(app, hObject);
                
                % Save the data in the INI directory
                if ~exist(data.INIDIR,'dir')
                    mkdir(data.INIDIR)
                end
                writetable(strlist,sprintf('%s/%s.ini', data.INIDIR, get(hObject,'name')), 'delimiter','\t','filetype','text')
                
                % remove the binica files in current directory
                %files = dir('binica*'); % Get all matching files
                files = dir('runica*'); % Get all matching files
                for f = 1:length(files)
                    delete(fullfile(files(f).folder, files(f).name));
                end
            catch
                warndlg('Error saving settings.')
            end
            
            delete(hObject);
        end

        % Button pushed function: pbBatch
        function pbBatch_Callback(app, event)
            % --- Executes on button press in pbBatch. -------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbBatch (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global skipOverwrite_choice %#ok<GVMIS>
            
            data = guidata(hObject);
            
            % open the batch modal window to get the settings
            h = figRunBatchModal(gcf, data.INIDIR);
            uiwait(h);
            pause(.05)
            data = guidata(hObject);
            
            if isfield(data,'batchfilenames') &&  ~isempty(data.batchfilenames)
                % loop thourgh files and apply the checked functions
                resultTable = [];
            
                % determine output filenames
                data.batchfilenamesout = {};
                for f=1:length(data.batchfilenames)
                    FNOut = [data.batchpathname '/' sprintf('%s%s', data.batchprefix, data.batchfilenames{f})];
                    [pth,fle,ext] = fileparts(FNOut);
                    FNOut = sprintf('%s/%s.set', pth, fle);
                    data.batchfilenamesout{end+1} = FNOut;
                end

                % test to ask for overwrite
                ex = false;
                for f=1:length(data.batchfilenamesout)
                    if exist(data.batchfilenamesout{f},'file')
                        continue
%                         ex = true;
%                         break
                    end
                end
                % ask for skip or overwrite
                if ex
                    skip_or_overwrite_dialog(app);
                end
            
            
                for f=1:length(data.batchfilenames)
                    try
                        % filename for output
                        FNOut = data.batchfilenamesout{f};
                        % skip if it exists and so requested.
                        if exist(FNOut) && strcmpi(skipOverwrite_choice, 'skip')
                            continue
                        end
            
            
                        % read the file
                        EEG = loadfile(app, data.batchpathname, data.batchfilenames{f}, ...
                            data.listboxStdout, data.checkboxBiosig, data.checkboxDataAreAvgRef);
                        %test modification
                        if ~isfield(EEG, 'event') || isempty(EEG.event)
                            EEG.event=struct('type', {}, 'latency', {}, 'urevent', {});
                        end
                        %end test

                        data.EEG = EEG;


                        % Initialize stack for history
                        if ~isfield(data, 'Stack') || isempty(data.Stack)
                            data.Stack = {};
                        end
                        if ~isfield(data, 'StackLabel') || isempty(data.StackLabel)
                            data.StackLabel = {};
                        end
                        
                        data.tabLine = struct();
                        data.tabLine.starttime = size(data.EEG.data(:,:),2) / data.EEG.srate;
                        guidata(hObject, data);

%                         data.tabLine = struct();
%                         data.tabLine.starttime = size(data.EEG.data(:,:),2) / data.EEG.srate;
%                         guidata(hObject, data);
                        pause(0.01); % allows the upload
%             
                        % perform the selected actions in a fixed order with the
                        % settings as specified.
                        T = data.batchchecked; % table holds 'order','tag','number', 'checked'
                        for cb=1:size(T,1)
                            if T.checked(cb)
                                % run this checkbox
                               switch T.number(cb)
                                    case 1
                                        pushbuttonFlatline_Callback(app, event);
                                        data=guidata(hObject);
                                    case 2
                                        pushbuttonExcessive_Callback(app, event);
                                        data=guidata(hObject);
                                    case 3
                                        pushbuttonChanlocs_Callback(app, event);
                                        data=guidata(hObject);
                                    case 4
                                        pushbuttonResample_Callback(app, event);
                                        data=guidata(hObject);
                                    case 5
                                        pushbuttonReref_Callback(app, event);
                                        data=guidata(hObject);
                                    case 6
                                        pushbuttonFilter_Callback(app, event);
                                        data=guidata(hObject);
                                    case 7
                                        pushbuttonInitialICA_Callback(app, event);
                                        data=guidata(hObject);
                                    case 8
                                        pushbuttonAltInitialEOG_Callback(app, event);
                                        data=guidata(hObject);
                                    case 9
                                        pushbuttonAARWinSec_Callback(app, event);
                                        data=guidata(hObject);
                                    case 10
                                        pushbuttonAltAAR_Callback(app, event);
                                        data=guidata(hObject);
                                    % channel remove checkboxes
                                    case 11
                                        pushbuttonRemoveNoEEG_Callback(app, event);
                                        data=guidata(hObject);
                                    case 12
                                        pushbuttonRemoveEOG_Callback(app, event);
                                        data=guidata(hObject);
                                    % remaining cleaning procedures flatline eperiods,
                                    % cleanrawdata and IClabel cleaning
                                    case 13
                                        pushbuttonFlatPeriods_Callback(app, event);
                                        data=guidata(hObject);
                                    case 14
                                        pushbuttonClean_Callback(app, event);
                                        data=guidata(hObject);
                                    case 15
                                        pushbuttonICLabel_Callback(app, event);
                                        data=guidata(hObject);
                                    case 16
                                        pushbuttonExcessivePeriodsButtonPushed(app, event)
                                        data=guidata(hObject);
                               end
                            end
                        end
            
                        % clear the stack
                        data.Stack = {};
                        data.StackLabel = {};
                        guidata(hObject, data);
            
                        % save the file with prefix batch
                        % With proper path handling:
                        [filepath, filename, fileext] = fileparts(data.batchfilenamesout{f});
                        if isempty(filepath)
                            filepath = data.batchpathname; % Get path from GUI
                        end
                        pop_saveset(data.EEG, 'filename', [filename, fileext], ...
                                              'filepath', filepath, ...
                                              'savemode', 'onefile')
                        
                        data.tabLine.finaltime = size(data.EEG.data(:,:),2) / data.EEG.srate;
            
            
                        % collect the table output
                        try
                            data.tabLine.filename = data.batchfilenames{f};
                            if isempty(resultTable)
                                resultTable = data.tabLine;
                            else
                                resultTable = cat(1, resultTable, data.tabLine);
                            end
            
            
                            % Intermediate save: append to output file
                            rowData = struct2cell(data.tabLine);
                            fid = fopen(sprintf('%s/Metadata_Batch.txt', data.batchpathname), 'a');
                            if fid>0
                                first = true;
                                for c=1:length(rowData)
                                    if ~first
                                        fprintf(fid, '\t')
                                    end
                                    switch class(rowData{c})
                                        case {'int8','int16','int32','int64','uint8','uint16','uint32','uint64'}
                                            fprintf(fid, '%d', rowData{c});
                                        case 'double'
                                            fprintf(fid, '%.4g', rowData{c});
                                        case 'char'
                                            fprintf(fid, '%s', rowData{c});
                                    end
                                    first = false;
                                end
                                fprintf(fid, '\n')
                            end
                            fclose(fid)
                        catch
                            warning('collecting metadata for this subject failed.');
                        end
            
                    catch E
                        fprintf(2, 'Error in file %s:\n%s\n', data.batchfilenames{f}, E.getReport());
                        continue
                        %AddToListbox(app, data.listboxStdout, '*** Undefined error. Continuing with next file')
                        % AddToListbox(data.listboxStdout, sprintf('- %s', E.message))
                    end
            
                end
            
                %system(sprintf('open "%s"', data.batchpathname))
          
            end
        end

        % Button pushed function: pbCompERP
        function pbCompERP_Callback(app, event)
            % --- Executes on button press in pbCompERP.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbCompERP (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            
            data = guidata(hObject);
            
            tmp = data.EEG; 
            
            if size(tmp.data,3) == 1
                error('Data must be in epochs')
            end
            
            % extract unique events and eventlist (only FIRST event in epoch)
            if iscell(tmp.epoch(1).eventtype)
                evtlist = arrayfun(@(x)x.eventtype(1),tmp.epoch,'uniformoutput',false);
                try
                    evtlist = [evtlist{:}];
                catch
                end
            else
                evtlist = {tmp.epoch.eventtype};
            end
            uniqueevt = unique(evtlist);
            uniqueevt = uniqueevt(~strcmp(uniqueevt,'boundary'));
            
            % set up plotting
            figure('pos',[50,50,800,600]);
            nchans = size(tmp.icawinv,2);
            nrows = floor(sqrt(nchans));
            ncols = ceil(sqrt(nchans));
            if nrows*ncols<nchans
                ncols=ncols+1;
            end
            
            % now start plotting
            colors = {[0 0 0]; [.8 .2 .2]; [.3 .3 .8]; [.2 .7 .2]; [1 0 0]; [0 0 .8]; [0 .7 0]};
            times = tmp.xmin:(1/tmp.srate):tmp.xmax;
            Xs = [tmp.chanlocs.X];
            Ys = [tmp.chanlocs.Y];
            Zs = [tmp.chanlocs.Z];
            if ~isfield(tmp,'icaact') || isempty(tmp.icaact)
                tmp.icaact = icaact(tmp.data, tmp.icaweights*tmp.icasphere);
            end
            epochdata = reshape(tmp.icaact,size(tmp.icaact,1),tmp.pnts,[]);
            ERP = [];
            for e=1:length(uniqueevt)
                ERP(:,:,e) = mean(epochdata(:,:,strcmpi(evtlist,uniqueevt{e})),3);
            end
            for ch=1:size(epochdata,1)
                h = subplot(nrows,ncols,ch);
                hold on
                for e=1:length(uniqueevt)
                    plot(times, ERP(ch,:,e),'-','color',colors{e});
                end
                ylim([min(ERP(:)) max(ERP(:))])
                xlim([tmp.xmin tmp.xmax])
                box off
                title(sprintf('comp%d',ch))
                % add topographic plot
                pos = get(gca,'pos');
                axes('pos',[pos(1)+.65*pos(3) pos(2) pos(3)*.4 pos(4)*.4])
                topoplot(tmp.icawinv(:,ch),tmp.chanlocs,'electrodes','off')
                box off
                axis off
                % update
                drawnow;
            end
        end

        % Button pushed function: pbDFA
        function pbDFA_Callback(app, event)
            % --- Executes on button press in pbDFA.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbDFA (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            
            data = guidata(hObject);
            tmp = data.EEG;
            
            AddToListbox(app, data.listboxStdout, 'Plotting DFA of alpha oscillations, EEG channels');
            
            lo = 7.0;
            hi = 13;
            
            % Only take EEG, i.e. channels with a location and not *EOG*
            deselect = contains({tmp.chanlocs.labels}, 'eog', 'ignorecase', true) | ...
                strcmpi({tmp.chanlocs.labels}, 'A1') | ...
                strcmpi({tmp.chanlocs.labels}, 'A2') | ...
                strcmpi({tmp.chanlocs.labels}, 'M1') | ...
                strcmpi({tmp.chanlocs.labels}, 'M2') | ...
                isempty([tmp.chanlocs.X]);
            exp = dfa(abs(hilbert(filter_fir(tmp.data(~deselect,:), tmp.srate, lo, hi, 3.0, true)')), tmp.srate);
            
            
            % Create figure if needed, otherwise put the figure in front
            if ~isfield(data, 'figDFA') || isempty(data.figDFA) || ~isstruct(data.figDFA) || ~isfield(data.figDFA, 'fig') || ~isvalid(data.figDFA.fig)
                AddToListbox(app, data.listboxStdout, '- creating figure');
                if ~isfield(data, 'figDFA')
                    data.figDFA = struct;
                end
                fig = figure('Name', 'plot DFA topoplot', 'Tag', 'figDFA', 'NumberTitle', 'off', ...
                             'Position', [50, 50, 300, 250]); % Adjust size as needed
                ax = axes('Parent', fig);
                data.figDFA.fig = fig;
                data.figDFA.ax = ax;
            
            else
                AddToListbox(app, data.listboxStdout, '- activating figure');
                fig = data.figDFA.fig;
                ax = data.figDFA.ax;
                figure(fig);
            end
            
            tmp.nbchan = size(tmp.data,1);
            clf;
            topoplot(exp, tmp.chanlocs(~deselect), 'maplimits', [.5 .8]);
            colorbar;
            
            guidata(hObject, data);
        end

        % Button pushed function: pbERP
        function pbERP_Callback(app, event)
            % --- Executes on button press in pbERP.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbERP (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            data = guidata(hObject);
            
            tmp = data.EEG;
            
            if size(tmp.data,3) == 1
                error('Data must be in epochs')
            end
            
            
            if isfield(tmp, 'eventlist')
                uniqueevt = tmp.eventlist;
                if isnumeric(tmp.epoch(1).eventtype)
                    numflag = true;
                end
                evtlist = {};
                for ep=1:length(tmp.epoch)
                    try
                        ndx = find(ismember(tmp.eventlist, tmp.epoch(ep).eventtype));
                    catch
                        ndx = [];
                    end
                    if ~isempty(ndx)
                        evtlist{ep} = tmp.eventlist{ndx};
                    else
                        evtlist{ep} = [];
                    end
                end
            else
                % extract unique events and eventlist (only FIRST event in epoch)
                if iscell(tmp.epoch(1).eventtype)
                    evtlist = arrayfun(@(x)x.eventtype(1),tmp.epoch,'uniformoutput',false);
                    evtlist = [evtlist{:}];
                    uniqueevt = unique(evtlist);
                else
                    evtlist = {tmp.epoch.eventtype};
                    uniqueevt = unique(evtlist);
                end
                uniqueevt = uniqueevt(~strcmp(uniqueevt,'boundary'));
            end
            
            % set up plotting
            figure('pos',[50,50,800,600]);
            nchans = size(tmp.data,1);
            nrows = floor(sqrt(nchans));
            ncols = ceil(sqrt(nchans));
            if nrows*ncols<nchans
                ncols=ncols+1;
            end
            
            % now start plotting
            colors = {[0 0 0]; [.8 .2 .2]; [.3 .3 .8]; [.2 .7 .2]; [1 0 0]; [0 0 .8]; [0 .7 0]};
            times = tmp.xmin:(1/tmp.srate):tmp.xmax;
            Xs = [tmp.chanlocs.Y]; % deliberate swap of X and Y!
            Ys = [tmp.chanlocs.X];
            Zs = [tmp.chanlocs.Z];
            ERP = [];
            for e=1:length(uniqueevt)
                ERP(:,:,e) = mean(tmp.data(:,:,strcmpi(evtlist,uniqueevt{e})),3);
            end
            for ch=1:size(tmp.data,1)
                pos = [.2+.6*(Xs(ch)-min(Xs))/range(Xs) .2+.6*(Ys(ch)-min(Ys))/range(Ys) .08 .08];
                if Zs(ch)<20, % move Zs curving under to the outside
                    pos(1:2) = (pos(1:2)-.5)*(1.1+abs(.3*((Zs(ch)-20)/range([20 min(Zs)]))))+.5;
                end
                axes('position',pos);
                hold on
                for e=1:length(uniqueevt)
                    plot(times, ERP(ch,:,e),'-','color',colors{e});
                end
                ylim([min(ERP(:)) max(ERP(:))])
                xlim([tmp.xmin tmp.xmax])
                box off
                axis off
                text(min(xlim)+range(xlim)*.1,min(ylim)+range(ylim)*.1,tmp.chanlocs(ch).labels)
                drawnow;
            end
        end

        % Button pushed function: pbEpoch
        function pbEpoch_Callback(app, event)
            % --- Executes on button press in pbEpoch.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbEpoch (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            data = guidata(hObject);
            
            h = guiEpoch(data.EEG, gcf);
            uiwait(h);
        end

        % Button pushed function: pbUndo
        function pbUndo_Callback(app, event)
            % --- Executes on button press in pbUndo.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbUndo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            data = guidata(hObject);
            
            if isempty(data.Stack)
                beep;
                AddToListbox(app, data.listboxStdout, 'No more saved datasets.');
            else
                AddToListbox(app, data.listboxStdout, sprintf('Undo %s',data.StackLabel{end}));
                data.EEG = data.Stack{end};
                if length(data.Stack)==1
                    data.Stack = {};
                    data.StackLabel = {};
                else
                    data.Stack  = data.Stack(1:end-1);
                    data.StackLabel  = data.StackLabel(1:end-1);
                end
            end
            
            guidata(hObject, data);
        end

        % Button pushed function: pbViewPSD
        function pbViewPSD_Callback(app, event)
            % --- Executes on button press in pbViewPSD.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbViewPSD (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            data = guidata(hObject);
            tmp = data.EEG;
            chanlocs = tmp.chanlocs;
            
            AddToListbox(app, data.listboxStdout, 'Plotting PSD');
            
            lo = data.spinnerLow.Value;
            hi = data.spinnerHigh.Value;
            
            [P,fs] = calc_PSD(app, tmp);
            
            % Create figure if needed, otherwise put the figure in front
            if ~isfield(data, 'figPSD') || isempty(data.figPSD) || ~isstruct(data.figPSD) || ~isfield(data.figPSD, 'fig') || ~isvalid(data.figPSD.fig)
                AddToListbox(app, data.listboxStdout, '- creating figure');
                if ~isfield(data, 'figPSD')
                    data.figPSD = struct;
                end
                fig = figure('Name', 'plot Power Spectrum', 'Tag', 'figPSD', 'NumberTitle', 'off', ...
                             'Position', [100, 100, 700, 500]); % Adjust size as needed
                % Create a frame (uipanel), checkbox, and create axes inside the panel
                frame = uipanel('Parent', fig, 'Title', 'Power spectrum', 'Tag', 'framePSD', ...
                                'Position', [0.005, 0.1, .98, 0.9]); % [x, y, width, height]
                ax = axes('Parent', frame, 'Position', [0.07, 0.1, .91, 0.88], 'Tag', 'axesPSD'); % Adjust within panel
                checkbox = uicontrol('Style', 'checkbox', 'Parent', fig, 'Tag', 'cbSummary', ...
                                     'String', 'Summarize into regions', 'Fontsize', data.fontsize, ...
                                     'Units', 'normalized', ...
                                     'Position', [0.05, 0.03, 0.4, 0.05], ...
                                     'Callback', @(src, event) checkbox_callback(app, src, ax, fs, P, chanlocs, data.fontsize+1, lo, hi));
                data.figPSD.fig = fig;
                data.figPSD.ax = ax;
                data.figPSD.checkbox = checkbox;
                set(ax,'xlim',[0 min(hi,45)])
            
            else
                AddToListbox(app, data.listboxStdout, '- activating figure');
                fig = data.figPSD.fig;
                ax = data.figPSD.ax;
                checkbox = data.figPSD.checkbox;
                figure(fig);
            end
            
            checkbox_callback(app, checkbox, ax, fs, P, chanlocs, data.fontsize+1, lo, hi);
            
            guidata(hObject, data);
        end

        % Button pushed function: pbView
        function pbView_Callback(app, event)
            % --- Executes on button press in pbView.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbView (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            data = guidata(hObject);
            
            if ~isfield(data,'EEG') || isempty(data.EEG.data)
                msgbox('No data available');
                data.pbView.BackgroundColor = [1 .6 .6];
                return
            end
            
            screensize = get(groot, 'Screensize' );
            tmp = data.EEG;
            eegplot(tmp.data,'srate',tmp.srate,'eloc_file',tmp.chanlocs,'spacing',50,...
                'limits',[tmp.xmin tmp.xmax],'winlength',12,'position',screensize,...
                'events',tmp.event, 'submean', 'on', 'dispchans', min(64,tmp.nbchan));
            
            guidata(hObject,data);
        end

        % Button pushed function: pushbuttoSaveChanlocs
        function pushbuttoSaveChanlocs_Callback(app, event)
            % --- Executes on button press in pushbuttoSaveChanlocs.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttoSaveChanlocs (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            data = guidata(hObject);
            
            if ~isfield(data,'EEG')|| isempty(data.EEG.chanlocs)
                AddToListbox(app, data.listboxStdout, '*** Warning *** No EEG data to save channel locations.');
                return
            end
            
            Chanlocs = data.EEG;
            Chanlocs.data = []; % do not save the data, only channel locations
            Chanlocs.icaact = [];
            save('.chanlocs.mat',"Chanlocs")
            AddToListbox(app, data.listboxStdout, 'Saving EEG data channel locations');
            AddToListbox(app, data.listboxStdout, sprintf('- %d channels saved', Chanlocs.nbchan));
        end

        % Button pushed function: pushbuttonRemoveFirstSec
        function pushbuttonRemoveFirstSec_Callback(app, event)
            % --- Executes on button press in pushbutton38.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton38 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            
            data = guidata(hObject);
            
            AddToListbox(app, data.listboxStdout, '- removing first 1 second of data');
            
            % as always, work on tmp in stead of data.EEG
            [tmp, cmd] = pop_select(data.EEG, 'rmtime', [0 1]);
            tmp.history = [tmp.history char(uint8(10)) cmd];
                    
            % push existing data onto stack. Update <data.EEG> to tmp.
            data.Stack{length(data.Stack)+1} = data.EEG;
            data.StackLabel{length(data.Stack)+1} = 'Remove first second';
            data.EEG = tmp;
            guidata(hObject, data);
        end

        % Button pushed function: pushbuttonAARWinSec
        function pushbuttonAARWinSec_Callback(app, event)
            % --- Executes on button press in pushbuttonAARWinSec.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonAARWinSec (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            data = guidata(hObject);
            set(hObject,'backgroundcolor',[.3 .6 .3])
            pause(0.005);
            
            AddToListbox(app, data.listboxStdout, 'Clean data of muscle actvit using AAR in 40s windows.');
            
            tmp = data.EEG;
            ws = data.sliderAARWinSec.Value;
            ss = data.sliderAARShift.Value;
            tmp = pop_autobssemg(tmp, ws, ss, 'bsscca', {'eigratio', [1000000]}, ...
                'emg_psd', {'ratio', [10],'fs', [256],'femg', [15],...
                'estimator', spectrum.welch, 'range', [0  34]});
            
            % push existing data onto stack. Update <data.EEG> to tmp.
            data.Stack{length(data.Stack)+1} = data.EEG;
            data.StackLabel{length(data.Stack)+1} = 'AAR';
            data.EEG = tmp;
            guidata(hObject, data);
            recordPower(app, event, 'AAR')
            
            set(hObject,'backgroundcolor',[.9 .8 .6])
            pause(0.005);
        end

        % Button pushed function: pushbuttonAltAAR
        function pushbuttonAltAAR_Callback(app, event)
            % --- Executes on button press in pushbuttonAltAAR.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonAltAAR (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            data = guidata(hObject);
            
            set(hObject,'backgroundcolor',[.3 .6 .3])
            pause(0.005);
            
            tmp = data.EEG;
            [~,~,~,~,~,int_data] = InterpolationCleaning(tmp);
            
            len = tmp.srate*5;
            startpnts = 1:len:(tmp.pnts-len+1);
            Forig = nan(len,tmp.nbchan,length(startpnts));
            Fint  = Forig;
            Fnew  = Forig;
            fft_fs = linspace(0,tmp.srate,len);
            fft_fs = fft_fs(1:end-1);
            
            Porig = nan(tmp.srate/2+1,tmp.nbchan,length(startpnts));
            Pint  = Porig;
            cnt = 0;
            for start=startpnts
                cnt=cnt+1;
                Forig(:,:,cnt) = fft(tmp.data(:, start:start+len-1)');
                Fint(:,:,cnt)  = fft(int_data(:, start:start+len-1)');
                [Porig(:,:,cnt), ~] = pfft(tmp.data(:, start:start+len-1)', tmp.srate, ones(1,tmp.srate), 0);
                [Pint(:,:,cnt), fs] = pfft(int_data(:, start:start+len-1)', tmp.srate, ones(1,tmp.srate), 0);
            
                % determine upward slope for original signal in high freq region. NOTE
                % negative tstat means more power in uncleaned data.
                ndx = fs>13&fs<35;
                [~,~,~,reg] = ttest(db(Pint(ndx,:,1)),db(Porig(ndx,:,1)));
                select = reg.tstat < -data.sliderTstat.Value;
            
                % assume all data is good. data_new is ordered in rows!
                data_new(:,:,cnt) = tmp.data(:, start:start+len-1);
            
                Fnew(:,:,cnt) = Forig(:,:,cnt);
                if sum(select)
                    % create a linspace from 0 to one from 13 to 35 hertz FOR FFT.
                    weight = zeros(1, len);
                    weight(fft_fs>=35) = 1;
                    weight(fft_fs>=13 & fft_fs<=35) = linspace(0,1,sum(fft_fs>=13 & fft_fs<=35));
                    % duplicate around center
                    weight = [weight(1:end/2) 1 weight(end/2:-1:2)];
            
                    Fnew(:,select,cnt) = Forig(:,select,cnt) .* repmat((1-weight)',1, sum(select)) + Fint(:,select,cnt) .* repmat(weight', 1, sum(select));
                    data_new(select,:,cnt) = ifft(Fnew(:,select,cnt))';
                end
            end
            
            tmp.data = data_new(:,:);
            tmp.pnts = size(tmp.data,2);
            tmp.xmax = (tmp.pnts-1)/tmp.srate;
            
            % push existing data onto stack. Update <data.EEG> to tmp.
            data.Stack{length(data.Stack)+1} = data.EEG;
            data.StackLabel{length(data.Stack)+1} = 'Alternate EMG cleaning';
            data.EEG = tmp;
            
            set(hObject,'backgroundcolor',[.9 .8 .6])
            
            guidata(hObject, data)
            recordPower(app, event, 'AltEMG')
        end

        % Button pushed function: pushbuttonAltInitialEOG
        function pushbuttonAltInitialEOG_Callback(app, event)
            % --- Executes on button press in pushbuttonAltInitialEOG.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonAltInitialEOG (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            data = guidata(hObject);
            
            set(hObject,'backgroundcolor',[.3 .6 .3])
            pause(0.005);
            
            AddToListbox(app, data.listboxStdout, 'Removing EOG with *EOG* channels');
            tmp = data.EEG;
            tmp.data = detrend(tmp.data','constant')';
            %%Modification
            fprintf('\n== EOG ICA Debug Info ==\n');
            fprintf('EEG data size: [%d channels x %d timepoints]\n', size(tmp.data, 1), size(tmp.data, 2));
            fprintf('EEG data rank: %d\n', rank(double(tmp.data)));
            fprintf('Requested PCA components: 12\n');

            eogndx = FindSetNdx({tmp.chanlocs.labels}, '*eog*', 'match','pattern');
            if isempty(eogndx)
                AddToListbox(app, data.listboxStdout, 'WARNING! could not match EOG channels. Nothing performed');
                return
            end
            
            try
                if data.checkboxJader.Value
                    warning('using the JADER ICA algorithm. Ncomps/ PCA not used')
                    [tmp, cmd] = pop_runica(tmp, 'icatype', 'jader');
                else
                    [tmp, cmd] = pop_runica(tmp,'icatype','binica','pca',12);
                    %tmp = pop_runica(tmp,'icatype','runica','pca',12);
                end
            catch E
                [tmp, cmd] = pop_runica(tmp,'icatype','runica','extended',1,'pca',12);
            end
            
            if isempty(tmp.icaact)
                AddToListbox(app, data.listboxStdout, '- Recalculate ICA activations.');
                tmp.icaact = icaact(tmp.data, tmp.icaweights*tmp.icasphere);
            end
            
            AddToListbox(app, data.listboxStdout, '- Match eye ICs to EOG channels');
            
            % determine eye ICs after some extra lowpass filtering as the
            % artifacts are likely to appear in the low frequencies.
            EYE = filter_fir(tmp.data(eogndx,:), tmp.srate, 0, 20, 3.0, true);
            ICs = filter_fir(tmp.icaact(:,:), tmp.srate, 0, 20, 3.0, true);
            R = abs(corr(EYE',ICs'));
            icdeselect = any(R>=.75,1);
            AddToListbox(app, data.listboxStdout, sprintf('- Removing %d ICs with subtraction method',sum(icdeselect)));
            if sum(icdeselect)>0
                % use the source subtraction method to remove ICs, NOT the pop_select
                % method that reduces the dimensionality of the data. This
                % was thoroughly tested!
                tmp.data = tmp.data - tmp.icawinv(:,icdeselect)*tmp.icaact(icdeselect,:);
                data.tabLine.removeEOG = sum(icdeselect);
                tmp.history = [tmp.history char(uint8(10)) sprintf('%% removed %d eye ICs', sum(icdeselect))];
            end
            
            % push existing data onto stack. Update <data.EEG> to tmp.
            data.Stack{length(data.Stack)+1} = data.EEG;
            data.StackLabel{length(data.Stack)+1} = 'Initial EOG (alt method)';
            data.EEG = tmp;
            guidata(hObject, data);
            recordPower(app, event, 'AltInitialEOG')
            
            set(hObject,'backgroundcolor',[.9 .8 .6])
        end

        % Button pushed function: pushbuttonBadChans
        function pushbuttonBadChans_Callback(app, event)
            % --- Executes on button press in pushbuttonBadChans.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonBadChans (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            data = guidata(hObject);
            
            data.pushbuttonBadChans.BackgroundColor = [.3 .6 .3];
            pause(0.005)
            
            if ~isfield(data,'EEG') || isempty(data.EEG.data)
                msgbox('No data available');
                data.pushbuttonBadChans.BackgroundColor = [1 .6 .6];
                return
            end

            savedEEG = data.EEG;
            
            % open bad channel gui for additional selection of bad channels. Open
            % modal! Wait for window to close. Data will be collected upon passing the
            % handle to the current window.
            h = figBadChansModal(app.fig_eeg_workflow);
            uiwait(h);
            
            % push saved data onto stack. if data have been changed it has
            % been done by the external figure. This workflow is a bit
            % different from other buttons. Only do this when channels have
            % been removed!
            if data.EEG.nbchan~=savedEEG.nbchan
                removedndx = ~ismember({savedEEG.chanlocs.labels}, {data.EEG.chanlocs.labels});
                data.Stack{length(data.Stack)+1} = savedEEG;
                data.StackLabel{length(data.Stack)+1} = sprintf('Bad channels removed (%d)', sum(removedndx));
                data.EEG.history = [data.EEG.history '\npop_select(EEG,{' sprintf('%s',savedEEG.chanlocs(removedndx).labels)];
                guidata(hObject, data);
            end

            data.pushbuttonBadChans.BackgroundColor = [.9 .8 .6];
        end

        % Button pushed function: pushbuttonChanlocs
        function pushbuttonChanlocs_Callback(app, event)
            % --- Executes on button press in pushbuttonChanlocs.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonChanlocs (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            data = guidata(hObject);
            set(hObject, 'BackgroundColor', [.3 .6 .3]);
            pause(0.005)
            
            if ~isfield(data,'EEG')
                msgbox('No data available');
                data.pushbuttonChanlocs.BackgroundColor = [1 .6 .6];
                return
            end
            
            AddToListbox(app, data.listboxStdout, 'Reading channel locations.');
            tmp = data.EEG;
            
            if data.checkboxAddCpz.Value
                if sum(strcmpi({tmp.chanlocs.labels},'CPz'))==0
                    AddToListbox(app, data.listboxStdout, ' - Adding CPz channel as flatline.');
                    tmp.data(end+1,:) = 0;
                    tmp.nbchan = data.EEG.nbchan+1;
                    tmp.chanlocs(end+1).labels = 'CPz';
                    AddToListbox(app, data.listboxStdout, ' - Removing ICA decomposition.');
                    tmp.icaweights = [];
                    tmp.icawinv = [];
                    tmp.icasphere = [];
                    tmp = eeg_checkset(tmp);
                else
                    AddToListbox(app, data.listboxStdout, 'Warning: CPz already in data. Not adding a flatine CPz reference channel.');
                end
            end
            
            switch data.popupmenuLookupType.Value
                case 1
                    AddToListbox(app, data.listboxStdout, ' - Looking up channels in standard-10-5-cap385.elp.');
                    tmp = pop_chanedit(tmp, 'lookup','standard-10-5-cap385.elp');
                    tmp.history = [tmp.history char(uint8(10)) 'pop_chanedit(tmp, "lookup","standard-10-5-cap385.elp")' ];
                case 2
                    AddToListbox(app, data.listboxStdout, ' - Renaming Biosemi channels to 10/10 and looking up channels in standard-10-5-cap385.elp.');
                    labs = readtable('BioSemi68_labels.txt');
                    for ch=1:length(labs.Label)
                        ndx = find(strcmp(labs.Label{ch}, {tmp.chanlocs.labels}));
                        if length(ndx)==1
                            tmp.chanlocs(ndx).labels = labs.ten10{ch};
                        elseif length(ndx)>2
                            pause;
                        else
                            AddToListbox(app, data.listboxStdout, sprintf( '   channel %s not found', labs.Label{ch}));
                        end
                    end
                    tmp = pop_chanedit(tmp, 'lookup','standard-10-5-cap385.elp');
                    tmp.history = [tmp.history char(uint8(10)) 'pop_chanedit(tmp, "lookup","standard-10-5-cap385.elp")' ];
            
                case 3
                    AddToListbox(app, data.listboxStdout, ' - Copying channel locations from a 128 channel EEGLAB dataset.');
                    lookup = pop_loadset('BioSemi128.set');
                    for ch=1:tmp.nbchan
                        ndx = find(strcmp(tmp.chanlocs(ch).labels, {lookup.chanlocs.labels}));
                        if length(ndx)==1
                            tmp.chanlocs(ch) = lookup.chanlocs(ndx);
                        else
                            AddToListbox(app, data.listboxStdout, sprintf( '   channel %s not found', tmp.chanlocs(ch).labels));
                        end
                    end
                    tmp.history = [tmp.history char(uint8(10)) '%% added biosemi 128 channel locations' ];

                case 4
                    AddToListbox(app, data.listboxStdout, ' - Lookup BioSemi 128 channel (rename known 10/10)');
                    lookup = pop_loadset('BioSemi128.set');
                    for ch=1:tmp.nbchan
                        ndx = find(strcmp(tmp.chanlocs(ch).labels, {lookup.chanlocs.labels}));
                        if length(ndx)==1
                            tmp.chanlocs(ch) = lookup.chanlocs(ndx);
                        else
                            AddToListbox(app, data.listboxStdout, sprintf( '   channel %s not found', tmp.chanlocs(ch).labels));
                        end
                    end
                    tmp.history = [tmp.history char(uint8(10)) '%% added biosemi 128 channel locations' ];

                    % rename some of the channels according to their
                    % approximate 10/20 (10/10) names
                    lookup1010 = readtable('Biosemi128_labels1010.txt');
                    rencnt = 0;
                    for ch=1:length(tmp.chanlocs)
                        ndx = find(strcmpi(tmp.chanlocs(ch).labels, lookup1010.orig128));
                        if ~isempty(ndx) && length(ndx)==1
                            if ~isempty(lookup1010.new1010{ndx})
                                rencnt = rencnt + 1;
                            end
                            tmp.chanlocs(ch).labels = lookup1010.mixed128{ndx};
                        end
                    end
                    AddToListbox(app, data.listboxStdout, sprintf(' - Renamed %d channels to 10/10', rencnt));
            
                case 5
                    AddToListbox(app, data.listboxStdout, ' - TD Brain channel locations');
                    lookup = pop_loadset('TDBrainLocs.set');
                    for ch=1:tmp.nbchan
                        ndx = find(strcmp(tmp.chanlocs(ch).labels, {lookup.chanlocs.labels}));
                        if length(ndx)==1
                            tmp.chanlocs(ch) = lookup.chanlocs(ndx);
                        else
                            AddToListbox(app, data.listboxStdout, sprintf( '   channel %s not found', tmp.chanlocs(ch).labels));
                        end
                    end
                    tmp.history = [tmp.history char(uint8(10)) '%% added TDBrain channel locations' ];

                case 6
                    AddToListbox(app, data.listboxStdout, '- Brain Products GmbH 128 channel locations');
                    labs=readtable('output_montage.txt','Delimiter','\t');
                     for ch=1:length(labs.Label)
                        ndx = find(strcmp(labs.Label{ch}, {tmp.chanlocs.labels}));
                        if length(ndx)==1
                            tmp.chanlocs(ndx).labels = labs.ten10{ch};
                        elseif length(ndx)>2
                            pause;
                        else
                            AddToListbox(app, data.listboxStdout, sprintf( '   channel %s not found', labs.Label{ch}));
                        end
                    end
%                     for ch=1:height(labs)
%                         ndx=find(strcmp(labs.Label{ch}, {tmp.chanlocs.labels}));
%                         if length(ndx)==1
%                             tmp.chanlocs(ndx).labels=labs.ten10{ch};
%                         elseif isempty(ndx)
%                             
%                         else
%                             AddtoListbox(data.listboxStdout, sprintf(' multiple matches for channel %s', labs.Label{ch}));
%                         end
%                     end 
                    tmp=pop_chanedit(tmp,'lookup','standard-10-5-cap385.elp');
                    tmp.history = [tmp.history char(uint8(10)) '%% added Brain Products 128 channel locations' ];

                case 7
                    AddToListbox(app, data.listboxStdout, '- Temple university channel locations');
                    % names are like stadard names with '-LE' or '-REF'
                     for ch=1:tmp.nbchan
                        s = strsplit(tmp.chanlocs(ch).labels, '-');
                        tmp.chanlocs(ch).labels = s{1};
                     end
                     tmp = pop_chanedit(tmp,'lookup','standard-10-5-cap385.elp');
                     tmp.history = [tmp.history char(uint8(10)) 'pop_chanedit(tmp, "lookup","standard-10-5-cap385.elp")' ];

                case 8
                    AddToListbox(app, data.listboxStdout, ' - EGI 128 EEG channels lookup');
                    tmp = pop_chanedit(tmp, {'lookup','GSN-HydroCel-128.sfp','filetype','autodetect'});
                    tmp.history = [tmp.history char(uint8(10)) '%% added EGI 128 channel locations' ];
            end

            if data.checkboxSaveForImpute.Value > 0.0
                withloc = ~cellfun(@isempty, {tmp.chanlocs.sph_phi});
                Chanlocs = pop_select(tmp, 'channel', find(withloc));
                save('.chanlocs.mat', 'Chanlocs')
                AddToListbox(app, data.listboxStdout, sprintf('Saved %d channel locations for imputation', sum(withloc)));
            end
            
            % push existing data onto stack. Update <data.EEG> to tmp.
            data.Stack{length(data.Stack)+1} = data.EEG;
            data.StackLabel{length(data.Stack)+1} = 'Lookup';
            data.EEG = tmp;
            guidata(hObject,data)

            recordPower(app, event, 'Chanlocs')
            
            listboxEegProperties_Update(app, hObject)
            set(hObject, 'BackgroundColor', [.9 .8 .5]);
            data.pushbuttonResample.BackgroundColor = [.6 1 .6];
        end

        % Button pushed function: pushbuttonClean
        function pushbuttonClean_Callback(app, event)
            % --- Executes on button press in pushbuttonClean.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonClean (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            data = guidata(hObject);
            set(hObject,'backgroundcolor',[.3 .6 .3])
            pause(0.005);
            
            AddToListbox(app, data.listboxStdout, 'Clean data usig clean_rawdata');
            
            [tmp, cmd] = pop_clean_rawdata(data.EEG, ...
                'FlatlineCriterion','on','LineNoiseCriterion','off','Highpass','off',...
                'BurstCriterionRefTolerances', [-Inf 3.0], ...
                'ChannelCriterion', data.sliderChannelMinR.Value, ...
                'BurstCriterion',data.sliderBurstCriterion.Value,...
                'WindowCriterion', 0.25 ,... % default value
                'BurstRejection',ifthen(data.checkboxBurstDelete.Value,'on','off'),...
                'Distance','Euclidian',...
                'ChannelCriterionMaxBadTime', data.sliderMaxBadTime.Value);
            tmp.history = [tmp.history char(uint8(10)) cmd];


            % add info to the table. data.EEG is still the old data.
            data.tabLine.cleanDeletedChans = abs(data.EEG.nbchan-tmp.nbchan);
            if (data.tabLine.cleanDeletedChans>0)
                prevdata = pop_select(data.EEG, 'channel', {tmp.chanlocs.labels});
            else
                prevdata = data.EEG;
            end
            data.tabLine.cleanDeletedTime = (prevdata.pnts - tmp.pnts) ./ data.EEG.srate;
            % sum(mean((prevdata.data(:,:)-tmp.data(:,:)).^2)>.1)

            % push existing data onto stack. Update <data.EEG> to tmp.
            data.Stack{length(data.Stack)+1} = data.EEG;
            data.StackLabel{length(data.Stack)+1} = 'Clean';
            data.EEG = tmp;
            guidata(hObject, data);

            recordPower(app, event, 'CleanRawdata')
                        
            set(hObject,'backgroundcolor',[.9 .8 .6])
            set(data.pushbuttonICLabel, 'backgroundcolor', [.6 1 .6]);
            pause(0.005);
        end

        % Button pushed function: pushbuttonDOWN
        function pushbuttonDOWN_Callback(app, event)
            % --- Executes on button press in pushbuttonDOWN.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonDOWN (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            data = guidata(hObject);
            
            if data.fontsize<4
                return
            end
            
            data.fontsize = data.fontsize - 1;
            
            setFontSize(app, hObject.Parent, data.fontsize)
            
            % push the fontsize data to the object
            guidata(hObject, data);
        end

        % Button pushed function: pushbuttonEMGPeriods
        function pushbuttonEMGPeriods_Callback(app, event)
            % --- Executes on button press in pushbuttonEMGPeriods.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonEMGPeriods (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            data = guidata(hObject);
        
             AddToListbox(app, data.listboxStdout, 'Removing muscle artefact periods (HF power + channel independence).')
%             AddToListbox(app, data.listboxStdout, '* WARNING: remove bad / non-EEG channels first.')
%             AddToListbox(app, data.listboxStdout, '- NOTE *eog* channels will be ignored.')
%             AddToListbox(app, data.listboxStdout, '- NOTE channels with no location info will be ignored.')
%         
            tmp = data.EEG;
        
            EOG_chans = FindSetNdx({tmp.chanlocs.labels},'*eog*','match','pattern');
            EEG_chans = find(~cellfun(@isempty, {tmp.chanlocs.X}));
            EEG_chans = setdiff(EEG_chans, EOG_chans);
            n_chans   = length(EEG_chans);
        
            % HF band limits (Hz) — HF_HI will become a UI parameter in a future version
            HF_LO = 20;
            HF_HI = 40;
            thr = -1.5;

            % interpolate data
            [~,~,~,~,~,int_data] = InterpolationCleaning(tmp);
            
            % define windows of 0.5 seconds
            len = tmp.srate;
            startpnts = 1:len:(tmp.pnts-len+1);
            %Forig = nan(len,tmp.nbchan,length(startpnts));
            % Fint  = Forig;
            % Fnew  = Forig;
            %fft_fs = linspace(0,tmp.srate,len);
            %fft_fs = fft_fs(1:end-1);
            
            Porig = nan(tmp.srate/2+1,tmp.nbchan,length(startpnts));
            Pint  = Porig;
            cnt = 0;
            for start=startpnts
                cnt=cnt+1;
                % Forig(:,:,cnt) = fft(tmp.data(:, start:start+len-1)');
                % Fint(:,:,cnt)  = fft(int_data(:, start:start+len-1)');
                [Porig(:,:,cnt), ~] = pfft(tmp.data(:, start:start+len-1)', tmp.srate, ones(1,tmp.srate), 0);
                [Pint(:,:,cnt), fs] = pfft(int_data(:, start:start+len-1)', tmp.srate, ones(1,tmp.srate), 0);
            
            end
            ndx = fs>=HF_LO & fs<HF_HI;
            Pdelta = squeeze(mean(Pint(ndx,:,:)) - mean(Porig(ndx,:,:)));

            % take the threshold as -1, make it adjustable in the future...
            % Minus means interpolated has much less beta power, so the
            % original is higher and probably EMG
            remove = Pdelta < thr;

            % first determine what channels surpass the maximum bad time
            % from the slider also used for CLeanRawData.
            bad = (sum(remove,2)./size(remove,2))>app.sliderMaxBadTime.Value;
            AddToListbox(app, data.listboxStdout, sprintf('- %d channels marked bad', sum(bad)))
            bad_wins = logical(sum(Pdelta(~bad,:) < thr));
            AddToListbox(app, data.listboxStdout, sprintf('- %d periods marked bad', sum(bad_wins)))

%         
%             % ------------------------------------------------------------------ %
%             % STEP 1 — HF power per epoch via pfft
%             %   1-s Hann window, 50% overlap → allP is [freq × channels × epochs]
%             % ------------------------------------------------------------------ %
%             win           = hann(tmp.srate)';                                   % row vector
%             [~, fs, allP] = pfft(tmp.data(EEG_chans,:)', tmp.srate, win, 0.5);
%         
%             idx_hf  = fs >= HF_LO & fs <= HF_HI;
%             idx_tot = fs >= 1;
%         
%             % Sum power in bands → [n_chans × n_epochs]
%             hf_power  = squeeze(sum(allP(idx_hf,  :, :), 1));
%             tot_power = squeeze(sum(allP(idx_tot, :, :), 1));
%             hf_ratio  = hf_power ./ (tot_power + eps);
%         
%             n_epochs = size(hf_ratio, 2);
%         
%             % ------------------------------------------------------------------ %
%             % STEP 2 — Per-channel robust outlier screen
%             %   Log-transform for better distributional shape, then per-channel
%             %   median ± scaled MAD.  Channels with >30% outlier epochs should be
%             %   removed rather than having their epochs rejected.
%             % ------------------------------------------------------------------ %
%             log_hf    = log(hf_ratio + eps);                          % [n_chans × n_epochs]
%             ch_med    = median(log_hf, 2);                             % [n_chans × 1]
%             ch_mad    = median(abs(log_hf - ch_med), 2) * 1.4826;     % [n_chans × 1]  (MAD → σ equivalent)
%             ch_thresh = ch_med + 2.5 * ch_mad;                        % per-channel upper threshold
%             outlier_mat = log_hf > ch_thresh;                          % [n_chans × n_epochs]  logical
%         
%             bad_chan = mean(outlier_mat, 2) > 0.30;
%             for ch = find(bad_chan)'
%                 AddToListbox(app, data.listboxStdout, sprintf( ...
%                     '  - WARNING channel %s has >30%% HF outlier epochs — consider channel removal instead.', ...
%                     tmp.chanlocs(EEG_chans(ch)).labels));
%             end
%         
%             good_idx = find(~bad_chan);   % indices into EEG_chans
%             n_good   = length(good_idx);
%         
%             if n_good == 0
%                 AddToListbox(app, data.listboxStdout, ...
%                     '- No usable channels remain after bad-channel warnings. Remove bad channels first.')
%                 return
%             end
%         
%             % ------------------------------------------------------------------ %
%             % STEP 3 — Epoch HF flag (good channels only)
%             %   An epoch is flagged if ANY good channel exceeds its own threshold.
%             % ------------------------------------------------------------------ %
%             bad_hf = any(outlier_mat(good_idx, :), 1);   % [1 × n_epochs] logical
%         
%             % ------------------------------------------------------------------ %
%             % STEP 4 — Inter-channel independence flag
%             %   Bandpass filter raw signal at HF_LO–HF_HI Hz, compute mean pairwise
%             %   Pearson correlation within each epoch window (matching pfft epochs).
%             %   Muscle artefacts are spatially local → abnormally LOW correlation.
%             % ------------------------------------------------------------------ %
%             [b_hf, a_hf] = butter(4, [HF_LO HF_HI] / (tmp.srate / 2), 'bandpass');
%             good_data    = tmp.data(EEG_chans(good_idx), :);         % [n_good × samples]
%             data_hf      = filtfilt(b_hf, a_hf, double(good_data)')';        % [n_good × samples]
%         
%             hop       = round(tmp.srate / 2);                         % 50% overlap in samples
%             mean_corr = nan(n_epochs, 1);
%         
%             for w = 1:n_epochs
%                 s1  = (w-1) * hop + 1;
%                 s2  = min(s1 + tmp.srate - 1, tmp.pnts);
%                 seg = data_hf(:, s1:s2)';                            % [samples × n_good]
%                 if n_good > 1
%                     R            = corrcoef(seg);
%                     ut           = triu(true(n_good), 1);             % upper triangle mask, no diagonal
%                     mean_corr(w) = mean(R(ut));
%                 else
%                     mean_corr(w) = 1;  % single channel: no pairs → never flag independence
%                 end
%             end
%         
%             % Robust lower-tail threshold: unusually low correlation = independence outlier
%             valid_corr  = mean_corr(~isnan(mean_corr));
%             corr_med    = median(valid_corr);
%             corr_mad    = median(abs(valid_corr - corr_med)) * 1.4826;
%             corr_thresh = corr_med - 2.5 * corr_mad;
%             bad_corr    = mean_corr < corr_thresh;
%         
%             % ------------------------------------------------------------------ %
%             % STEP 5 — Combine: both HF power AND low correlation must be met
%             % ------------------------------------------------------------------ %
%             bad_wins = bad_hf(:) | bad_corr(:);
%         
%             AddToListbox(app, data.listboxStdout, sprintf('- Found %d/%d artefact windows (%.0f%%)', ...
%                 sum(bad_wins), n_epochs, 100 * mean(bad_wins)));
%         
            % ------------------------------------------------------------------ %
            % STEP 6 — Convert window flags to per-sample boolean mask
            % ------------------------------------------------------------------ %
            hop = median(diff(startpnts));
            n_epochs = length(startpnts);
            mask = false(1, tmp.pnts);
            for w = 1:n_epochs
                if bad_wins(w)
                    s1 = (w-1) * hop + 1;
                    s2 = min(s1 + tmp.srate - 1, tmp.pnts);
                    mask(s1:s2) = true;
                end
            end
        
            % ------------------------------------------------------------------ %
            % STEP 7 — Extract contiguous bad periods as [start end] sample pairs
            % ------------------------------------------------------------------ %
            row   = 0;
            times = [];
            state = 0;
            for s = 1:length(mask)
                if mask(s) && state == 0
                    state = 1;
                    row   = row + 1;
                    times(row, 1) = s; %#ok<AGROW>
                elseif ~mask(s) && state == 1
                    state = 0;
                    times(row, 2) = s - 1;
                end
            end
            if state == 1 && (isempty(times) || times(end, 2) == 0)
                times(end, 2) = tmp.pnts;
            end
        
            if isempty(times)
                AddToListbox(app, data.listboxStdout, '- No artefact periods found.')
                return
            end
        
            % ------------------------------------------------------------------ %
            % STEP 8 — Merge periods separated by < 1 s
            % ------------------------------------------------------------------ %
            keep = true(size(times, 1), 1);
            for row = 1:size(times, 1)-1
                if times(row+1, 1) <= times(row, 2) + tmp.srate
                    times(row+1, 1) = times(row, 1);
                    keep(row)       = false;
                end
            end
            times = times(keep, :);
        
            % ------------------------------------------------------------------ %
            % STEP 9 — Output result and apply
            % ------------------------------------------------------------------ %
            AddToListbox(app, data.listboxStdout, sprintf('- Removing %d periods', size(times, 1)))
            [tmp, cmd] = pop_select(tmp, 'nopoint', times);
            tmp.history = [tmp.history char(uint8(10)) cmd];
        
            % Push existing data onto stack. Update data.EEG to tmp.
            data.Stack{length(data.Stack)+1}      = data.EEG;
            data.StackLabel{length(data.Stack)+1} = 'Clear muscle artefact periods';
            data.EEG = tmp;
        
            guidata(hObject, data)

            recordPower(app, event, 'EMGPeriods')

        end

        % Button pushed function: pushbuttonExcessive
        function pushbuttonExcessive_Callback(app, event)
            % --- Executes on button press in pushbuttonExcessive.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonExcessive (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            data = guidata(hObject);
            
            set(hObject, 'BackgroundColor', [.3 .6 .3]);
            
            if ~isfield(data,'EEG') || isempty(data.EEG.data)
                msgbox('No data available');
                set(hObject, 'BackgroundColor', [1 .6 .6]);
                return
            end
            
            % always work with a tmp variable.
            tmp = data.EEG;
            crit = data.spinnerExcessive.Value;
            AddToListbox(app, data.listboxStdout, sprintf('Removing channels with Z>%.1f.', crit));
            
            % get very low StdDev for channels
            SD = std(data.EEG.data(:,:)');
            Z = (SD-mean(SD))./std(SD);
            ndx = find(Z > crit);
            if ~isempty(ndx)
                AddToListbox(app, data.listboxStdout, sprintf('- Removing %d channels ', length(ndx)));
                [tmp, cmd] = pop_select(tmp,'nochannel',ndx);
                tmp.history = [tmp.history char(uint8(10)) cmd];
                data.tabLine.excessive = length(ndx);
            else
                AddToListbox(app, data.listboxStdout, '- NO channels removed');
                data.TabLine.excessive = 0;
            end
            
            % push existing data onto stack. Update <data.EEG> to tmp.
            data.Stack{length(data.Stack)+1} = data.EEG;
            data.StackLabel{length(data.Stack)+1} = 'Excessive channel SD z-score';
            data.EEG = tmp;
            guidata(hObject, data);
            
            % set the button colors
            set(hObject, 'BackgroundColor', [.9 .8 .5]);
            set(data.pushbuttonChanlocs, 'BackgroundColor', [.6 1 .6]);
        end

        % Button pushed function: pushbuttonFilter
        function pushbuttonFilter_Callback(app, event)
            % --- Executes on button press in pushbuttonFilter.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonFilter (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            data = guidata(hObject);
            data.pushbuttonFilter.BackgroundColor = [.3 .6 .3];
            pause(0.005)
            
            if ~isfield(data,'EEG') || isempty(data.EEG.data)
                AddToListbox(app, data.listboxStdout,'*** error *** No EEG data avilable');
                msgbox('No data available');
                data.pushbuttonFilter.BackgroundColor = [1 .6 .6];
                return
            end
            
            tmp = data.EEG;
            
            AddToListbox(app, data.listboxStdout, sprintf('Filter %.1f to %.1f Hz', data.spinnerLow.Value, data.spinnerHigh.Value));
                
            if data.checkboxButterworth.Value
                AddToListbox(app, data.listboxStdout,'- Filtering with 2nd order Butterworth');
                tmp = filter_butter(tmp, tmp.srate, data.spinnerLow.Value, data.spinnerHigh.Value, 9, true, false, true);
                tmp.history = [tmp.history char(uint8(10)) sprintf('%% filter_butter between  %.1f and %.1f', data.spinnerLow.Value, data.spinnerHigh.Value)];
                if data.checkboxNotch.Value
                    AddToListbox(app, data.listboxStdout,'- Filtering with 2nd order Butterworth from 47 to 53 Hz');
                    tmp = filter_butter(tmp, tmp.srate, 47, 53, 2, true, true);
                    tmp.history = [tmp.history char(uint8(10)) '% filter_butter between 47 and 53'];
                end
            else
                AddToListbox(app, data.listboxStdout,'- Filtering with FIR filter.');
                [tmp, cmd] = pop_eegfiltnew(tmp, data.spinnerLow.Value, data.spinnerHigh.Value, [], false);
                tmp.history = [tmp.history char(uint8(10)) cmd];
                if data.checkboxNotch.Value
                    AddToListbox(app, data.listboxStdout,'- Notch FIR filter 47 to 53 Hz.');
                    [tmp, cmd] = pop_eegfiltnew(tmp, 47, 53, [], true);
                    tmp.history = [tmp.history char(uint8(10)) cmd];
                end
            end
            
            % push existing data onto stack. Update <data.EEG> to tmp.
            data.Stack{length(data.Stack)+1} = data.EEG;
            data.StackLabel{length(data.Stack)+1} = 'Filter';
            data.EEG = tmp;

            guidata(hObject, data);
            
            recordPower(app, event, 'Filter')
            
            data.pushbuttonFilter.BackgroundColor = [.9 .8 .6];
            data.pushbuttonInitialICA.BackgroundColor = [.6 1 .6];
        end

        % Button pushed function: pushbuttonFlatPeriods
        function pushbuttonFlatPeriods_Callback(app, event)
            % --- Executes on button press in pushbuttonFlatPeriods.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonFlatPeriod (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            data = guidata(hObject);
            
            AddToListbox(app, data.listboxStdout, 'Removing flatline periods based on Std and some heuristics.')
            AddToListbox(app, data.listboxStdout, '* WARNING remove bad / non-eeg channels first.')
            AddToListbox(app, data.listboxStdout, '- NOTE *eog* channels will be ignored.')
            AddToListbox(app, data.listboxStdout, '- NOTE channels with no location info will be ignored')
            
            tmp = data.EEG;
            
            EOG = FindSetNdx({tmp.chanlocs.labels},'*eog*','match','pattern');
            EEG = find(~cellfun(@isempty, {tmp.chanlocs.X}));
            EEG = setdiff(EEG,EOG);
            
            % get power spectra in chuncks of 200ms so as to get 5, 10, 15 ... Hz power
            % in many epochs each of 200 ms.
            %[~,fs, allP] = pfft(tmp.data(EEG,:)', tmp.srate, ones(1,tmp.srate/5), 0);
            %zz = log(squeeze(mean(allP(fs>8&fs<=20, :, :)))'); % log is to normalize
            %H = nan(size(zz));
            %H(:) = zz(:)<-3.0;
            
            
            len=20;
            delta = nan(tmp.nbchan, tmp.pnts);
            for s=1:tmp.pnts-len+1
                delta(:,s) = std(detrend(tmp.data(:,s:s+len-1)'));
            end
            
            % make a logical vector from the delta scores (actually SDs)
            mask = any(1./(delta+.1)>2, 1); % max is 10, avoids Inf. 
            % mask = ((sum(delta<median(delta(:))/10))>tmp.nbchan/3);
            mask2 = false(1, length(mask));
            for s=1:length(mask)
                if mask(s)
                    ndx1 = s-4;
                    ndx2 = s+len+4;
                    ndx1 = ifthen(ndx1<1, 1, ndx1);
                    ndx2 = ifthen(ndx2>tmp.pnts, 1, ndx2);
                    mask2(ndx1:ndx2)=true;
                end
            end
            
            row=0;
            times=[];
            state=0;
            for s=1:length(mask2)
                if mask2(s) && state==0
                    state=1;
                    row=row+1;
                    times(row,1)=s;
                elseif ~mask2(s) && state==1
                    state=0;
                    times(row,2)=s-1;
                end
            end

            if state==1 && times(end,2) == 0
                % ended in ON state (flatline), add pnts
                times(end,2) = tmp.pnts;
            end
            
%             row=0;
%             times=[];
%             state=0;
%             for s=1:size(H,1)
%                 if any(H(s,:)) && state==0
%                     state = 1;
%                     row = row+1;
%                     times(row,1) = (s-1)*.2 - .2; % fixed! .2 depends on pfft call!
%                 elseif ~any(H(s,:)) && state==1
%                     state = 0;
%                     times(row,2) = (s-1)*.2 + .2;
%                 end
%             end
%             if state==1 && times(row,2) == 0
%                 times(row,2) = tmp.xmax;
%             end
            
            % merge lines if period inbetween to-be-removed chunks <1s.
            % Note that times is in samples
            keep = true(size(times,1),1);
            for row=1:size(times,1)-1
                if times(row+1,1)<=times(row,2)+1.*tmp.srate
                    times(row+1,1) = times(row,1);
                    keep(row) = false;
                end
            end
            times = times(keep,:);
            
            
            % output result and apply
            AddToListbox(app, data.listboxStdout, sprintf('- Removing %d periods', size(times,1)))
            [tmp, cmd] = pop_select(tmp, 'nopoint', times);
            tmp.history = [tmp.history char(uint8(10)) cmd];

            % push existing data onto stack. Update <data.EEG> to tmp.
            data.Stack{length(data.Stack)+1} = data.EEG;
            data.StackLabel{length(data.Stack)+1} = 'Clear flatline periods';
            data.EEG = tmp;
            
            guidata(hObject, data)

            recordPower(app, event, 'EMGPeriods')            
        end

        % Button pushed function: pushbuttonFlatline
        function pushbuttonFlatline_Callback(app, event)
            % --- Executes on button press in pushbuttonFlatline.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonFlatline (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            data = guidata(hObject);
            
            set(hObject, 'BackgroundColor', [.3 .6 .3]);
            
            if ~isfield(data,'EEG') || isempty(data.EEG.data)
                msgbox('No data available');
                set(hObject, 'BackgroundColor', [1 .6 .6]);
                return
            end
            
            % always work with a tmp variable.
            tmp = data.EEG;
            crit = data.spinnerFlatlineSD.Value;
            AddToListbox(app, data.listboxStdout, sprintf('Removing channels with <%.1f stdev.', crit));
            
            
            % get very low StdDev for channels
            SD = std(data.EEG.data(:,:)');
            ndx = find(SD < crit);
            if ~isempty(ndx)
                AddToListbox(app, data.listboxStdout, sprintf('- Removing %d channels ', length(ndx)));
                [tmp, cmd] = pop_select(tmp,'nochannel',ndx);
                tmp.history = [tmp.history char(uint8(10)) cmd];
                data.tabLine.flatline = length(ndx);
            else
                AddToListbox(app, data.listboxStdout, '- NO channels removed');
                data.tabLine.flatline = 0;
            end
            
            
            % push existing data onto stack. Update <data.EEG> to tmp.
            data.Stack{length(data.Stack)+1} = data.EEG;
            data.StackLabel{length(data.Stack)+1} = 'Flatline';
            data.EEG = tmp;
            guidata(hObject, data);
            
            % set the button colors
            set(hObject, 'BackgroundColor', [.9 .8 .5]);
            set(data.pushbuttonExcessive, 'BackgroundColor', [.6 1 .6]);
        end

        % Button pushed function: pushbuttonICLabel
        function pushbuttonICLabel_Callback(app, event)
             
            % --- Executes on button press in pushbuttonICLabel.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonICLabel (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            data = guidata(hObject);
            set(hObject, 'BackgroundColor', [.3 .6 .3])

            tmp = data.EEG; 
            ncomps = data.sliderNComps.Value;
      
            if ncomps<10
                ncomps = 10;
                AddToListbox(app, data.listboxStdout, 'Too few components selected for ICA. Taking the minimum of 10.')
            end
            if ncomps>tmp.nbchan
                ncomps=tmp.nbchan;
                AddToListbox(app, data.listboxStdout, 'Too many components selected for ICA. Taking the maximum.')
            end
            
            try
                try
                    % AddToListbox(app, data.listboxStdout, 'Running ICA.')
                    if data.checkboxJader.Value
                        warning('using the JADER ICA algorithm. Ncomps/ PCA not used')
                        [tmp, cmd] = pop_runica(tmp, 'icatype', 'jader');
                    else
                        [tmp, cmd] = pop_runica(tmp,'icatype','binica','pca',ncomps);
                    end
                catch E
                    % AddToListbox(app, data.listboxStdout, 'Running ICA failed. Reverting to slower version.')
                    [tmp, cmd] = pop_runica(tmp,'icatype','runica','extended',1,'pca',ncomps);
                end
                data.EEG = tmp;
                guidata(hObject, data);
            catch E
                throw(E);
            end
            tmp.history = [tmp.history char(uint8(10)) cmd];

            % IC label part
            % AddToListbox(app, data.listboxStdout, 'Performing IC labelling')
            [tmp, cmd] = pop_iclabel(tmp, 'default');
            tmp.history = [tmp.history char(uint8(10)) cmd];

            %brainlabel = FindSetNdx(tmp.etc.ic_classification.ICLabel.classes,'Brain');
            artifactlabel = FindSetNdx(tmp.etc.ic_classification.ICLabel.classes,{'Muscle','Eye','Heart','Line Noise','Channel Noise'});
            
            if data.checkboxKeepOther.Value == 0.0
                % add the 'other' category
                artifactlabel = [artifactlabel {'Other'}];
            end
            
            % select components that are >45% certain brain. take sum per row i.e. for
            % all artefacts (along columns). This will allow uncertainty about which
            % artefact is present.
            icdeselect = sum(tmp.etc.ic_classification.ICLabel.classifications(:,artifactlabel),2) >= data.sliderMinICLabelPercent.Value;
            fprintf('Removing components: ')
            fprintf(' %d',find(icdeselect))
            fprintf('\n')
            if sum(icdeselect)>0
                if isempty(tmp.icaact)
                    AddToListbox(app, data.listboxStdout, '- Recalculate ICA activations.');
                    tmp.icaact = icaact(tmp.data, tmp.icaweights*tmp.icasphere);
                end

                % use subtraction method for each component (repmat not required this
                % way!
                fprintf('== ICA Matrix Sizes ==\n');
                fprintf('icaact size: [%d x %d]\n', size(tmp.icaact,1), size(tmp.icaact,2));
                fprintf('icawinv size: [%d x %d]\n', size(tmp.icawinv,1), size(tmp.icawinv,2));
                fprintf('nbchan: %d\n', tmp.nbchan);
                fprintf('Channels in data: %d\n', size(tmp.data,1));
                fprintf('Timepoints in data: %d\n', size(tmp.data,2));
                fprintf('Components to remove: %s\n', mat2str(find(icdeselect)));
                tmp.icaact=tmp.icaweights * tmp.icasphere* double(tmp.data);
                %validComp
                removendx = find(icdeselect);
                if ~isempty(removendx)
                    cmd = sprintf('%% subtracting %d components ( ', length(removendx));
                    for comp=removendx(:)'
                        fprintf("Removing component %d\n",comp);
                        contribution = tmp.icawinv(:,comp)*tmp.icaact(comp,:);
                        tmp.data = tmp.data-contribution;
                        data.tabLine.iclabelDeselect = sum(icdeselect);
                        cmd = [cmd sprintf('%d ', comp)];
                    end
                    cmd = [cmd ')'];
                    tmp.history = [tmp.history char(uint8(10)) cmd];
                end
            end
            
            % push existing data onto stack. Update <data.EEG> to tmp.
            data.Stack{length(data.Stack)+1} = data.EEG;
            data.StackLabel{length(data.Stack)+1} = 'ICLabel';
            data.EEG = tmp;

            guidata(hObject, data);

            recordPower(app, event, 'IClabel')
            
            set(hObject, 'BackgroundColor', [.9 .8 .6])
            set(data.pushbuttonSave, 'BackgroundColor', [.6 1 .6])
        end

        % Button pushed function: pushbuttonImputeAll
        function pushbuttonImputeAll_Callback(app, event)
            % --- Executes on button press in pushbuttonImputeAll. -------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonImputeAll (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            data = guidata(hObject);
            impute = data.EEG;
            AddToListbox(app, data.listboxStdout, 'Replacing all channels with imputed version');
            for ch=1:data.EEG.nbchan
                tmp = pop_interp(data.EEG, ch, 'spherical');
                impute.data(ch,:) = tmp.data(ch,:);
            end
            impute.history = [impute.history '\n%% interpolated back all channels to remove local artefacts'];
            
            data.EEG = impute;
            
            % push existing data onto stack. Update <data.EEG> to tmp.
            data.Stack{length(data.Stack)+1} = data.EEG;
            data.StackLabel{length(data.Stack)+1} = 'Impute all channels';
            guidata(hObject, data);

            recordPower(app, event, 'ImputeAll')
        end

        % Button pushed function: pushbuttonImpute
        function pushbuttonImpute_Callback(app, event)
            % --- Executes on button press in pushbuttonImpute.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonImpute (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            data = guidata(hObject);
            
            if ~exist('.chanlocs.mat', 'file')
                AddToListbox(app, data.listboxStdout, '*** Warning *** No EEG data saved to impute with.');
                AddToListbox(app, data.listboxStdout, '*** First save a full channel locations set with the right button');
                return
            end
            
            load('.chanlocs.mat') % loads Chanlocs EEG struct
            
            remove = setdiff({data.EEG.chanlocs.labels}, {Chanlocs.chanlocs.labels});
            if length(remove)>0
                AddToListbox(app, data.listboxStdout, 'Removing channels not in imputation list');
                for r=1:length(remove)
                    AddToListbox(app, data.listboxStdout, sprintf(' %s', remove{r}));
                end
                [data.EEG, cmd] = pop_select(data.EEG, 'nochannel', remove);
            end
            
            impute = setdiff({Chanlocs.chanlocs.labels}, {data.EEG.chanlocs.labels});
            AddToListbox(app, data.listboxStdout, sprintf('Imputing %d channels', length(impute)))
            [data.EEG, cmd] = pop_interp(data.EEG, Chanlocs.chanlocs, 'spherical');
            data.EEG.history = [data.EEG.history char(uint8(10)) cmd];
            data.EEG.nbchan = size(data.EEG.data,1);
            
            % push existing data onto stack. Update <data.EEG> to tmp.
            data.Stack{length(data.Stack)+1} = data.EEG;
            data.StackLabel{length(data.Stack)+1} = 'Impute channels';
            guidata(hObject, data);

            recordPower(app, event, 'ImputeChans')
        end

        % Button pushed function: pushbuttonInitialICA
        function pushbuttonInitialICA_Callback(app, event)
            % --- Executes on button press in pushbuttonInitialICA.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonInitialICA (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            data = guidata(hObject);
            
            set(hObject,'backgroundcolor',[.3 .6 .3])
            pause(0.005);
            
            AddToListbox(app, data.listboxStdout, 'Running intial ICA of 16 PCs');
            
            tmp = data.EEG;
            if (tmp.trials==1)
                tmp.data = detrend(tmp.data','constant')';
            end
            try
                if data.checkboxJader.Value
                    warning('using the JADER ICA algorithm. Ncomps/ PCA not used')
                    tmp = pop_runica(tmp, 'icatype', 'jader');
                else
                    tmp = pop_runica(tmp,'icatype','binica','pca',16);
                end
            catch E
                tmp = pop_runica(tmp,'icatype','runica','extended',1,'pca',ifthen(tmp.nbchan<16,tmp.nbchan,16));
            end
            if isempty(tmp.icaact)
                AddToListbox(app, data.listboxStdout, ' Recalculate ICA activations.');
                tmp.icaact=icaact(tmp.data, tmp.icaweights*tmp.icasphere);
            end

            AddToListbox(app, data.listboxStdout, ' Get eye PCs using ICLabel.');
            
            % determine eye ICs
            tmp = pop_iclabel(tmp, 'default');
            eyelabel = FindSetNdx(tmp.etc.ic_classification.ICLabel.classes,'Eye');
            icdeselect = (tmp.etc.ic_classification.ICLabel.classifications(:,eyelabel)')'>.50;
            AddToListbox(app, data.listboxStdout, sprintf(' Removing %d ICs',sum(icdeselect)));
            if sum(icdeselect)>0
                AddToListbox(app, data.listboxStdout, sprintf(' - Remove %d eye ICs',sum(icdeselect)));
                fprintf(' - Remove %d eye ICs using subtraction method\n',sum(icdeselect));
                % use the source subtraction method to remove ICs, NOT the pop_select
                % method that reduces the dimensionality of the data. TESTED for
                % icawinv*icaact being thebest option to reconstruct the EEG data (but
                % a small 10-5 to 10-6 error remained!)
                tmp.data(:,:) = tmp.data(:,:) - tmp.icawinv(:,icdeselect)*tmp.icaact(icdeselect,:);
                data.tabLine.removeEOG = sum(icdeselect);
                tmp.history = [tmp.history char(uint8(10)) ...
                    sprintf('%% removed %d eye ICs (',sum(icdeselect)) ...
                    sprintf('%d ', find(icdeselect)) ...
                    ')' ];
            end
            
            % push existing data onto stack. Update <data.EEG> to tmp.
            data.Stack{length(data.Stack)+1} = data.EEG;
            data.StackLabel{length(data.Stack)+1} = 'Initial ICA';
            data.EEG = tmp;
            guidata(hObject, data);
            recordPower(app, event, 'InitialEOG')
            
            set(hObject,'backgroundcolor',[.9 .8 .6])
        end

        % Button pushed function: pushbuttonMaskEvt
        function pushbuttonMaskEvt_Callback(app, event)
            % --- Executes on button press in pushbuttonMaskEvt.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonMaskEvt (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            data = guidata(hObject);
            
            AddToListbox(app, data.listboxStdout, '- masking all events types to 0-255 (numeric or not)');
            
            % as always, work on tmp in stead of data.EEG
            tmp = data.EEG;
            keep = true(1,length(tmp.event));
            for e=1:length(tmp.event)
                ev = tmp.event(e).type;
                evclass = class(ev);
                if isnumeric(ev)
                    if ev==round(ev)
                        val = bitand(ev, 255);
                        if val
                            ev = val;
                        else
                            keep(e) = false;
                        end
                    end
                elseif ~isnan(str2double(ev))
                    val = bitand(str2double(ev), 255);
                    if val
                        ev = num2str(bitand(str2double(ev), 255));
                    else
                        keep(e) = false;
                    end
                end
                tmp.event(e).type = ev;
            end
            
            tmp.event = tmp.event(keep);
            
            
            % push existing data onto stack. Update <data.EEG> to tmp.
            data.Stack{length(data.Stack)+1} = data.EEG;
            data.StackLabel{length(data.Stack)+1} = 'Massk events'' to first 8 bits';
            data.EEG = tmp;
            guidata(hObject, data);
        end

        % Button pushed function: pushbuttonMemoryBack
        function pushbuttonMemoryBack_Callback(app, event)
            % --- Executes on button press in pushbuttonMemoryBack.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonMemoryBack (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            data = guidata(hObject);
            
            global GlobEEG
            
            data.EEG = GlobEEG;
            
            guidata(hObject, data);
        end

        % Button pushed function: pushbuttonOpen
        function pushbuttonOpen_Callback(app, event)
            % --- Executes on button press in pbOpen.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbOpen (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            data = guidata(hObject);
            savecolour = hObject.BackgroundColor; % save colour for after cancel
            hObject.BackgroundColor = [.3 .6 .3];
            
            FilterSpec = {'*.*', 'All files'
                '*.bdf', 'Biosemi'
                '*.cnt', 'ANT Neuro / Neuroscan'
                '*.edf', 'European data format'
                '*.set', 'EEGLAB'
                '*.vhdr', 'BrainVision'
                };
            fid = fopen(sprintf('%s/.EegWorkflow_DefaultPath.ini', data.INIDIR), 'r');
            DefaultPath = '.';
            if fid>0
                try
                    DefaultPath = fgetl(fid);
                    fclose(fid);
                catch
                end
            end
            [FileName, PathName, FilterIndex] = uigetfile(FilterSpec,'Select an EEG file', DefaultPath);
            
            % save the default folder.
            if ~exist(data.INIDIR)
                mkdir(data.INIDIR);
            end
            fid = fopen(sprintf('%s/.EegWorkflow_DefaultPath.ini', data.INIDIR), 'w');
            if fid>0
                fprintf(fid, '%s', PathName);
                fclose(fid);
            end
            
            % try block here?
            if isnumeric(FileName) && FileName==0
                AddToListbox(app, data.listboxStdout, '*** warning *** no file selected');
                hObject.BackgroundColor = savecolour;
            else
            
                data.EEG = loadfile(app, PathName, FileName, data.listboxStdout, ...
                data.checkboxBiosig, data.checkboxDataAreAvgRef);
                data.EEG = eeg_checkset(data.EEG);
            
                % correct amplitude of Nihon Koden files (EOGH EOGV Fp1 Fp2 , possibly F7 F8 had
                % different gain settings. Heuristic is to divide all channels but
                % these by two (half the gain settings). CHECK WITH PSD
                if data.checkboxCorrectNK.Value
                    AddToListbox(app, data.listboxStdout, '*** warning *** correcting Nihon Koden files');
                    adjust = FindSetNdx({data.EEG.chanlocs.labels},{'EOGH','EOGV','Fp1','Fp2','F7','F8'});
                    % adjust = setdiff(1:data.EEG.nbchan, adjust);
                    data.EEG.data(adjust,:) = data.EEG.data(adjust,:)*2;
                end
            
                % correct nonstandard EOG channel naming 
                if data.checkboxRenameEOGVH.Value
                    AddToListbox(app, data.listboxStdout, '- ranaming EOGV and EOGH');
                    adjust = FindSetNdx({data.EEG.chanlocs.labels},{'EOGH'});
                    if ~isempty(adjust)
                        data.EEG.chanlocs(adjust).labels = 'HEOG';
                    end
                    adjust = FindSetNdx({data.EEG.chanlocs.labels},{'EOGV'});
                    if ~isempty(adjust)
                        data.EEG.chanlocs(adjust).labels = 'VEOG';
                    end
                end

                % reset the stack for Undo operations
                data.Stack = {};
                data.StackLabel = {};
                %if isfield(data.EEG,'event')
                %    for ev=1:length(data.EEG.event)
                %        if ischar(data.EEG.event(ev).type)
                %            data.EEG.event(ev).type = data.EEG.event(ev).type(~ismember(data.EEG.event(ev).type,[0 9:13 32]));
                %        end
                %    end
                %end
            
                % make all button red (except the next one)
                list = fieldnames(data);
                for l=1:length(list)
                    if isfield(getfield(data,list{l}),'style')
                        sty = get(getfield(data,list{l}),'style');
                        if strcmpi(sty,'pushbutton')
                            set(getfield(data,list{l}),'backgroundcolor',[1 .6 .6])
                        end
                        pause(0.005);
                    end
                end
                % Flatline and Remove Resting may turn green
                data.pushbuttonFlatline.BackgroundColor = [.6 1 .6];
                % The remainder should be red.
                data.pushbuttonLookup.BackgroundColor = [1 .6 .6];
                data.pushbuttonChanlocs.BackgroundColor = [1 .6 .6];
                data.pushbuttonResample.BackgroundColor = [1 .6 .6];
                data.pushbuttonFilter.BackgroundColor = [1 .6 .6];
                data.pushbuttonReref.BackgroundColor = [1 .6 .6];
                data.pushbuttonInitialICA.BackgroundColor = [1 .6 .6];
                data.pushbuttonAAR.BackgroundColor = [1 .6 .6];
                data.pushbuttonBadChans.BackgroundColor = [1 .6 .6];
                data.pushbuttonClean.BackgroundColor = [1 .6 .6];
                data.pushbuttonICA.BackgroundColor = [1 .6 .6];
                data.pushbuttonICLabel.BackgroundColor = [1 .6 .6];
                data.pushbuttonAARWinSec.BackgroundColor = [1 .6 .6];
                hObject.BackgroundColor = [1 .6 .6];
            
            end
            
            % start a meta-data table line. Fill it as struct, then use struct2table to
            % create the table. Every call (every buttonpresss will fill a struct
            % field.
            data.tabLine = struct();
            data.tabLine.starttime = size(data.EEG.data(:,:),2) / data.EEG.srate;
            
            guidata(hObject,data)
        end

        % Button pushed function: pushbuttonOverlay
        function pushbuttonOverlay_Callback(app, event)
            % --- Executes on button press in pushbuttonOverlay.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonOverlay (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            
            data = guidata(hObject);
            
            if ~isfield(data,'EEG') || isempty(data.EEG.data)
                msgbox('No data available');
                data.pbView.BackgroundColor = [1 .6 .6];
                return
            end
            
            if ~isfield(data,'EEG') || isempty(data.EEG) || data.EEG.nbchan==0
                AddToListbox(app, data.listboxStdout, '*** Warning *** no data available');
                return
            end
            if ~isfield(data,'Stack') || isempty(data.Stack)
                AddToListbox(app, data.listboxStdout, '*** Warning *** no comparison data available');
                return
            end
            
            screensize = get(groot, 'Screensize' );
            tmp1 = data.EEG;
            tmp2 = data.Stack{length(data.Stack)};
            if tmp1.nbchan==tmp2.nbchan && tmp1.pnts==tmp2.pnts
                eegplot(tmp2.data,'srate',tmp2.srate,'eloc_file',tmp2.chanlocs,'spacing',50,...
                    'limits',[tmp2.xmin tmp2.xmax],'winlength',12,'position',screensize,...
                    'events',tmp2.event, 'data2', tmp1.data, 'dispchans', min(tmp1.nbchan, 64));
            else
                AddToListbox(app, data.listboxStdout, '*** Warning *** data and comparison data are incompatible to plot together');
            
                % try to match the data on channels
                u = union({tmp2.chanlocs.labels}, {tmp1.chanlocs.labels});
                tmp1 = pop_select(tmp1, 'channel', u);
                tmp2 = pop_select(tmp2, 'channel', u);
                % match sampling rate
                if tmp1.srate~=tmp2.srate
                    newrate = min(tmp1.srate, tmp2.srate);
                    tmp1 = pop_resample(tmp1,newrate);
                    tmp2 = pop_resample(tmp2,newrate);
                end
                % match time
                if tmp1.pnts ~= tmp2.pnts
                    newpnts = min(tmp1.pnts, tmp2.pnts);
                    tmp1 = pop_select(tmp1, 'point', [1 newpnts]);
                    tmp2 = pop_select(tmp2, 'point', [1 newpnts]);
                    warning('EEG data does not match in size!')
                end
                if tmp1.nbchan ~= tmp2.nbchan
                    both = intersect({tmp1.chanlocs.labels},{tmp2.chanlocs.labels});
                    tmp1 = pop_select(tmp1, 'channel', both);
                    tmp2 = pop_select(tmp2, 'channel', both);
                end
                eegplot(tmp2.data,'srate',tmp2.srate,'eloc_file',tmp2.chanlocs,'spacing',50,...
                'limits',[tmp2.xmin tmp2.xmax],'winlength',12,'position',screensize,...
                'events',tmp2.event, 'data2', tmp1.data, 'dispchans', min(tmp2.nbchan, 64));
            end
            
            guidata(hObject,data);
        end

        % Button pushed function: pushbuttonPlot2D
        function pushbuttonPlot2D_Callback(app, event)
            % --- Executes on button press in pushbuttonPlot2D.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonPlot2D (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            data = guidata(hObject);
            figure;
            topoplot([],data.EEG.chanlocs, 'style', 'blank', ...
                'electrodes', 'labelpoint', ...
                'chaninfo', data.EEG.chaninfo);
        end

        % Button pushed function: pushbuttonRemoveEOG
        function pushbuttonRemoveEOG_Callback(app, event)
            % --- Executes on button press in pushbuttonRemoveEOG.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonRemoveEOG (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
             % hObject    handle to pushbuttonRemoveNoEEG (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            data = guidata(hObject);
            
            tmp = data.EEG;
            
            eog = FindSetNdx({tmp.chanlocs.labels},'*eog*','match','pattern');
            ref = FindSetNdx({tmp.chanlocs.labels},{'M1','M2','A1','A2'},'match','exact');
            ext = FindSetNdx({tmp.chanlocs.labels},{'EXT*','EXG*'},'match','pattern');
            AddToListbox(app, data.listboxStdout, sprintf('Removing %d EOG EXT REF channels', length(eog)+length(ref)+length(ext)))
            [tmp, cmd] = pop_select(tmp, 'nochannel', [eog ref ext]);
            tmp.history = [tmp.history char(uint8(10)) cmd];
            if strcmpi(tmp.ref, 'average')
                AddToListbox(app, data.listboxStdout, sprintf('- redo avg reference'))
                [tmp, cmd] = pop_reref(tmp, []);
                tmp.history = [tmp.history char(uint8(10)) cmd];
            end
            
            data.Stack{length(data.Stack)+1} = data.EEG;
            data.StackLabel{length(data.Stack)+1} = 'Remove EOG and REF';
            data.EEG = tmp;
            guidata(hObject, data);
            pause(0.01)

        end

        % Button pushed function: pushbuttonRemoveNoEEG
        function pushbuttonRemoveNoEEG_Callback(app, event)
            % --- Executes on button press in pushbuttonRemoveNoEEG.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonRemoveNoEEG (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            data = guidata(hObject);
            
            tmp = data.EEG;
            
            notEEG = find(cellfun(@isempty, {tmp.chanlocs.X}));
            eog = FindSetNdx({tmp.chanlocs.labels},'*eog*','match','pattern');
            notEEG = setdiff(notEEG, eog);
            
            AddToListbox(app, data.listboxStdout, sprintf('Removing %d non-EEG channels', length(notEEG)))
            [tmp, cmd] = pop_select(tmp, 'nochannel', notEEG);
            tmp.history = [tmp.history char(uint8(10)) cmd];
            if strcmpi(tmp.ref, 'average')
                AddToListbox(app, data.listboxStdout, sprintf('- redo avg reference'))
                [tmp, cmd] = pop_reref(tmp, []);
                tmp.history = [tmp.history char(uint8(10)) cmd];
            end
            
            data.Stack{length(data.Stack)+1} = data.EEG;
            data.StackLabel{length(data.Stack)+1} = 'Remove non-EEG';
            data.EEG = tmp;
            guidata(hObject, data);
            pause(0.01)
        end

        % Button pushed function: pushbuttonRemoveResting
        function pushbuttonRemoveResting_Callback(app, event)
            % --- Executes on button press in pushbuttonRemoveResting.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonRemoveResting (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            data = guidata(hObject);
            
            % as always, work on tmp in stead of data.EEG
            dims = [1 35];  % Textbox dimensions
            definput = {'2.0', '2.0'};  % Default values
            answer = inputdlg({'Keep data before event (s)', 'Keep data after event (s)'}, ...
                'input lower and upper bound for PSD', dims, definput);
            % Convert the cell array to numbers
            if ~isempty(answer) % Check if user didn't cancel
                lo = round(str2double(answer{1}));
                hi = round(str2double(answer{2}));
            else
                AddToListbox(app, data.listboxStdout, 'User cancelled');
                return
            end
            cWithin = 2.0; %
            AddToListbox(app, data.listboxStdout, sprintf('Looking for periods with events (+ %.f and - %.f s)', hi, lo));
            
            tmp = data.EEG;
            mask = false(1,tmp.pnts);
            for e=1:length(tmp.event)
                if (~strcmpi(tmp.event(e).type, "boundary"))
                    start = floor(tmp.event(e).latency-tmp.srate*lo);
                    stop  = ceil(tmp.event(e).latency+tmp.srate*hi);
                    start = ifthen(start<1, 1, start);
                    stop  = ifthen(stop<1, 1, stop);
                    mask(start:stop) = true;
                end
            end
            
            if data.checkboxTaskNoTask.Value>0
                AddToListbox(app, data.listboxStdout, sprintf('- reversing the mask (removing task data)', sum(~mask)/tmp.srate));
                mask = ~mask;
            end
            
            AddToListbox(app, data.listboxStdout, sprintf('- removing %f.1 seconds of data', sum(~mask)/tmp.srate));
            
            % get points to remove, ndx1 the strat point, ndx2 the end points
            ndx1 = find(diff(mask)<0);
            ndx2 = find(diff(mask)>0)-1;
            if ndx1(1)>ndx2(1)
                ndx1 = [1 ndx1];
            end
            if length(ndx1)>length(ndx2)
                ndx2=[ndx2 tmp.pnts];
            end
            remove = [ndx1' ndx2'];
            [tmp, cmd] = pop_select(tmp, 'nopoint', remove);
            tmp.history = [tmp.history char(uint8(10)) cmd];
            
            
            % push existing data onto stack. Update <data.EEG> to tmp.
            data.Stack{length(data.Stack)+1} = data.EEG;
            data.StackLabel{length(data.Stack)+1} = 'Remove task or no-task';
            data.EEG = tmp;
            guidata(hObject, data);
        end

        % Button pushed function: pushbuttonReref
        function pushbuttonReref_Callback(app, event)
            % --- Executes on button press in pushbuttonReref.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonReref (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            data = guidata(hObject);
            set(hObject, 'BackgroundColor', [.3 .6 .3]);
            pause(.005);
            
            if ~isfield(data,'EEG') || isempty(data.EEG.data)
                msgbox('No data available');
                data.pushbuttonReref.BackgroundColor = [.6 1 .6];
                return
            end
            
            tmp = data.EEG;
            if size(tmp.data,3) ~= tmp.trials
                tmp.trials = size(tmp.data,3);
            end
            if tmp.trials==1 && tmp.pnts~=size(tmp.data,2)
                tmp.pnts = size(tmp.data,2);
            end
            
            AddToListbox(app, data.listboxStdout, sprintf('Rereferencing data (%s)',data.popupmenuReref.String{data.popupmenuReref.Value}));
            
            switch data.popupmenuReref.Value
                case 1, [tmp, cmd] = pop_reref(tmp, find(ismember(upper({tmp.chanlocs.labels}),'CPZ')));
                case 2, [tmp, cmd] = pop_reref(tmp, find(ismember(upper({tmp.chanlocs.labels}),{'M1','M2'})));
                case 3, [tmp, cmd] = pop_reref(tmp, [], 'exclude', find(ismember(upper({tmp.chanlocs.labels}),{'HEOG','VEOG'})));
                case 4, [tmp, cmd] = eeg_REST_reref(tmp);
                case 5, [tmp, cmd] = pop_reref(tmp, find(ismember(upper({tmp.chanlocs.labels}),{'A1','A2'})));
                case 6  % special case for a special dataset, first avg then A1A2
                    [tmp, cmd] = pop_reref(tmp, []);
                    [tmp, cmd] = pop_reref(tmp, find(ismember(upper({tmp.chanlocs.labels}),{'A1','A2'})));
                case 7  % special case for BECAUSE dataset: EXT5/6 are A1/A2
                    [tmp, cmd] = pop_reref(tmp, {'EXT5','EXT6'});
                case 8
                    trodes = {};
                    for ch=1:tmp.nbchan
                        if ~isempty(tmp.chanlocs(ch).X)
                            trodes = cat(1, trodes, {tmp.chanlocs(ch).labels});
                        end
                    end
                    if length(trodes)<tmp.nbchan
                        warning('DOWNSIZING DATA. Only channels with  location info can be used for CSD')
                        tmp = pop_select(tmp,'channel',trodes);
                    end
                    %% Get Montage for use with CSD Toolbox
                    Montage_64 = ExtractMontage('/data/damitsea/Matlab/CSDtoolbox/resource/10-5-System_Mastoids_EGI129.csd',trodes);
                    [G,H] = GetGH(Montage_64);
                    [s1,s2,s3] = size(tmp.data);
                    tmp.data = CSD(tmp.data(:,:),G,H);
                    if s3>1
                        tmp.data = reshape(tmp.data, [s1,s2,s3]);
                    end
                    cmd = '%% REST reref';
                case 9
                    ndx = find(ismember(upper({tmp.chanlocs.labels}),{'TP9','TP10'}));
                    if ~isempty(ndx)
                        [tmp, cmd] = pop_reref(tmp, ndx);
                    else
                        AddToListbox(app, data.listboxStdout, 'Channels TP9/10 not found');
                    end

                case 10
                    ndx = find(ismember(upper({tmp.chanlocs.labels}),{'P9','P10'}));
                    if ~isempty(ndx)
                        [tmp, cmd] = pop_reref(tmp, ndx);
                    else
                        AddToListbox(app, data.listboxStdout, 'Channels P9/10 not found');
                    end

            
            end
            tmp.history = [tmp.history char(uint8(10)) cmd];
            
            
            % push existing data onto stack. Update <data.EEG> to tmp.
            data.Stack{length(data.Stack)+1} = data.EEG;
            data.StackLabel{length(data.Stack)+1} = 'Rereference';
            data.EEG = tmp;
            guidata(hObject, data);
            recordPower(app, event, 'Reref')

            set(hObject, 'BackgroundColor', [.9 .8 .6]);
            data.pushbuttonFilter.BackgroundColor = [1 .6 .6];
            pause(0.005);
        end

        % Button pushed function: pushbuttonResample
        function pushbuttonResample_Callback(app, event)
            % --- Executes on button press in pushbuttonResample.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonResample (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            set(hObject,'backgroundcolor', [.3 .6 .3])
            pause(0.005);
            data = guidata(hObject);
            
            AddToListbox(app, data.listboxStdout,'Resampling data');
            
            tmp = data.EEG;
            
            % resample if sampling rate different
            fs_new = str2num(data.popupmenuRerefFreq.String{data.popupmenuRerefFreq.Value});
            if ~data.popupmenuRerefFreq.Value>1 && NewSrate~=data.EEG.srate
                AddToListbox(app, data.listboxStdout,'*** warning *** no change in sampling rate.');
                return
            end
            
            % check what alogorithm to use
            fs_old = tmp.srate;
            isint = (fs_new/fs_old==round(fs_new/fs_old)) | (fs_old/fs_new==round(fs_old/fs_new));
            if ~isint
                AddToListbox(app, data.listboxStdout,'*** warning *** resampling with spline (noninteger divide)');
            end
            
            % do the resampling
            if isint
                [tmp, cmd] = pop_resample(tmp, fs_new);
            else
                warning('Resampling by spline interpolation!')
                dummy = pop_resample(tmp, fs_new);
                t_old = (0:tmp.pnts-1)/fs_old;
                t_new = (0:dummy.pnts-1)/fs_new;
            
                % copy all info to tmp. Overwrite data with spline later.
                tmp = dummy;
            
                % Resample using spline interpolation
                for ch=1:tmp.nbchan
                    tmp.data(ch,:) = interp1(t_old, data.EEG.data(ch,:), t_new, 'spline');
                end
                cmd = '% custom spline resampling';
            end
            tmp.history = [tmp.history char(uint8(10)) cmd];
            
            data.Stack{length(data.Stack)+1} = data.EEG;
            data.StackLabel{length(data.Stack)+1} = 'Resample';
            data.EEG = tmp;
            guidata(hObject, data);
            
            set(hObject,'backgroundcolor', [.9 .8 .6])
            set(data.pushbuttonReref,'backgroundcolor', [.6 1 .6])
        end

        % Button pushed function: pushbuttonReview
        function pushbuttonReview_Callback(app, event)
            % --- Executes on button press in pushbuttonReview.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonReview (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            data = guidata(hObject);
            
            if ~isfield(data,'EEG') || isempty(data.EEG.data)
                msgbox('No data available');
                data.pbView.BackgroundColor = [1 .6 .6];
                return
            end
            
            global TMPREJ
            TMPREJ = [];
            
            tmp = data.EEG;
            
            % tmp = reviewwindow(tmp);

            pop_eegplot(data.EEG, 1, 1, 0, [], 'title', 'Scroll EEG', ...
                'command', 'global TMPREJ', 'winlength', 12,...
                'spacing', 50, 'submean', 'on', 'dispchans', min(64,data.EEG.nbchan));
            % the pause is to make sure the window has opened (not sure it
            % is needed, but it allows the processing of events)
            pause(.5);
            
            % the trick is to wait for the window to close. Then we work
            % with TMPREJ to remove the data
            uiwait(gcf);
            pause(.5)

            [data.EEG, cmd] = eeg_eegrej(data.EEG, eegplot2event(TMPREJ)); 
            data.EEG.history = [data.EEG.history char(uint8(10)) cmd];
            guidata(hObject, data);
            recordPower(app, event, 'Review')
        end

        % Button pushed function: pushbuttonSaveMemory
        function pushbuttonSaveMemory_Callback(app, event)
            % --- Executes on button press in pushbuttonSaveMemory.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonSaveMemory (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            data = guidata(hObject);
            global GlobEEG
            GlobEEG = data.EEG;
            AddToListbox(app, data.listboxStdout, 'Saving data into global variable GlobEEG');
        end

        % Button pushed function: pushbuttonSave
        function pushbuttonSave_Callback(app, event)

            % --- Executes on button press in pushbuttonSave.

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbuttonSave (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            data = guidata(hObject);
            tmp = data.EEG;

            % open modal dialog and wait
            h = figSaveModal(tmp, tmp.filename, data.fontsize, data.INIDIR);
            uiwait(h);
         
        end

        % Button pushed function: pushbuttonSleepTheta
        function pushbuttonSleepTheta_Callback(app, event)
            % --- Executes on button press in pushbuttonSleepTheta.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonSleepTheta (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            data = guidata(hObject);
            
            AddToListbox(app, data.listboxStdout, 'Removing excessive theta periods indicating sleep')
            
            tmp = data.EEG;
            
            % get power spectra in chunks of 2000ms so as to get .5 1 1.5 2, ... Hz power
            cWinSize = tmp.srate*2;
            cWinSec = cWinSize / tmp.srate;
            cWinSecMove = cWinSec / 2;
            
            EOG = FindSetNdx({tmp.chanlocs.labels},'*eog*','match','pattern');
            EEG = find(~cellfun(@isempty, {tmp.chanlocs.X}));
            EEG = setdiff(EEG,EOG);
            
            % get power spectra in chuncks of 200ms so as to get 5, 10, 15 ... Hz power
            % in many epochs each of 200 ms.
            
            [~,fs, allP] = pfft(tmp.data(EEG,:)', tmp.srate, ones(1, cWinSize), .5);
            Th = mean(log(squeeze(mean(allP(fs>3&fs<=6, :, :)))'), 2); % log is to normalize
            Al = mean(log(squeeze(mean(allP(fs>7&fs<=13, :, :)))'), 2); % log is to normalize
            H = nan(size(Th));
            H(:) = (Th(:)./Al(:))>4.0;
            % determine start and end times of all the epochs. Note the .5voverlap in
            % the pfft call!
            starts = 0:cWinSecMove:tmp.xmax;
            
            % collect the data
            row=0;
            times=[];
            state=0;
            for s=1:length(H)
                if H(s) && state==0
                    state = 1;
                    row = row+1;
                    times(row,1) = starts(s); % fixed! .2 depends on pfft call!
                elseif ~H(s) && state==1
                    state = 0;
                    times(row,2) = starts(s);
                end
            end
            if state==1 && times(row,2) == 0
                times(row,2) = tmp.xmax;
            end
            
            % merge lines if period inbetween to-be-removed chunks <1s
            for row=1:size(times,1)-1
                if times(row+1,1)<=times(row,2)+1
                    times(row+1,1) = times(row+1,1)-1;
                end
            end
            
            
            % output result and apply
            AddToListbox(app, data.listboxStdout, sprintf('- Removing %d periods', size(times,1)))
            [tmp, cmd] = pop_select(tmp, 'notime', times);
            tmp.history = [tmp.history char(uint8(10)) cmd];
            
            % push existing data onto stack. Update <data.EEG> to tmp.
            data.Stack{length(data.Stack)+1} = data.EEG;
            data.StackLabel{length(data.Stack)+1} = 'Clear flatline periods';
            data.EEG = tmp;
            
            guidata(hObject, data)
            recordPower(app, event, 'SleepTheta')
        end

        % Button pushed function: pushbuttonUp
        function pushbuttonUp_Callback(app, event)
            % --- Executes on button press in pushbuttonUp.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonUp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            data = guidata(hObject);
            
            if data.fontsize>15
                return
            end
            
            data.fontsize = data.fontsize + 1;
            
            setFontSize(app, hObject.Parent, data.fontsize)
            
            % push the fontsize data to the object
            guidata(hObject, data);
        end

        % Value changed function: sliderAARShift
        function sliderAARShift_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to sliderAARShift (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            v = app.sliderAARShift.Value;
            snapped = round(v/5)*5;
            snapped = min(max(snapped,5),120);

            app.sliderAARShift.Value = snapped;   % enforce discrete value
            
            data = guidata(hObject);
            data.textAARShift.String = sprintf('shift %.1fs',get(hObject, "Value"));
        end

        % Value changed function: sliderAARWinSec
        function sliderAARWinSec_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to sliderAARWinSec (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            v = app.sliderAARWinSec.Value;
            snapped = round(v/5)*5;
            snapped = min(max(snapped,5),120);

            app.sliderAARWinSec.Value = snapped;   % enforce discrete value

            
            data = guidata(hObject);
            data.textAARWinSec.String = sprintf('AAR win size %ds',round(get(hObject, "Value")));
        end

        % Value changed function: sliderBurstCriterion
        function sliderBurstCriterion_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to sliderChannelMinR (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            
            data = guidata(hObject);
            
            set(hObject,'value',round(get(hObject,'value')));
            
            set(data.textBurstCriterion,'string',sprintf('Burst crit. %.1f',get(hObject,'value')));
            
            guidata(hObject, data);
        end

        % Value changed function: sliderChannelMinR
        function sliderChannelMinR_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to sliderChannelMinR (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            
            data = guidata(hObject);
            
            set(data.textChannelMinR,'string',sprintf('Chan min r %.02f', get(hObject,'value')));
            
            guidata(hObject, data);
        end

        % Callback function
        function sliderExcessive_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to sliderExcessive (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            updateSliderAndText(app, hObject)
            % data.textExcessive.String = sprintf('Z>%.1f', get(hObject,'Value'));
        end

        % Callback function
        function sliderFlatlineSD_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to sliderFlatlineSD (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            data = guidata(hObject);
            data.textFilterSD.String = sprintf('SD<%.1f', get(hObject,'Value'));
        end

        % Callback function
        function sliderHigh_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to sliderHigh (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            
            data = guidata(hObject);
            data.textHigh.String = sprintf('High: %.1f', get(hObject,'Value'));
        end

        % Callback function
        function sliderLow_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to sliderLow (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            
            data = guidata(hObject);
            data.textLow.String = sprintf('Low: %.1f', get(hObject,'Value'));
        end

        % Value changed function: sliderMaxBadTime
        function sliderMaxBadTime_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to sliderMaxBadTime (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            
            data = guidata(hObject);
            
            set(data.textMaxBadTime,'string',sprintf('Max bad time %d%%', round(get(hObject,'value')*100)));
            
            guidata(hObject, data);
        end

        % Value changed function: sliderMinICLabelPercent
        function sliderMinICLabelPercent_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to sliderMinICLabelPercent (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            
            data = guidata(hObject);
            
            set(data.textMinICLabelPercent,'string',sprintf('Remove ICs at %d%%',round(get(hObject,'value')*100)));
            
            guidata(hObject, data);
        end

        % Value changed function: sliderNComps
        function sliderNComps_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to sliderNComps (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            
            data = guidata(hObject);
            set(data.textNComps,'string', sprintf('PCA: %d', get(hObject,'Value')))
        end

        % Value changed function: sliderTstat
        function sliderTstat_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to sliderTstat (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            
            data = guidata(hObject);
            
            data.textTstat.String = sprintf('%.1f', get(hObject,'Value'));
        end

        % Button pushed function: pushbuttonExcessivePeriods
        function pushbuttonExcessivePeriodsButtonPushed(app, event)
            % --- Executes on button press in pushbuttonFlatPeriods.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonFlatPeriod (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            data = guidata(hObject);
            
            AddToListbox(app, data.listboxStdout, 'Removing excessive periods based on Std and some heuristics.')
            AddToListbox(app, data.listboxStdout, '* WARNING remove bad / non-eeg channels first.')
            AddToListbox(app, data.listboxStdout, '- NOTE *eog* channels will be ignored.')
            AddToListbox(app, data.listboxStdout, '- NOTE channels with no location info will be ignored')
            
            tmp = data.EEG;
            
            if tmp.trials>1
                warning('Cannot do this on 3D data yet.')
                AddToListbox(app, data.listboxStdout, ' *** error *** 3D data not yet supported.')
            end
            
            EOG = FindSetNdx({tmp.chanlocs.labels},'*eog*','match','pattern');
            EEG = find(~cellfun(@isempty, {tmp.chanlocs.X}));
            EEG = setdiff(EEG, EOG);
            
            len = tmp.srate; % 1 s windows
            nepoch = floor(tmp.pnts./len)*2-1; % 50% overlapping windows! may be 49% overlapping, depends on linspace
            starts = floor(linspace(1,tmp.pnts-len+1,nepoch)); % starting samples of epochs
            
            % two types of excessive data: simple >400 microV and SD based 
            % First get the SD of each epoch
            sds = nan(length(EEG), nepoch);
            maxval = sds;
            for s=1:length(starts)
                sds(:,s) = std(tmp.data(EEG, starts(s):starts(s)+len-1),[],2);
                maxval(:,s) = max(abs(detrend(tmp.data(EEG, starts(s):starts(s)+len-1)', 'constant')));
            end
            p = pnorm(zscore(sds')');
            [~,~,p_fdr] = fdr_bh(p(:));
            p_fdr = reshape(p_fdr,size(p));
            mask = false(size(p_fdr)); 
            mask(p_fdr<.01) = true;    % zscore based extreme values
            mask(maxval>400) = true;   % simple threshold based extreme values
            mask2 = any(mask); % single vector
            
            % epoch starting times required for later rejection
            times = tmp.times(starts)./1000;  % work in seconds, not ms
           
            row=0;
            rej=[];
            state=0;
            for e=1:length(mask2)
                if mask2(e) && state==0
                    state = 1;
                    row = row+1;
                    rej(row,1) = times(e)
                    e
                end
                if ~mask2(e) && state==1
                    state = 0;
                    rej(row,2) = times(e)-1./1000
                    e
                end
            end
            if state==1
                rej(row,2) = tmp.xmax;
            end
            
            % merge lines if period inbetween to-be-removed chunks <1s
            for row=1:size(rej,1)-1
                if rej(row+1,1)<=rej(row,2)+1
                    rej(row+1,1) = rej(row+1,1)-1;
                end
            end
            
            
            % output result and apply
            AddToListbox(app, data.listboxStdout, sprintf('- Removing %d periods', size(times,1)))
            [tmp, cmd] = pop_select(tmp, 'rmtime', rej);
            tmp.history = [tmp.history char(uint8(10)) cmd];
            
            % push existing data onto stack. Update <data.EEG> to tmp.
            data.Stack{length(data.Stack)+1} = data.EEG;
            data.StackLabel{length(data.Stack)+1} = 'Clear excessive periods';
            data.EEG = tmp;
            
            guidata(hObject, data)
            
        end

        % Button pushed function: pushbuttonLastSec
        function pushbuttonLastSecButtonPushed(app, event)
            % --- Executes on button press in pushbutton38.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton38 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            data = guidata(hObject);
            
            AddToListbox(app, data.listboxStdout, '- removing last 1 second of data');
            
            % as always, work on tmp in stead of data.EEG
            [tmp, cmd] = pop_select(data.EEG, 'rmtime', [data.EEG.xmax-1 data.EEG.xmax]);
            tmp.history = [tmp.history char(uint8(10)) cmd];
                    
            % push existing data onto stack. Update <data.EEG> to tmp.
            data.Stack{length(data.Stack)+1} = data.EEG;
            data.StackLabel{length(data.Stack)+1} = 'Remove last second';
            data.EEG = tmp;
            guidata(hObject, data);            
        end

        % Button pushed function: pbOverlayPSD
        function pbOverlayPSDButtonPushed(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbViewPSD (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            data = guidata(hObject);
            if ~isfield(data,'EEG') || isempty(data.EEG.data)
                msgbox('No data available');
                data.pbView.BackgroundColor = [1 .6 .6];
                return
            end
            
            if ~isfield(data,'EEG') || isempty(data.EEG) || data.EEG.nbchan==0
                AddToListbox(app, data.listboxStdout, '*** Warning *** no data available');
                return
            end
            if ~isfield(data,'Stack') || isempty(data.Stack)
                AddToListbox(app, data.listboxStdout, '*** Warning *** no comparison data available');
                return
            end
            
            AddToListbox(app, data.listboxStdout, 'Plotting PSDs of current and previous');
            tmp = data.EEG;
            tmp2 = data.Stack{length(data.Stack)};
            chanlocs = tmp.chanlocs;

            
            % settings taken from the filter
            lo = data.spinnerLow.Value;
            hi = data.spinnerHigh.Value;
            
            % store the data
            [P,fs] = calc_PSD(app, tmp);
            [P2,fs] = calc_PSD(app, tmp2);
            
            % Create figure if needed, otherwise put the figure in front
            if ~isfield(data, 'figPSD') || isempty(data.figPSD) || ~isstruct(data.figPSD) || ~isfield(data.figPSD, 'fig') || ~isvalid(data.figPSD.fig)
                AddToListbox(app, data.listboxStdout, '- creating figure');
                if ~isfield(data, 'figPSD')
                    data.figPSD = struct;
                end
                fig = figure('Name', 'plot Power Spectrum', 'Tag', 'figPSD', 'NumberTitle', 'off', ...
                             'Position', [100, 100, 700, 500]); % Adjust size as needed
                % Create a frame (uipanel), checkbox, and create axes inside the panel
                frame = uipanel('Parent', fig, 'Title', 'Power spectrum', 'Tag', 'framePSD', ...
                                'Position', [0.005, 0.1, .98, 0.9]); % [x, y, width, height]
                ax = axes('Parent', frame, 'Position', [0.07, 0.1, .91, 0.88], 'Tag', 'axesPSD'); % Adjust within panel
                checkbox = uicontrol('Style', 'checkbox', 'Parent', fig, 'Tag', 'cbSummary', ...
                                     'String', 'Summarize into regions', 'Fontsize', data.fontsize, ...
                                     'Units', 'normalized', ...
                                     'Position', [0.05, 0.03, 0.4, 0.05], ...
                                     'Callback', @(src, event) checkbox_callback_overlay(app, src, event, ax, fs, {P, P2}, data.fontsize+1, lo, hi));
                data.figPSD.fig = fig;
                data.figPSD.ax = ax;
                data.figPSD.checkbox = checkbox;
                data.figPSD.chanlocs = chanlocs;
                set(ax,'xlim',[0 min(hi,45)])

            else
                AddToListbox(app, data.listboxStdout, '- activating figure');
                fig = data.figPSD.fig;
                ax = data.figPSD.ax;
                checkbox = data.figPSD.checkbox;
                try
                    chanlocs = data.figPSD.chanlocs;
                catch
                    data.figPSD.chanlocs = tmp.chanlocs;
                    chanlocs = tmp.chanlocs;
                end
                figure(fig);
            end
            
            checkbox_callback_overlay(app, checkbox, event, ax, fs, {P, P2}, data.fontsize+1, lo, hi);
            
            guidata(hObject, data);

        end

        % Button pushed function: pushbuttonRmEyesOpen
        function pushbuttonRmEyesOpenButtonPushed(app, event)
            % --- Executes on button press in pushbuttonRmEyesOpenButton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonRemoveResting (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            data = guidata(hObject);

            tmp = data.EEG;
            % --- BLINK EVENT MARKING (threshold + peak detection) ---
            % Assumes you have an EOG channel named 'VEOG' (edit label as needed).
            % Writes events with type 'blink' into EEG.event.
            
            % 1) choose channel
            labels = {tmp.chanlocs.labels};
            eoglabels = {'VEOG', 'EOGV', 'Fp1', 'Fp2', 'Fpz', 'F1','Fz','F2'};
            eog = min(find(ismember(eoglabels, labels)));
            eoglabel = eoglabels{eog};
            blinkch = find(ismember(labels, eoglabel)); 
            tmp_filt = pop_eegfiltnew(pop_select(tmp, 'channel', blinkch), 'locutoff', 1, 'hicutoff', 10, 'plotfreqz', 0);
            
            x = double(tmp_filt.data(1,:));
            % 3) robust thresholding (MAD-based) on absolute deflection
            xa = abs(x);
            med = median(xa);
            madv = median(abs(xa - med));
            sigma_robust = 1.4826 * madv;
            
            thr = med + 28*sigma_robust;  % start here; adjust (e.g., 4–8) depending on your data
            
            % enforce a refractory period so you don't detect one blink multiple times
            minDist = round(0.25 * tmp.srate); % 250 ms
            
            % Peak detection (Signal Processing Toolbox has findpeaks)
            [pks, locs] = findpeaks(xa, 'MinPeakHeight', thr, 'MinPeakDistance', minDist);
            
            % 4) insert events
            n0 = numel(tmp.event);
            for k = 1:numel(locs)
                tmp.event(n0+k).type    = 'blink';
                tmp.event(n0+k).latency = locs(k);   % latency in samples
                tmp.event(n0+k).duration = 0;
            end
            
            % sort events by latency + consistency check (if events
            % available)
            if numel(tmp.event)>0
                [~, idx] = sort([tmp.event.latency]);
                tmp.event = tmp.event(idx);
                tmp = eeg_checkset(tmp, 'eventconsistency');
            
                AddToListbox(app, data.listboxStdout, ...
                    sprintf('Added %d blink events from channel %s (thr=%.2f).\n', numel(locs), eoglabel, thr));
            
                % Remove ±5 s around all events of type 'blink' in continuous EEGLAB dataset EEG
            
                winSec = 4;                         % seconds before/after
                winSamp = round(winSec * tmp.srate);
            
                % 1) Find blink event latencies (in samples)
                blinkIdx = find(strcmpi({tmp.event.type}, 'blink'));
                if isempty(blinkIdx)
                    warning('No blink events found.');
                    return;
                end
                
                lat = round([tmp.event(blinkIdx).latency]);   % samples
                
                % 2) Build [start end] rejection regions in samples
                rej = [lat - winSamp; lat + winSamp]';
                rej(:,1) = max(rej(:,1), 1);
                rej(:,2) = min(rej(:,2), tmp.pnts);
                
                % 3) Sort and merge overlapping/adjacent regions
                rej = sortrows(rej, 1);
                merged = rej(1,:);
                for i = 2:size(rej,1)
                    if rej(i,1) <= merged(end,2) + 1
                        merged(end,2) = max(merged(end,2), rej(i,2));
                    else
                        merged = [merged; rej(i,:)]; %#ok<AGROW>
                    end
                end
                
                % 4) Remove those data segments
                tmp = eeg_eegrej(tmp, merged);
                
                % 5) Clean up event structure
                tmp = eeg_checkset(tmp, 'eventconsistency');
                
                fprintf('Removed %d merged rejection windows (±%gs around each blink).\n', size(merged,1), winSec);
            else
                fprintf('Removed NO blink windows.\n');
            end
            
            % push existing data onto stack. Update <data.EEG> to tmp.
            data.Stack{length(data.Stack)+1} = data.EEG;
            data.StackLabel{length(data.Stack)+1} = 'Remove blink based eyes-open periods';
            data.EEG = tmp;
            guidata(hObject, data);

        end

        % Value changing function: sliderAARWinSec
        function sliderAARWinSecValueChanging(app, event)
            v = event.Value;
            snapped = round(v/5)*5;     % snap to nearest multiple of 5
            snapped = min(max(snapped,5),120);  % clamp
            

        end

        % Button pushed function: pbBatch_v2
        function pbBatch_v2ButtonPushed(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
                        
            global skipOverwrite_choice %#ok<GVMIS>
            
            data = guidata(hObject);
            
            % open the batch modal window to get the settings
            h = figRunBatchModal_v2(gcf, data.INIDIR);
            uiwait(h);
            pause(.05)
            data = guidata(hObject);
            
            if isfield(data,'batchfilenames') &&  ~isempty(data.batchfilenames)
                % loop thourgh files and apply the checked functions
                resultTable = [];
            
                % determine output filenames
                data.batchfilenamesout = {};
                for f=1:length(data.batchfilenames)
                    [~, Fname, Fext] = fileparts(data.batchfilenames{f});
                    FNOut = [data.batchoutputdir '/' sprintf('%s%s%s', data.batchprefix, Fname, Fext)];
                    %[pth,fle,ext] = fileparts(FNOut);
                    %FNOut = sprintf('%s/%s.set', pth, fle);
                    data.batchfilenamesout{end+1} = FNOut;
                end

                % test to ask for overwrite
                ex = false;
                for f=1:length(data.batchfilenamesout)
                    if exist(data.batchfilenamesout{f},'file')
                        ex = true;
                        break
                    end
                end
                % ask for skip or overwrite. Stores result in global.
                if ex
                    skip_or_overwrite_dialog(app);
                end
            
                % finally start the batch loop
                for f=1:length(data.batchfilenames)
                    try
                        % filename for output
                        FNOut = data.batchfilenamesout{f};
                        % skip if it exists and so requested.
                        if exist(FNOut) && strcmpi(skipOverwrite_choice, 'skip')
                            continue
                        end
                    
                        % read the file extract path and name first
                        [Fpath, Fname, Fext] = fileparts(data.batchfilenames{f});
                        EEG = loadfile(app, Fpath, [Fname Fext], ...
                            data.listboxStdout, data.checkboxBiosig, data.checkboxDataAreAvgRef);
                        %test modification
                        if ~isfield(EEG, 'event') || isempty(EEG.event)
                            EEG.event=struct('type', {}, 'latency', {}, 'urevent', {});
                        end
                        %end test

                        data.EEG = EEG;

                        % Initialize stack for history
                        if ~isfield(data, 'Stack') || isempty(data.Stack)
                            data.Stack = {};
                        end
                        if ~isfield(data, 'StackLabel') || isempty(data.StackLabel)
                            data.StackLabel = {};
                        end
                        
                        data.tabLine = struct();
                        data.tabLine.starttime = size(data.EEG.data(:,:),2) / data.EEG.srate;
                        guidata(hObject, data);

                        pause(0.01); % allows the upload
            
                        % perform the selected actions in a fixed order with the
                        % settings as specified.
                        T = data.batchchecked; % table holds 'order','tag', 'value'
                        for cb=1:size(T,1)
                            if T.value(cb)>1
                                % run this. Value=1 is actually <select>
                                % This is the list of items in the dropdown
                                    % 1 Flatline channels
                                    % 2 Excessive signal channels
                                    % 3 Lookup channel locations
                                    % 4 Resample
                                    % 5 Rereference
                                    % 6 Filter
                                    % 7 Initial EOG
                                    % 8 Alternate initial EOG
                                    % 9 EMG removal with AAR
                                    % 10 Alternate EMG removal
                                    % 11 Clean rawdata
                                    % 12 IC label
                                    % 13 ----
                                    % 14 Remove EOG channels
                                    % 15 Remove non-EEG channels
                                    % 16 Remove flatline periods
                                    % 17 Remove excessive periods
                                    % 18 Remove EMG periods
                                    % 19 Remove eyes open
                                    % 20 Remove task/resting
                                switch T.value(cb)-1
                                    case 1
                                        pushbuttonFlatline_Callback(app, event);
                                        data=guidata(hObject);
                                    case 2
                                        pushbuttonExcessive_Callback(app, event);
                                        data=guidata(hObject);
                                    case 3
                                        pushbuttonChanlocs_Callback(app, event);
                                        data=guidata(hObject);
                                    case 4
                                        pushbuttonResample_Callback(app, event);
                                        data=guidata(hObject);
                                    case 5
                                        pushbuttonReref_Callback(app, event);
                                        data=guidata(hObject);
                                    case 6
                                        pushbuttonFilter_Callback(app, event);
                                        data=guidata(hObject);
                                    case 7
                                        pushbuttonInitialICA_Callback(app, event);
                                        data=guidata(hObject);
                                    case 8
                                        pushbuttonAltInitialEOG_Callback(app, event);
                                        data=guidata(hObject);
                                    case 9
                                        pushbuttonAARWinSec_Callback(app, event);
                                        data=guidata(hObject);
                                    case 10
                                        pushbuttonAltAAR_Callback(app, event);
                                        data=guidata(hObject);
                                    % channel remove checkboxes
                                    case 11
                                        pushbuttonClean_Callback(app, event);
                                        data=guidata(hObject);
                                    case 12
                                        pushbuttonICLabel_Callback(app, event);
                                        data=guidata(hObject);
                                    case 14
                                        pushbuttonRemoveEOG_Callback(app, event);
                                        data=guidata(hObject);
                                    case 15
                                        pushbuttonRemoveNoEEG_Callback(app, event);
                                        data=guidata(hObject);
                                    % remaining cleaning procedures flatline eperiods,
                                    % cleanrawdata and IClabel cleaning
                                    case 16
                                        pushbuttonFlatPeriods_Callback(app, event);
                                        data=guidata(hObject);
                                    case 17
                                        pushbuttonExcessivePeriodsButtonPushed(app, event)
                                        data=guidata(hObject);
                                    case 18
                                        pushbuttonEMGPeriods_Callback(app, event)
                                        data=guidata(hObject);
                                    case 19
                                        pushbuttonRmEyesOpenButtonPushed(app, event)
                                        data=guidata(hObject);
                                    case 20
                                        if data.checkbox.Value
                                            tag = 'Remove task';
                                        else
                                            tag = 'Remove resting';
                                        end
                                        pushbuttonRemoveResting_Callback(app, event)
                                        data=guidata(hObject);

                                end
                            end
                        end
            
                        % clear the stack
                        data.Stack = {};
                        data.StackLabel = {};
                        guidata(hObject, data);
            
                        % save the file with prefix batch
                        % With proper path handling:
                        [filepath, filename, fileext] = fileparts(data.batchfilenamesout{f});
                        if isempty(filepath)
                            filepath = data.batchpathname; % Get path from GUI
                        end
                        pop_saveset(data.EEG, 'filename', [filename, fileext], ...
                                              'filepath', filepath, ...
                                              'savemode', 'onefile')
                        
                        data.tabLine.finaltime = size(data.EEG.data(:,:),2) / data.EEG.srate;

                        % collect the recordPower data
            
            
                        % collect the table output
                        try
                            data.tabLine.filename = data.batchfilenames{f};
                            if isempty(resultTable)
                                resultTable = data.tabLine;
                            else
                                resultTable = cat(1, resultTable, data.tabLine);
                            end
            
            
                            % Intermediate save: append to output file
                            rowData = struct2cell(data.tabLine);
                            fid = fopen(sprintf('%s/Metadata_Batch.txt', data.batchpathname), 'a');
                            if fid>0
                                first = true;
                                for c=1:length(rowData)
                                    if ~first
                                        fprintf(fid, '\t')
                                    end
                                    switch class(rowData{c})
                                        case {'int8','int16','int32','int64','uint8','uint16','uint32','uint64'}
                                            fprintf(fid, '%d', rowData{c});
                                        case 'double'
                                            fprintf(fid, '%.4g', rowData{c});
                                        case 'char'
                                            fprintf(fid, '%s', rowData{c});
                                    end
                                    first = false;
                                end
                                fprintf(fid, '\n');
                            end
                            fclose(fid);
                        catch
                            warning('collecting metadata for this subject failed.');
                        end
            
                    catch E
                        fprintf(2, 'Error in file %s:\n%s\n', data.batchfilenames{f}, E.getReport());
                        continue
                        %AddToListbox(app, data.listboxStdout, '*** Undefined error. Continuing with next file')
                        % AddToListbox(data.listboxStdout, sprintf('- %s', E.message))
                    end
            
                end
            
                %system(sprintf('open "%s"', data.batchpathname))
          
            end

        end

        % Button pushed function: pushbuttonLast4min
        function pushbuttonLast4minButtonPushed(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton38 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            data = guidata(hObject);
            
            AddToListbox(app, data.listboxStdout, 'Extracting last 4 minutes of data');
            
            % check first if data is sufficient. xmax is in secs
            if data.EEG.xmax>60*4
                [tmp, cmd] = pop_select(data.EEG, 'time', [data.EEG.xmax-60*4 data.EEG.xmax]);
                tmp.history = [tmp.history char(uint8(10)) cmd];

                % push existing data onto stack. Update <data.EEG> to tmp.
                data.Stack{length(data.Stack)+1} = data.EEG;
                data.StackLabel{length(data.Stack)+1} = 'Remove last second';
                data.EEG = tmp;
                guidata(hObject, data);            
            else
                AddToListbox(app, data.listboxStdout, ' - data too short. No change.');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.fig_eeg_workflow = uifigure('Visible', 'off');
            app.fig_eeg_workflow.Name = 'figEegAutoflow';
            app.fig_eeg_workflow.CloseRequestFcn = createCallbackFcn(app, @fig_eeg_workflow_CloseRequestFcn, true);
            app.fig_eeg_workflow.Tag = 'fig_eeg_workflow';
            app.textTstat = uilabel(app.fig_eeg_workflow);
            app.textTstat.Tag = 'textTstat';
            app.textTstat.Text = '%.1f';
            app.textMaxBadTime = uilabel(app.fig_eeg_workflow);
            app.textMaxBadTime.Tag = 'textMaxBadTime';
            app.textMaxBadTime.Text = 'Max bad time %.2f';
            app.pushbuttonOpen = uibutton(app.fig_eeg_workflow, 'push');
            app.pushbuttonOpen.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonOpen_Callback, true);
            app.pushbuttonOpen.Tag = 'pushbuttonOpen';
            app.pushbuttonOpen.Tooltip = 'Open files of ANT, EEGLAB, Neuroscan, BioSemi type';
            app.pushbuttonOpen.Text = 'Open';
            app.pushbuttonChanlocs = uibutton(app.fig_eeg_workflow, 'push');
            app.pushbuttonChanlocs.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonChanlocs_Callback, true);
            app.pushbuttonChanlocs.Tag = 'pushbuttonChanlocs';
            app.pushbuttonChanlocs.Tooltip = 'look up channel locations. only the spherical positions of 10/10 locations are available. ';
            app.pushbuttonChanlocs.Text = 'Lookup';
            app.pushbuttonFilter = uibutton(app.fig_eeg_workflow, 'push');
            app.pushbuttonFilter.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonFilter_Callback, true);
            app.pushbuttonFilter.Tag = 'pushbuttonFilter';
            app.pushbuttonFilter.Tooltip = 'Filter. Set specs right and below.';
            app.pushbuttonFilter.Text = 'Filter';
            app.pbView = uibutton(app.fig_eeg_workflow, 'push');
            app.pbView.ButtonPushedFcn = createCallbackFcn(app, @pbView_Callback, true);
            app.pbView.Tag = 'pbView';
            app.pbView.Text = 'View';
            app.pushbuttonReref = uibutton(app.fig_eeg_workflow, 'push');
            app.pushbuttonReref.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonReref_Callback, true);
            app.pushbuttonReref.Tag = 'pushbuttonReref';
            app.pushbuttonReref.Tooltip = 'Rereference. If avg, be sure to redo after deleting channels.';
            app.pushbuttonReref.Text = 'Reref';
            app.popupmenuReref = uidropdown(app.fig_eeg_workflow);
            app.popupmenuReref.Items = {'CPz', 'M1/M2', 'Average', 'REST', 'A1/A2', 'Avg --> A1/A2', 'EXT5/6', 'CSD', 'TP9/10', 'P9/10'};
            app.popupmenuReref.Tag = 'popupmenuReref';
            app.popupmenuReref.Value = 'Average';
            app.pushbuttonBadChans = uibutton(app.fig_eeg_workflow, 'push');
            app.pushbuttonBadChans.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonBadChans_Callback, true);
            app.pushbuttonBadChans.Tag = 'pushbuttonBadChans';
            app.pushbuttonBadChans.Tooltip = 'Determine bad channels and delete';
            app.pushbuttonBadChans.Text = 'Bad chans';
            app.pushbuttonRemoveNoEEG = uibutton(app.fig_eeg_workflow, 'push');
            app.pushbuttonRemoveNoEEG.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonRemoveNoEEG_Callback, true);
            app.pushbuttonRemoveNoEEG.Tag = 'pushbuttonRemoveNoEEG';
            app.pushbuttonRemoveNoEEG.Tooltip = 'Remove channels without location info (assumed to be not EEG)';
            app.pushbuttonRemoveNoEEG.Text = 'Rm ~EEG ch';
            app.pushbuttonICLabel = uibutton(app.fig_eeg_workflow, 'push');
            app.pushbuttonICLabel.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonICLabel_Callback, true);
            app.pushbuttonICLabel.Tag = 'pushbuttonICLabel';
            app.pushbuttonICLabel.Tooltip = 'Clean non-brain ICs with IClabel toolbox. Keeps ''Other'' ICs.';
            app.pushbuttonICLabel.Text = 'IC Label';
            app.pbEpoch = uibutton(app.fig_eeg_workflow, 'push');
            app.pbEpoch.ButtonPushedFcn = createCallbackFcn(app, @pbEpoch_Callback, true);
            app.pbEpoch.Tag = 'pbEpoch';
            app.pbEpoch.Text = 'Epoch';
            app.pbERP = uibutton(app.fig_eeg_workflow, 'push');
            app.pbERP.ButtonPushedFcn = createCallbackFcn(app, @pbERP_Callback, true);
            app.pbERP.Tag = 'pbERP';
            app.pbERP.Text = 'View ERPs';
            app.pushbuttonResample = uibutton(app.fig_eeg_workflow, 'push');
            app.pushbuttonResample.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonResample_Callback, true);
            app.pushbuttonResample.Tag = 'pushbuttonResample';
            app.pushbuttonResample.Tooltip = 'Resample. Set popup menu on the right.';
            app.pushbuttonResample.Text = 'Resample';
            app.popupmenuRerefFreq = uidropdown(app.fig_eeg_workflow);
            app.popupmenuRerefFreq.Items = {'<current>', '64', '128', '250', '256', '422', '512', '1000', '1024'};
            app.popupmenuRerefFreq.Tag = 'popupmenuRerefFreq';
            app.popupmenuRerefFreq.Value = '250';
            app.pushbuttonFlatline = uibutton(app.fig_eeg_workflow, 'push');
            app.pushbuttonFlatline.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonFlatline_Callback, true);
            app.pushbuttonFlatline.Tag = 'pushbuttonFlatline';
            app.pushbuttonFlatline.Tooltip = 'remove flatline channels (works best directly afte rimport).';
            app.pushbuttonFlatline.Text = 'Flatline';
            app.checkboxAddCpz = uicheckbox(app.fig_eeg_workflow);
            app.checkboxAddCpz.Tag = 'checkboxAddCpz';
            app.checkboxAddCpz.Tooltip = 'Some ANT montages ar missing AFz (refernce). Add it as a flatline and it will be restored after rereferencing';
            app.checkboxAddCpz.Text = 'Add CPz';
            app.checkboxNotch = uicheckbox(app.fig_eeg_workflow);
            app.checkboxNotch.Tag = 'checkboxNotch';
            app.checkboxNotch.Text = '50 Hz Notch';
            app.pushbuttonInitialICA = uibutton(app.fig_eeg_workflow, 'push');
            app.pushbuttonInitialICA.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonInitialICA_Callback, true);
            app.pushbuttonInitialICA.Tag = 'pushbuttonInitialICA';
            app.pushbuttonInitialICA.Tooltip = 'Initial EOG removes eye blinks to make cleaning raw data better.';
            app.pushbuttonInitialICA.Text = 'Initial EOG';
            app.pushbuttonClean = uibutton(app.fig_eeg_workflow, 'push');
            app.pushbuttonClean.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonClean_Callback, true);
            app.pushbuttonClean.Tag = 'pushbuttonClean';
            app.pushbuttonClean.Tooltip = 'Clean with cleanrawdata / ASR';
            app.pushbuttonClean.Text = 'Clean';
            app.pushbuttonAARWinSec = uibutton(app.fig_eeg_workflow, 'push');
            app.pushbuttonAARWinSec.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonAARWinSec_Callback, true);
            app.pushbuttonAARWinSec.Tag = 'pushbuttonAARWinSec';
            app.pushbuttonAARWinSec.Tooltip = 'AAR removes muscle artefacts.';
            app.pushbuttonAARWinSec.Text = 'AAR';
            app.sliderNComps = uislider(app.fig_eeg_workflow);
            app.sliderNComps.Limits = [1 128];
            app.sliderNComps.MajorTicks = [10 30 50 70 90 110 130];
            app.sliderNComps.MajorTickLabels = {''};
            app.sliderNComps.ValueChangedFcn = createCallbackFcn(app, @sliderNComps_Callback, true);
            app.sliderNComps.MinorTicks = [10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100 105 110 115 120 125];
            app.sliderNComps.Tag = 'sliderNComps';
            app.sliderNComps.Tooltip = 'Reduce dimensionality (min 10, max num channels)';
            app.sliderNComps.Value = 50;
            app.textNComps = uilabel(app.fig_eeg_workflow);
            app.textNComps.Tag = 'textNComps';
            app.textNComps.Text = 'PCA: %d';
            app.pbCompERP = uibutton(app.fig_eeg_workflow, 'push');
            app.pbCompERP.ButtonPushedFcn = createCallbackFcn(app, @pbCompERP_Callback, true);
            app.pbCompERP.Tag = 'pbCompERP';
            app.pbCompERP.Text = 'View comp ERPs';
            app.checkboxBurstDelete = uicheckbox(app.fig_eeg_workflow);
            app.checkboxBurstDelete.Tag = 'checkboxBurstDelete';
            app.checkboxBurstDelete.Tooltip = 'Delete rogue bursts (otherwise clean data)';
            app.checkboxBurstDelete.Text = 'Burst delete';
            app.sliderChannelMinR = uislider(app.fig_eeg_workflow);
            app.sliderChannelMinR.Limits = [0.3 0.95];
            app.sliderChannelMinR.MajorTicks = [0.3 0.5 0.7 0.9];
            app.sliderChannelMinR.MajorTickLabels = {''};
            app.sliderChannelMinR.ValueChangedFcn = createCallbackFcn(app, @sliderChannelMinR_Callback, true);
            app.sliderChannelMinR.MinorTicks = [0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95];
            app.sliderChannelMinR.Tag = 'sliderChannelMinR';
            app.sliderChannelMinR.Tooltip = 'Burst criterion tolerance value. Smaller values more lenient';
            app.sliderChannelMinR.Value = 0.6;
            app.textChannelMinR = uilabel(app.fig_eeg_workflow);
            app.textChannelMinR.Tag = 'textChannelMinR';
            app.textChannelMinR.Text = 'Chan min r %.2f';
            app.sliderBurstCriterion = uislider(app.fig_eeg_workflow);
            app.sliderBurstCriterion.Limits = [3 20];
            app.sliderBurstCriterion.MajorTicks = [1 6 11 16];
            app.sliderBurstCriterion.MajorTickLabels = {''};
            app.sliderBurstCriterion.ValueChangedFcn = createCallbackFcn(app, @sliderBurstCriterion_Callback, true);
            app.sliderBurstCriterion.MinorTicks = [3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.sliderBurstCriterion.Tag = 'sliderBurstCriterion';
            app.sliderBurstCriterion.Tooltip = 'Burst criterion value: SD cutoff for invoking ASR. Higher=more lenient.';
            app.sliderBurstCriterion.Value = 9;
            app.textBurstCriterion = uilabel(app.fig_eeg_workflow);
            app.textBurstCriterion.Tag = 'textBurstCriterion';
            app.textBurstCriterion.Text = 'Burst crit. %.1f';
            app.sliderMinICLabelPercent = uislider(app.fig_eeg_workflow);
            app.sliderMinICLabelPercent.Limits = [0.1 1];
            app.sliderMinICLabelPercent.MajorTicks = [0.1 0.3 0.5 0.7 0.9];
            app.sliderMinICLabelPercent.MajorTickLabels = {''};
            app.sliderMinICLabelPercent.ValueChangedFcn = createCallbackFcn(app, @sliderMinICLabelPercent_Callback, true);
            app.sliderMinICLabelPercent.MinorTicks = [0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.sliderMinICLabelPercent.Tag = 'sliderMinICLabelPercent';
            app.sliderMinICLabelPercent.Tooltip = 'Remove eye, heart, muscle ICs at this % ceertainty';
            app.sliderMinICLabelPercent.Value = 0.5;
            app.textMinICLabelPercent = uilabel(app.fig_eeg_workflow);
            app.textMinICLabelPercent.Tag = 'textMinICLabelPercent';
            app.textMinICLabelPercent.Text = 'Remove ICs at %.2f';
            app.checkboxKeepOther = uicheckbox(app.fig_eeg_workflow);
            app.checkboxKeepOther.Tag = 'checkboxKeepOther';
            app.checkboxKeepOther.Tooltip = 'Not all ICs can be clearly defined (''Other''). Keep these in the cleaned data.';
            app.checkboxKeepOther.Text = 'keep ''other'' ICs';
            app.checkboxKeepOther.Value = true;
            app.pushbuttonSave = uibutton(app.fig_eeg_workflow, 'push');
            app.pushbuttonSave.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonSave_Callback, true);
            app.pushbuttonSave.Tag = 'pushbuttonSave';
            app.pushbuttonSave.Text = 'Save';
            app.listboxStdout = uilistbox(app.fig_eeg_workflow);
            app.listboxStdout.Items = {'<function output will be shown here>'};
            app.listboxStdout.Tag = 'listboxStdout';
            app.listboxStdout.Value = '<function output will be shown here>';
            app.text11 = uilabel(app.fig_eeg_workflow);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Filter frequencies:';
            app.popupmenuLookupType = uidropdown(app.fig_eeg_workflow);
            app.popupmenuLookupType.Items = {'Standard', 'Biosemi 64', 'Biosemi 128', 'Biosemi 128 (10/20 locs)', 'TDBrain', 'Brain Products 128', 'Temple Uni', 'EGI 128'};
            app.popupmenuLookupType.Tag = 'popupmenuLookupType';
            app.popupmenuLookupType.Value = 'EGI 128';
            app.pushbuttonPlot2D = uibutton(app.fig_eeg_workflow, 'push');
            app.pushbuttonPlot2D.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonPlot2D_Callback, true);
            app.pushbuttonPlot2D.Tag = 'pushbuttonPlot2D';
            app.pushbuttonPlot2D.Text = 'Plot 2D';
            app.pbUndo = uibutton(app.fig_eeg_workflow, 'push');
            app.pbUndo.ButtonPushedFcn = createCallbackFcn(app, @pbUndo_Callback, true);
            app.pbUndo.Tag = 'pbUndo';
            app.pbUndo.Text = 'Undo';
            app.pushbuttonSaveMemory = uibutton(app.fig_eeg_workflow, 'push');
            app.pushbuttonSaveMemory.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonSaveMemory_Callback, true);
            app.pushbuttonSaveMemory.Tag = 'pushbuttonSaveMemory';
            app.pushbuttonSaveMemory.Tooltip = 'Save data as variable GlobEEG. To acces type ''global GlobEEG'' in command window';
            app.pushbuttonSaveMemory.Text = 'Save as GlobEEG';
            app.pushbuttonUp = uibutton(app.fig_eeg_workflow, 'push');
            app.pushbuttonUp.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonUp_Callback, true);
            app.pushbuttonUp.Tag = 'pushbuttonUp';
            app.pushbuttonUp.Tooltip = 'Initial ICA removes eye blinks to make cleaning raw data better.';
            app.pushbuttonUp.Text = '↑';
            app.pushbuttonDOWN = uibutton(app.fig_eeg_workflow, 'push');
            app.pushbuttonDOWN.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonDOWN_Callback, true);
            app.pushbuttonDOWN.Tag = 'pushbuttonDOWN';
            app.pushbuttonDOWN.Tooltip = 'Initial ICA removes eye blinks to make cleaning raw data better.';
            app.pushbuttonDOWN.Text = '↓';
            app.sliderMaxBadTime = uislider(app.fig_eeg_workflow);
            app.sliderMaxBadTime.Limits = [0.1 0.8];
            app.sliderMaxBadTime.MajorTicks = [0.1 0.3 0.5 0.7 0.9];
            app.sliderMaxBadTime.MajorTickLabels = {''};
            app.sliderMaxBadTime.ValueChangedFcn = createCallbackFcn(app, @sliderMaxBadTime_Callback, true);
            app.sliderMaxBadTime.MinorTicks = [0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8];
            app.sliderMaxBadTime.Tag = 'sliderMaxBadTime';
            app.sliderMaxBadTime.Tooltip = 'ChannelCriterionMaxBadTime : This is the maximum tolerated fraction of the recording duration ';
            app.sliderMaxBadTime.Value = 0.4;
            app.pushbuttonRemoveResting = uibutton(app.fig_eeg_workflow, 'push');
            app.pushbuttonRemoveResting.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonRemoveResting_Callback, true);
            app.pushbuttonRemoveResting.Tag = 'pushbuttonRemoveResting';
            app.pushbuttonRemoveResting.Tooltip = 'Remove perdiods of resting between task blocks. Deselect any period of data 2s removed from events';
            app.pushbuttonRemoveResting.Text = {'Rm no-task'; 'periods'};
            app.checkboxTaskNoTask = uicheckbox(app.fig_eeg_workflow);
            app.checkboxTaskNoTask.ValueChangedFcn = createCallbackFcn(app, @checkboxTaskNoTask_Callback, true);
            app.checkboxTaskNoTask.Tag = 'checkboxTaskNoTask';
            app.checkboxTaskNoTask.Tooltip = 'Toggle Task or No-Task removal';
            app.checkboxTaskNoTask.Text = 'toggle task';
            app.pushbuttonExcessive = uibutton(app.fig_eeg_workflow, 'push');
            app.pushbuttonExcessive.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonExcessive_Callback, true);
            app.pushbuttonExcessive.Tag = 'pushbuttonExcessive';
            app.pushbuttonExcessive.Tooltip = 'remove channels with excessive signal';
            app.pushbuttonExcessive.Text = 'Excessive signal';
            app.checkboxBiosig = uicheckbox(app.fig_eeg_workflow);
            app.checkboxBiosig.Tag = 'checkboxBiosig';
            app.checkboxBiosig.Tooltip = 'Use pop_biosig for BDF iomport (otherwise readbdf)';
            app.checkboxBiosig.Text = 'biosig for BDF';
            app.pushbuttonOverlay = uibutton(app.fig_eeg_workflow, 'push');
            app.pushbuttonOverlay.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonOverlay_Callback, true);
            app.pushbuttonOverlay.Tag = 'pushbuttonOverlay';
            app.pushbuttonOverlay.Text = 'Overlayed View';
            app.pushbuttoSaveChanlocs = uibutton(app.fig_eeg_workflow, 'push');
            app.pushbuttoSaveChanlocs.ButtonPushedFcn = createCallbackFcn(app, @pushbuttoSaveChanlocs_Callback, true);
            app.pushbuttoSaveChanlocs.Tag = 'pushbuttoSaveChanlocs';
            app.pushbuttoSaveChanlocs.Tooltip = 'Save current channel locations into file .chanlocs.mat';
            app.pushbuttoSaveChanlocs.Text = 'Save Chanlocs';
            app.pushbuttonImpute = uibutton(app.fig_eeg_workflow, 'push');
            app.pushbuttonImpute.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonImpute_Callback, true);
            app.pushbuttonImpute.Tag = 'pushbuttonImpute';
            app.pushbuttonImpute.Tooltip = 'load .chanlocs.mat (Chanlocs variable) and impute';
            app.pushbuttonImpute.Text = 'Impute Chans';
            app.pbBatch = uibutton(app.fig_eeg_workflow, 'push');
            app.pbBatch.ButtonPushedFcn = createCallbackFcn(app, @pbBatch_Callback, true);
            app.pbBatch.Tag = 'pbBatch';
            app.pbBatch.Enable = 'off';
            app.pbBatch.Tooltip = 'Batch run';
            app.pbBatch.Text = 'Batch';
            app.pushbuttonImputeAll = uibutton(app.fig_eeg_workflow, 'push');
            app.pushbuttonImputeAll.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonImputeAll_Callback, true);
            app.pushbuttonImputeAll.Tag = 'pushbuttonImputeAll';
            app.pushbuttonImputeAll.Tooltip = 'Impute all channels. this may help in reducing noise';
            app.pushbuttonImputeAll.Text = 'Impute All';
            app.pushbuttonRemoveFirstSec = uibutton(app.fig_eeg_workflow, 'push');
            app.pushbuttonRemoveFirstSec.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonRemoveFirstSec_Callback, true);
            app.pushbuttonRemoveFirstSec.Tag = 'pushbutton38';
            app.pushbuttonRemoveFirstSec.Tooltip = 'Remove seconds from start';
            app.pushbuttonRemoveFirstSec.Text = 'Rm first 1s';
            app.pushbuttonMaskEvt = uibutton(app.fig_eeg_workflow, 'push');
            app.pushbuttonMaskEvt.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonMaskEvt_Callback, true);
            app.pushbuttonMaskEvt.Tag = 'pushbuttonMaskEvt';
            app.pushbuttonMaskEvt.Tooltip = 'Open files of ANT, EEGLAB, Neuroscan, BioSemi type';
            app.pushbuttonMaskEvt.Text = 'Mask events 0xFF';
            app.textAARWinSec = uilabel(app.fig_eeg_workflow);
            app.textAARWinSec.Tag = 'textAARWinSec';
            app.textAARWinSec.Text = 'AAR window length %.1f s';
            app.sliderAARWinSec = uislider(app.fig_eeg_workflow);
            app.sliderAARWinSec.Limits = [10 120];
            app.sliderAARWinSec.MajorTicks = [];
            app.sliderAARWinSec.ValueChangedFcn = createCallbackFcn(app, @sliderAARWinSec_Callback, true);
            app.sliderAARWinSec.ValueChangingFcn = createCallbackFcn(app, @sliderAARWinSecValueChanging, true);
            app.sliderAARWinSec.MinorTicks = [5 14 23 32 41 50 59 68 77 86 95 104 113 120];
            app.sliderAARWinSec.Tag = 'sliderAARWinSec';
            app.sliderAARWinSec.Tooltip = 'Window size for AAR';
            app.sliderAARWinSec.Value = 40;
            app.sliderAARShift = uislider(app.fig_eeg_workflow);
            app.sliderAARShift.Limits = [5 120];
            app.sliderAARShift.MajorTicks = [];
            app.sliderAARShift.ValueChangedFcn = createCallbackFcn(app, @sliderAARShift_Callback, true);
            app.sliderAARShift.MinorTicks = [2.5 11.5 20.5 29.5 38.5 47.5 56.5 65.5 74.5 83.5 92.5 101.5 110.5 120];
            app.sliderAARShift.Tag = 'sliderAARShift';
            app.sliderAARShift.Tooltip = 'Window shift for AAR';
            app.sliderAARShift.Value = 20;
            app.textAARShift = uilabel(app.fig_eeg_workflow);
            app.textAARShift.Tag = 'textAARShift';
            app.textAARShift.Text = 'AAR window shift %.1f s';
            app.pushbuttonAltInitialEOG = uibutton(app.fig_eeg_workflow, 'push');
            app.pushbuttonAltInitialEOG.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonAltInitialEOG_Callback, true);
            app.pushbuttonAltInitialEOG.Tag = 'pushbuttonAltInitialEOG';
            app.pushbuttonAltInitialEOG.Tooltip = 'Alternative version for Initial EOG for low number of channels';
            app.pushbuttonAltInitialEOG.Text = 'Alt EOG';
            app.pbViewPSD = uibutton(app.fig_eeg_workflow, 'push');
            app.pbViewPSD.ButtonPushedFcn = createCallbackFcn(app, @pbViewPSD_Callback, true);
            app.pbViewPSD.Tag = 'pbViewPSD';
            app.pbViewPSD.Text = 'View PSD';
            app.pbDFA = uibutton(app.fig_eeg_workflow, 'push');
            app.pbDFA.ButtonPushedFcn = createCallbackFcn(app, @pbDFA_Callback, true);
            app.pbDFA.Tag = 'pbDFA';
            app.pbDFA.Text = 'View DFA';
            app.pushbuttonFlatPeriods = uibutton(app.fig_eeg_workflow, 'push');
            app.pushbuttonFlatPeriods.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonFlatPeriods_Callback, true);
            app.pushbuttonFlatPeriods.Tag = 'pushbuttonFlatPeriod';
            app.pushbuttonFlatPeriods.Tooltip = 'Remove periods with flatline data (for specific EEG devices)';
            app.pushbuttonFlatPeriods.Text = 'Flat periods';
            app.pushbuttonAltAAR = uibutton(app.fig_eeg_workflow, 'push');
            app.pushbuttonAltAAR.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonAltAAR_Callback, true);
            app.pushbuttonAltAAR.Tag = 'pushbuttonAltAAR';
            app.pushbuttonAltAAR.Tooltip = 'AAR removes muscle artefacts.';
            app.pushbuttonAltAAR.Text = 'Alt EMG';
            app.sliderTstat = uislider(app.fig_eeg_workflow);
            app.sliderTstat.Limits = [2 16];
            app.sliderTstat.MajorTicks = [];
            app.sliderTstat.ValueChangedFcn = createCallbackFcn(app, @sliderTstat_Callback, true);
            app.sliderTstat.MinorTicks = [2 3 4 5 6 7 8 9 10 11 12 13 14 15 16];
            app.sliderTstat.Tag = 'sliderTstat';
            app.sliderTstat.Tooltip = 'T statistic cutoff for alternate EMG cleaning';
            app.sliderTstat.Value = 4;
            app.checkboxCorrectNK = uicheckbox(app.fig_eeg_workflow);
            app.checkboxCorrectNK.Tag = 'checkboxCorrectNK';
            app.checkboxCorrectNK.Tooltip = 'Only use for (very old) Nihon Koden devices';
            app.checkboxCorrectNK.Text = 'Correct NK';
            app.pushbuttonReview = uibutton(app.fig_eeg_workflow, 'push');
            app.pushbuttonReview.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonReview_Callback, true);
            app.pushbuttonReview.Tag = 'pushbuttonReview';
            app.pushbuttonReview.Text = 'Review';
            app.pushbuttonMemoryBack = uibutton(app.fig_eeg_workflow, 'push');
            app.pushbuttonMemoryBack.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonMemoryBack_Callback, true);
            app.pushbuttonMemoryBack.Tag = 'pushbuttonMemoryBack';
            app.pushbuttonMemoryBack.Tooltip = 'Save data as variable GlobEEG. To acces type ''global GlobEEG'' in command window';
            app.pushbuttonMemoryBack.Text = 'Restore GlobEEG';
            app.pushbuttonRemoveEOG = uibutton(app.fig_eeg_workflow, 'push');
            app.pushbuttonRemoveEOG.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonRemoveEOG_Callback, true);
            app.pushbuttonRemoveEOG.Tag = 'pushbuttonRemoveEOG';
            app.pushbuttonRemoveEOG.Tooltip = {'Remove channels matching *EOG*, EXT* A1, A2, M1, M2'};
            app.pushbuttonRemoveEOG.Text = {'Rm *EOG* REF'; 'EXT chans'};
            app.checkboxJader = uicheckbox(app.fig_eeg_workflow);
            app.checkboxJader.Tag = 'checkboxJader';
            app.checkboxJader.Text = 'Jader ICA';
            app.pushbuttonEMGPeriods = uibutton(app.fig_eeg_workflow, 'push');
            app.pushbuttonEMGPeriods.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonEMGPeriods_Callback, true);
            app.pushbuttonEMGPeriods.Tag = 'pushbuttonEMGPeriods';
            app.pushbuttonEMGPeriods.Tooltip = 'Remove periods with flatline data (for specific EEG devices)';
            app.pushbuttonEMGPeriods.Text = 'EMG periods';
            app.pushbuttonSleepTheta = uibutton(app.fig_eeg_workflow, 'push');
            app.pushbuttonSleepTheta.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonSleepTheta_Callback, true);
            app.pushbuttonSleepTheta.Tag = 'pushbuttonSleepTheta';
            app.pushbuttonSleepTheta.Tooltip = {'Remove sleep EEG periods (excessive theta on most if not all channels)'};
            app.pushbuttonSleepTheta.Text = 'n/a Sleep theta';
            app.checkboxButterworth = uicheckbox(app.fig_eeg_workflow);
            app.checkboxButterworth.Tag = 'checkboxButterworth';
            app.checkboxButterworth.Text = 'Butterworth';
            app.LowLabel = uilabel(app.fig_eeg_workflow);
            app.LowLabel.Text = 'Low';
            app.spinnerLow = uispinner(app.fig_eeg_workflow);
            app.spinnerLow.Step = 0.5;
            app.spinnerLow.Limits = [0 49];
            app.spinnerLow.ValueDisplayFormat = '%3.1f';
            app.spinnerLow.Tag = 'spinnerLow';
            app.spinnerLow.Value = 1;
            app.HighLabel = uilabel(app.fig_eeg_workflow);
            app.HighLabel.Text = 'High';
            app.spinnerHigh = uispinner(app.fig_eeg_workflow);
            app.spinnerHigh.Limits = [1 120];
            app.spinnerHigh.ValueDisplayFormat = '%5d';
            app.spinnerHigh.Tag = 'spinnerHigh';
            app.spinnerHigh.Value = 47;
            app.SDSpinnerLabel = uilabel(app.fig_eeg_workflow);
            app.SDSpinnerLabel.Text = 'SD<';
            app.spinnerFlatlineSD = uispinner(app.fig_eeg_workflow);
            app.spinnerFlatlineSD.Step = 0.5;
            app.spinnerFlatlineSD.Limits = [0.5 10];
            app.spinnerFlatlineSD.ValueDisplayFormat = '%4.1f';
            app.spinnerFlatlineSD.Tag = 'spinnerFlatlineSD';
            app.spinnerFlatlineSD.Value = 1.5;
            app.ZLabel = uilabel(app.fig_eeg_workflow);
            app.ZLabel.Text = 'Z>';
            app.spinnerExcessive = uispinner(app.fig_eeg_workflow);
            app.spinnerExcessive.Limits = [3 15];
            app.spinnerExcessive.ValueDisplayFormat = '%d';
            app.spinnerExcessive.Tag = 'spinnerExcessive';
            app.spinnerExcessive.Value = 5;
            app.pushbuttonExcessivePeriods = uibutton(app.fig_eeg_workflow, 'push');
            app.pushbuttonExcessivePeriods.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonExcessivePeriodsButtonPushed, true);
            app.pushbuttonExcessivePeriods.Tag = 'pushbuttonExcessivePeriod';
            app.pushbuttonExcessivePeriods.Tooltip = {'Remove periods excessive data'};
            app.pushbuttonExcessivePeriods.Text = 'Excessive periods';
            app.pushbuttonLastSec = uibutton(app.fig_eeg_workflow, 'push');
            app.pushbuttonLastSec.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonLastSecButtonPushed, true);
            app.pushbuttonLastSec.Tag = 'pushbutton38';
            app.pushbuttonLastSec.Tooltip = 'Remove seconds from start';
            app.pushbuttonLastSec.Text = 'Rm last 1s';
            app.checkboxDataAreAvgRef = uicheckbox(app.fig_eeg_workflow);
            app.checkboxDataAreAvgRef.Tag = 'checkboxDataAreAvgRef';
            app.checkboxDataAreAvgRef.Tooltip = 'Only use for (very old) Nihon Koden devices';
            app.checkboxDataAreAvgRef.Text = 'Data are avg ref';
            app.pbOverlayPSD = uibutton(app.fig_eeg_workflow, 'push');
            app.pbOverlayPSD.ButtonPushedFcn = createCallbackFcn(app, @pbOverlayPSDButtonPushed, true);
            app.pbOverlayPSD.Tag = 'pbOverlayPSD';
            app.pbOverlayPSD.Text = 'Overlay PSD';
            app.pushbuttonRmEyesOpen = uibutton(app.fig_eeg_workflow, 'push');
            app.pushbuttonRmEyesOpen.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonRmEyesOpenButtonPushed, true);
            app.pushbuttonRmEyesOpen.Tag = 'pushbuttonRmEyesOpen';
            app.pushbuttonRmEyesOpen.Tooltip = 'Remove seconds from start';
            app.pushbuttonRmEyesOpen.Text = 'Rm eyes-open';
            app.checkboxRenameEOGVH = uicheckbox(app.fig_eeg_workflow);
            app.checkboxRenameEOGVH.Tag = 'checkboxRenameEOGVH';
            app.checkboxRenameEOGVH.Tooltip = 'Only use for (very old) Nihon Koden devices';
            app.checkboxRenameEOGVH.Text = 'Rename EOGV/H';
            app.pbBatch_v2 = uibutton(app.fig_eeg_workflow, 'push');
            app.pbBatch_v2.ButtonPushedFcn = createCallbackFcn(app, @pbBatch_v2ButtonPushed, true);
            app.pbBatch_v2.Tag = 'pbBatch_v2';
            app.pbBatch_v2.Tooltip = 'Batch run';
            app.pbBatch_v2.Text = 'Batch v2';
            app.pushbuttonLast4min = uibutton(app.fig_eeg_workflow, 'push');
            app.pushbuttonLast4min.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonLast4minButtonPushed, true);
            app.pushbuttonLast4min.Tag = 'pushbuttonLast4min';
            app.pushbuttonLast4min.Tooltip = 'Remove seconds from start';
            app.pushbuttonLast4min.Text = 'Keep last 4 min';
            app.checkboxSaveForImpute = uicheckbox(app.fig_eeg_workflow);
            app.checkboxSaveForImpute.Tag = 'checkboxSaveForImpute';
            app.checkboxSaveForImpute.Tooltip = {'Save chanlocs of channels with location for "Impute Chans"'};
            app.checkboxSaveForImpute.Text = 'Save for impute';
            % Show the figure after all components are created
            app.fig_eeg_workflow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = guiEegAutoflow_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.fig_eeg_workflow)

                % Execute the startup function
                runStartupFcn(app, @(app)guiEegAutoflow_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.fig_eeg_workflow)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.fig_eeg_workflow)
        end
    end
end